classdef impModelGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        UniformDensityTab               matlab.ui.container.Tab
        Panel_3                         matlab.ui.container.Panel
        LampLabel_uni                   matlab.ui.control.Label
        Lamp_uni                        matlab.ui.control.Lamp
        ExportSTL_uni                   matlab.ui.control.Button
        Plot_uni                        matlab.ui.control.StateButton
        exactRelativeDensity_uni        matlab.ui.control.EditField
        ExactRelativeDensityLabel       matlab.ui.control.Label
        relativeDensity_uni             matlab.ui.control.Spinner
        RelativeDensitySpinnerLabel     matlab.ui.control.Label
        thickness_uni                   matlab.ui.control.Spinner
        ThicknessLabel                  matlab.ui.control.Label
        RelativeDensityCheckBox_uni     matlab.ui.control.CheckBox
        ThicknessCheckBox_uni           matlab.ui.control.CheckBox
        UniformDensityLabel             matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        fillDirection_uni               matlab.ui.control.DropDown
        FillDirectionLabel              matlab.ui.control.Label
        CustomFunction_uni              matlab.ui.control.EditField
        CustomFunctionEditFieldLabel    matlab.ui.control.Label
        StructureType_uni               matlab.ui.control.Switch
        Function_uni                    matlab.ui.control.DropDown
        FunctionLabel                   matlab.ui.control.Label
        TPMSParametersLabel             matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        cellZ_uni                       matlab.ui.control.Spinner
        ZLabel_2                        matlab.ui.control.Label
        cellY_uni                       matlab.ui.control.Spinner
        YLabel_2                        matlab.ui.control.Label
        cellX_uni                       matlab.ui.control.Spinner
        XSpinnerLabel_5                 matlab.ui.control.Label
        CellDimensionsLabel             matlab.ui.control.Label
        cubiodZ_uni                     matlab.ui.control.Spinner
        ZLabel                          matlab.ui.control.Label
        cubiodY_uni                     matlab.ui.control.Spinner
        YLabel                          matlab.ui.control.Label
        cubiodX_uni                     matlab.ui.control.Spinner
        XSpinnerLabel_4                 matlab.ui.control.Label
        CuboidDimensionsLabel_2         matlab.ui.control.Label
        Gridpoints_uni                  matlab.ui.control.Spinner
        NumberofGridpointsSpinnerLabel  matlab.ui.control.Label
        BrowseButton_uni                matlab.ui.control.StateButton
        fileName_uni                    matlab.ui.control.EditField
        NameofFileLabel                 matlab.ui.control.Label
        ImportedMesh_uni                matlab.ui.control.DropDown
        ImportCustomMeshDropDownLabel   matlab.ui.control.Label
        GeometryInitializationLabel     matlab.ui.control.Label
        UIAxes_uni                      matlab.ui.control.UIAxes
        GradedDensityTab                matlab.ui.container.Tab
        Panel_6                         matlab.ui.container.Panel
        GradingFormula                  matlab.ui.control.EditField
        LampLabel_grade                 matlab.ui.control.Label
        Lamp_grade                      matlab.ui.control.Lamp
        ExportSTL_grade                 matlab.ui.control.Button
        Plot_grade                      matlab.ui.control.StateButton
        exactRelativeDensity_grade      matlab.ui.control.EditField
        ExactRelativeDensityLabel_2     matlab.ui.control.Label
        MaximumThicknessSpinner_grade   matlab.ui.control.Spinner
        MaximumThicknessSpinnerLabel    matlab.ui.control.Label
        MinimumThicknessSpinner_grade   matlab.ui.control.Spinner
        MinimumThicknessSpinnerLabel    matlab.ui.control.Label
        MaximumDensitySpinner_grade     matlab.ui.control.Spinner
        MaximumDensitySpinnerLabel      matlab.ui.control.Label
        MinimumDensitySpinner_grade     matlab.ui.control.Spinner
        MinimumDensitySpinnerLabel      matlab.ui.control.Label
        RelativeDensityCheckBox_grade   matlab.ui.control.CheckBox
        ThicknessCheckBox_grade         matlab.ui.control.CheckBox
        positionZ_grade                 matlab.ui.control.Spinner
        ZLabel_9                        matlab.ui.control.Label
        positionY_grade                 matlab.ui.control.Spinner
        YLabel_8                        matlab.ui.control.Label
        positionX_grade                 matlab.ui.control.Spinner
        ALabel_2                        matlab.ui.control.Label
        RelativePosition_grade          matlab.ui.control.Label
        Radius_grade                    matlab.ui.control.Spinner
        ZLabel_6                        matlab.ui.control.Label
        coefficientC_grade              matlab.ui.control.Spinner
        ZLabel_5                        matlab.ui.control.Label
        coefficientB_grade              matlab.ui.control.Spinner
        YLabel_5                        matlab.ui.control.Label
        coefficientA_grade              matlab.ui.control.Spinner
        ALabel                          matlab.ui.control.Label
        CoefficientLabel                matlab.ui.control.Label
        GradingEquation_grade           matlab.ui.control.DropDown
        FunctionLabel_3                 matlab.ui.control.Label
        GradedDensityLabel              matlab.ui.control.Label
        Panel_8                         matlab.ui.container.Panel
        fillDirection_grade             matlab.ui.control.DropDown
        FillDirectionLabel_2            matlab.ui.control.Label
        CustomFunction_grade            matlab.ui.control.EditField
        CustomFunctionEditFieldLabel_2  matlab.ui.control.Label
        StructureType_grade             matlab.ui.control.Switch
        Function_grade                  matlab.ui.control.DropDown
        FunctionLabel_4                 matlab.ui.control.Label
        TPMSParametersLabel_2           matlab.ui.control.Label
        Panel_7                         matlab.ui.container.Panel
        cellZ_grade                     matlab.ui.control.Spinner
        ZLabel_8                        matlab.ui.control.Label
        cellY_grade                     matlab.ui.control.Spinner
        YLabel_7                        matlab.ui.control.Label
        cellX_grade                     matlab.ui.control.Spinner
        XSpinnerLabel_7                 matlab.ui.control.Label
        CellDimensionsLabel_2           matlab.ui.control.Label
        cubiodZ_grade                   matlab.ui.control.Spinner
        ZLabel_7                        matlab.ui.control.Label
        cubiodY_grade                   matlab.ui.control.Spinner
        YLabel_6                        matlab.ui.control.Label
        cubiodX_grade                   matlab.ui.control.Spinner
        XSpinnerLabel_6                 matlab.ui.control.Label
        CuboidDimensionsLabel           matlab.ui.control.Label
        Gridpoints_grade                matlab.ui.control.Spinner
        NumberofGridpointsSpinnerLabel_2  matlab.ui.control.Label
        BrowseButton_grade              matlab.ui.control.StateButton
        fileName_grade                  matlab.ui.control.EditField
        NameofFIle_2                    matlab.ui.control.Label
        ImportedMesh_grade              matlab.ui.control.DropDown
        ImportCustomMeshDropDownLabel_2  matlab.ui.control.Label
        GeometryInitializationLabel_2   matlab.ui.control.Label
        UIAxes_grade                    matlab.ui.control.UIAxes
    end


    methods (Access = private)

        function [x1,y1,z1,out_p,volumeFill,inp] = initialGeo(app,len,bre,wid,cell_len_x,cell_len_y,cell_len_z,...
                imported_mesh,inp,gridpoints)

            if strcmp(imported_mesh, 'Yes')
                if contains(inp,'.stl') || contains(inp,'.STL')
                else
                    inp= strcat(inp, '.stl');
                end
            end

            period = [len/cell_len_x,bre/cell_len_y,wid/cell_len_z];

            if strcmp(imported_mesh,'No')
                x = linspace(0, period(1), gridpoints);
                y = linspace(0, period(2), gridpoints);
                z = linspace(0, period(3), gridpoints);
                out_p = [];
            elseif strcmp(imported_mesh,'Yes')
                [out_p,x,y,z] = stlinput(inp,gridpoints);
                x = x/cell_len_x; y = y/cell_len_y; z = z/cell_len_z;
            end

            [x1,y1,z1] = meshgrid(x,y,z);

            volumeFill = app.fillDirection_uni.Value;
            switch volumeFill
                case "Fill Above Isovalue"
                    volumeFill = 'above';
                case "Fill Below Isovalue"
                    volumeFill = 'below';
            end
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure,"center")
            set(app.UIAxes_uni.XLabel,'Color','white'); set(app.UIAxes_uni.YLabel,'Color','white'); set(app.UIAxes_uni.ZLabel,'Color','white');
            set(app.UIAxes_grade.XLabel,'Color','white'); set(app.UIAxes_grade.YLabel,'Color','white'); set(app.UIAxes_grade.ZLabel,'Color','white');
            app.Function_uni.Items = sort({'Primitive Schwartz Surface','Double P Surface','Gyroid Surface','Double Gyroid Surface','Diamond Surface','Double Diamond 1 Surface','Double Diamond 2 Surface','IWP Surface','FRD Prime Surface','I2-Y','G Prime 1 Surface','G Prime 2 Surface','Neovius Surface','Lidinoid Surface','D Prime Surface','KP Surface','S Surface',...
                                            'Fischer-Koch Surface','Bionic Bone 1 Surface','Bionic Bone 2 Surface','Octo 1 Surface','Octo 2 Surface','PN Surface','C(Y) Surface','Black D Surface','FRD Surface','Diamond D Surface','D Surface','Split P Surface'});
            app.Function_uni.Items(end) = {'Custom Function'};
            app.Function_grade.Items = sort({'Primitive Schwartz Surface','Double P Surface','Gyroid Surface','Double Gyroid Surface','Diamond Surface','Double Diamond 1 Surface','Double Diamond 2 Surface','IWP Surface','FRD Prime Surface','I2-Y','G Prime 1 Surface','G Prime 2 Surface','Neovius Surface','Lidinoid Surface','D Prime Surface','KP Surface','S Surface',...
                                            'Fischer-Koch Surface','Bionic Bone 1 Surface','Bionic Bone 2 Surface','Octo 1 Surface','Octo 2 Surface','PN Surface','C(Y) Surface','Black D Surface','FRD Surface','Diamond D Surface','D Surface','Split P Surface'});
            app.Function_grade.Items(end) = {'Custom Function'};
        end

        % Value changed function: StructureType_grade
        function StructureType_gradeValueChanged(app, event)
            if strcmp(app.StructureType_grade.Value, "Sheet")
                app.fillDirection_grade.Enable = 'off';
                app.ThicknessCheckBox_grade.Enable = 'on';
                app.RelativeDensityCheckBox_grade.Enable = 'on';
                if app.ThicknessCheckBox_grade.Value == true
                    app.RelativeDensityCheckBox_grade.Value = false;
                    app.MinimumThicknessSpinner_grade.Enable = "on";
                    app.MaximumThicknessSpinner_grade.Enable = 'on';
                    app.MaximumDensitySpinner_grade.Enable = 'off';
                    app.MinimumDensitySpinner_grade.Enable = 'off';
                else
                    app.RelativeDensityCheckBox_grade.Value = true;
                    app.MinimumThicknessSpinner_grade.Enable = "off";
                    app.MaximumThicknessSpinner_grade.Enable = 'off';
                    app.MaximumDensitySpinner_grade.Enable = 'on';
                    app.MinimumDensitySpinner_grade.Enable = 'on';
                end
            else
                app.fillDirection_grade.Enable = 'on';
                app.ThicknessCheckBox_grade.Enable = 'off';
                app.RelativeDensityCheckBox_grade.Enable = 'off';
                app.MinimumThicknessSpinner_grade.Enable = "off";
                app.MaximumThicknessSpinner_grade.Enable = 'off';
                app.MaximumDensitySpinner_grade.Enable = 'on';
                app.MinimumDensitySpinner_grade.Enable = 'on';

            end
        end

        % Value changed function: StructureType_uni
        function StructureType_uniValueChanged(app, event)
            if strcmp(app.StructureType_uni.Value, "Sheet")
                app.fillDirection_uni.Enable = 'off';
                app.ThicknessCheckBox_uni.Enable = 'on';
                app.RelativeDensityCheckBox_uni.Enable = 'on';
                if app.ThicknessCheckBox_uni.Value == true
                    app.thickness_uni.Enable = "on";
                    app.relativeDensity_uni.Enable = 'off';
                else
                    app.thickness_uni.Enable = "off";
                    app.relativeDensity_uni.Enable = 'on';
                end
            else
                app.fillDirection_uni.Enable = 'on';
                app.thickness_uni.Enable = 'off';
                app.ThicknessCheckBox_uni.Enable = 'off';
                app.RelativeDensityCheckBox_uni.Enable = 'off';
                app.relativeDensity_uni.Enable = 'on';

            end
        end

        % Value changed function: ImportedMesh_uni
        function ImportedMesh_uniValueChanged(app, event)
            if strcmp(app.ImportedMesh_uni.Value, "Yes")
                app.fileName_uni.Editable = 'on';
                app.BrowseButton_uni.Enable = 'on';
            else
                app.fileName_uni.Editable = 'off';
                app.BrowseButton_grade.Enable = 'off';
            end
        end

        % Value changed function: ImportedMesh_grade
        function ImportedMesh_gradeValueChanged(app, event)
            if strcmp(app.ImportedMesh_grade.Value, "Yes")
                app.fileName_grade.Editable = 'on';
                app.BrowseButton_grade.Enable = 'on';
            else
                app.fileName_grade.Editable = 'off';
                app.BrowseButton_uni.Enable = 'off';
            end
        end

        % Value changed function: Function_uni
        function Function_uniValueChanged(app, event)
            if strcmp(app.Function_uni.Value, "Custom Function")
                app.CustomFunction_uni.Editable = "on";
            else
                app.CustomFunction_uni.Editable = "off";
            end
        end

        % Value changed function: Function_grade
        function Function_gradeValueChanged(app, event)
            if strcmp(app.Function_grade.Value, "Custom Function")
                app.CustomFunction_grade.Editable = "on";
            else
                app.CustomFunction_grade.Editable = "off";
            end
        end

        % Value changed function: Plot_uni
        function Plot_uniValueChanged(app, event)
            app.Lamp_uni.Color = [1,1,0];
            app.LampLabel_uni.Text = "Running...";
            drawnow;
            [x1,y1,z1,out_p,volumeFill,inp] = initialGeo(app,app.cubiodX_uni.Value,app.cubiodY_uni.Value,app.cubiodZ_uni.Value,app.cellX_uni.Value,app.cellY_uni.Value,app.cellZ_uni.Value, app.ImportedMesh_uni.Value, app.fileName_uni.Value, app.Gridpoints_uni.Value);
            [isovalue_new,app_t_new] = rel_den(app.relativeDensity_uni.Value,app.ImportedMesh_uni.Value,inp,app.cubiodX_uni.Value,app.cubiodY_uni.Value,app.cubiodZ_uni.Value,app.cellX_uni.Value,app.cellY_uni.Value,app.cellZ_uni.Value,app.thickness_uni.Value,app.Function_uni.Value,app.StructureType_uni.Value, out_p, x1,y1,z1,volumeFill,app.RelativeDensityCheckBox_uni.Value,app.CustomFunction_uni.Value);
            [F, V] = imp_model_func(out_p, x1,y1,z1,app.cellX_uni.Value,app.cellY_uni.Value,app.cellZ_uni.Value,app_t_new,isovalue_new,app.Function_uni.Value,app.StructureType_uni.Value,volumeFill,app.CustomFunction_uni.Value);
            if strcmp(app.ImportedMesh_uni.Value,'Yes')                                                      % Check if the user is using an imported mesh domain
                nd = stlread(inp);                                                              % read the imported mesh domain
                ndv = nd.Points; ndf = nd.ConnectivityList;                                     % get face and vertices of imported mesh domain
                v_ini = stlVolume(ndv',ndf');                                                   % compute the volume of imported mesh domain
            else
                v_ini = app.cubiodX_uni.Value*app.cubiodY_uni.Value*app.cubiodZ_uni.Value;                                                            % compute volume of default domain
            end
            app.exactRelativeDensity_uni.Value = num2str(abs(stlVolume(V',F'))/v_ini,3);

            trisurf(F,V(:,1),V(:,2),V(:,3), 'Facecolor', '[1, 0.341, 0.200]','Edgecolor','none','Parent',app.UIAxes_uni);
            axis(app.UIAxes_uni, "equal");
            camlight(app.UIAxes_uni, 'headlight');
            light(app.UIAxes_uni,'position',[0 -1 0]);
            lighting(app.UIAxes_uni,'flat') ;
            pbaspect(app.UIAxes_uni,[1,1,1])
            app.Lamp_uni.Color = [0,1,0];
            app.LampLabel_uni.Text = " ";
        end

        % Button pushed function: ExportSTL_uni
        function ExportSTL_uniButtonPushed(app, event)
            app.Lamp_uni.Color = [1,1,0];
            app.LampLabel_uni.Text = "Running...";
            drawnow;
            [x1,y1,z1,out_p,volumeFill,inp] = initialGeo(app,app.cubiodX_uni.Value,app.cubiodY_uni.Value,app.cubiodZ_uni.Value,app.cellX_uni.Value,app.cellY_uni.Value,app.cellZ_uni.Value, app.ImportedMesh_uni.Value, app.fileName_uni.Value, app.Gridpoints_uni.Value);
            [isovalue_new,app_t_new] = rel_den(app.relativeDensity_uni.Value,app.ImportedMesh_uni.Value,inp,app.cubiodX_uni.Value,app.cubiodY_uni.Value,app.cubiodZ_uni.Value,app.cellX_uni.Value,app.cellY_uni.Value,app.cellZ_uni.Value,app.thickness_uni.Value,app.Function_uni.Value,app.StructureType_uni.Value,out_p,x1,y1,z1,volumeFill,app.RelativeDensityCheckBox_uni.Value,app.CustomFunction_uni.Value);
            [F, V] = imp_model_func(out_p, x1,y1,z1,app.cellX_uni.Value,app.cellY_uni.Value,app.cellZ_uni.Value,app_t_new,isovalue_new,app.Function_uni.Value,app.StructureType_uni.Value,volumeFill,app.CustomFunction_uni.Value);
            [file, path] = uiputfile('.stl','Save As');
            if ~isequal(file, 0)  % Check if the user canceled the dialog
                fileName = fullfile(path,file);
                stlwrite2(fileName, F, V); %creates the object on an stl file
            else
            end
            app.Lamp_uni.Color = [0,1,0];
            app.LampLabel_uni.Text = " ";
        end

        % Value changed function: Plot_grade
        function Plot_gradeValueChanged(app, event)
            app.Lamp_grade.Color = [1,1,0];
            app.LampLabel_grade.Text = "Running...";
            drawnow;
            [x1,y1,z1,out_p,volumeFill,inp] = initialGeo(app,app.cubiodX_grade.Value,app.cubiodY_grade.Value,app.cubiodZ_grade.Value,app.cellX_grade.Value,app.cellY_grade.Value,app.cellZ_grade.Value, app.ImportedMesh_grade.Value, app.fileName_grade.Value, app.Gridpoints_grade.Value);
            [isovalue_new,app_t_new] = grading(app.MinimumDensitySpinner_grade.Value,app.MaximumDensitySpinner_grade.Value,app.MinimumThicknessSpinner_grade.Value,app.MaximumThicknessSpinner_grade.Value,app.ImportedMesh_grade.Value,inp,app.cubiodX_grade.Value,app.cubiodY_grade.Value,app.cubiodZ_grade.Value,app.cellX_grade.Value,app.cellY_grade.Value,app.cellZ_grade.Value,...
                app.Function_grade.Value,app.StructureType_grade.Value,out_p,x1,y1,z1,volumeFill,app.GradingEquation_grade.Value,app.coefficientA_grade.Value,app.coefficientB_grade.Value,app.coefficientC_grade.Value,app.Radius_grade.Value,app.RelativeDensityCheckBox_grade.Value,app.CustomFunction_grade.Value, app.positionX_grade.Value, app.positionY_grade.Value,app.positionZ_grade.Value);
            [F, V] = imp_model_func(out_p, x1,y1,z1,app.cellX_grade.Value,app.cellY_grade.Value,app.cellZ_grade.Value,app_t_new,isovalue_new,app.Function_grade.Value,app.StructureType_grade.Value,volumeFill,app.CustomFunction_grade.Value);
            
            if strcmp(app.ImportedMesh_grade.Value,'Yes')                                                      % Check if the user is using an imported mesh domain
                nd = stlread(inp);                                                              % read the imported mesh domain
                ndv = nd.Points; ndf = nd.ConnectivityList;                                     % get face and vertices of imported mesh domain
                v_ini = stlVolume(ndv',ndf');                                                   % compute the volume of imported mesh domain
            else
                v_ini = app.cubiodX_grade.Value*app.cubiodY_grade.Value*app.cubiodZ_grade.Value;                                                            % compute volume of default domain
            end
            app.exactRelativeDensity_grade.Value = num2str(abs(stlVolume(V',F'))/v_ini,3);

            trisurf(F,V(:,1),V(:,2),V(:,3), 'Facecolor', '[1, 0.341, 0.200]','Edgecolor','none','Parent',app.UIAxes_grade);
            axis(app.UIAxes_grade, "equal");
            camlight(app.UIAxes_grade, 'headlight');
            light(app.UIAxes_grade,'position',[0 -1 0]);
            lighting(app.UIAxes_grade,'flat') ;
            pbaspect(app.UIAxes_grade,[1,1,1])
            app.Lamp_grade.Color = [0,1,0];
            app.LampLabel_grade.Text = " ";
        end

        % Button pushed function: ExportSTL_grade
        function ExportSTL_gradeButtonPushed(app, event)
            app.Lamp_grade.Color = [1,1,0];
            app.LampLabel_grade.Text = "Running...";
            drawnow;
            [x1,y1,z1,out_p,volumeFill,inp] = initialGeo(app,app.cubiodX_grade.Value,app.cubiodY_grade.Value,app.cubiodZ_grade.Value,app.cellX_grade.Value,app.cellY_grade.Value,app.cellZ_grade.Value, app.ImportedMesh_grade.Value, app.fileName_grade.Value, app.Gridpoints_grade.Value);
            [isovalue_new,app_t_new] = grading(app.MinimumDensitySpinner_grade.Value,app.MaximumDensitySpinner_grade.Value,app.MinimumThicknessSpinner_grade.Value,app.MaximumThicknessSpinner_grade.Value,app.ImportedMesh_grade.Value,inp,app.cubiodX_grade.Value,app.cubiodY_grade.Value,app.cubiodZ_grade.Value,app.cellX_grade.Value,app.cellY_grade.Value,app.cellZ_grade.Value,...
                app.Function_grade.Value,app.StructureType_grade.Value,out_p,x1,y1,z1,volumeFill,app.GradingEquation_grade.Value,app.coefficientA_grade.Value,app.coefficientB_grade.Value,app.coefficientC_grade.Value,app.Radius_grade.Value,app.RelativeDensityCheckBox_grade.Value,app.CustomFunction_grade.Value, app.positionX_grade.Value, app.positionY_grade.Value,app.positionZ_grade.Value);
            [F, V] = imp_model_func(out_p, x1,y1,z1,app.cellX_grade.Value,app.cellY_grade.Value,app.cellZ_grade.Value,app_t_new,isovalue_new,app.Function_grade.Value,app.StructureType_grade.Value,volumeFill,app.CustomFunction_grade.Value);
            [file, path] = uiputfile('.stl','Save As');
            if ~isequal(file, 0)  % Check if the user canceled the dialog
                fileName = fullfile(path,file);
                stlwrite2(fileName, F, V); %creates the object on an stl file
            else
            end
            app.Lamp_grade.Color = [0,1,0];
            app.LampLabel_grade.Text = " ";
        end

        % Value changed function: ThicknessCheckBox_uni
        function ThicknessCheckBox_uniValueChanged(app, event)
            app.ThicknessCheckBox_uni.Value = true;
            app.RelativeDensityCheckBox_uni.Value = false;
            app.thickness_uni.Enable = "on";
            app.relativeDensity_uni.Enable = 'off';
        end

        % Value changed function: RelativeDensityCheckBox_uni
        function RelativeDensityCheckBox_uniValueChanged(app, event)
            app.ThicknessCheckBox_uni.Value = false;
            app.RelativeDensityCheckBox_uni.Value = true;
            app.thickness_uni.Enable = "off";
            app.relativeDensity_uni.Enable = 'on';
        end

        % Value changed function: ThicknessCheckBox_grade
        function ThicknessCheckBox_gradeValueChanged(app, event)
            app.ThicknessCheckBox_grade.Value = true;
            app.RelativeDensityCheckBox_grade.Value = false;
            app.MinimumThicknessSpinner_grade.Enable = "on";
            app.MaximumThicknessSpinner_grade.Enable = 'on';
            app.MaximumDensitySpinner_grade.Enable = 'off';
            app.MinimumDensitySpinner_grade.Enable = 'off';
        end

        % Value changed function: RelativeDensityCheckBox_grade
        function RelativeDensityCheckBox_gradeValueChanged(app, event)
            app.ThicknessCheckBox_grade.Value = false;
            app.RelativeDensityCheckBox_grade.Value = true;
            app.MinimumThicknessSpinner_grade.Enable = "off";
            app.MaximumThicknessSpinner_grade.Enable = 'off';
            app.MaximumDensitySpinner_grade.Enable = 'on';
            app.MinimumDensitySpinner_grade.Enable = 'on';
        end

        % Value changed function: GradingEquation_grade
        function GradingEquation_gradeValueChanged(app, event)
            switch app.GradingEquation_grade.Value
                case "Linear"
                    app.GradingFormula.Value = "AX + BY + CZ";
                case "Quadratic"
                    app.GradingFormula.Value = "AX^2 + BY^2 + CZ^2";
                case "Cubic"
                    app.GradingFormula.Value = "AX^3 + BY^3 + CZ^3";
                case "Circular"
                    app.GradingFormula.Value = "sqrt(AX^2 + BY^2 + CZ^2) - R";
                case "Exponential"
                    app.GradingFormula.Value = "e^(AX) + e^(BY) + e^(CZ)";
                case "Trigonometric - Sin"
                    app.GradingFormula.Value = "sin(AX) + sin(BY) + sin(CZ)";
                case "Trigonometric - Cosine"
                    app.GradingFormula.Value = "cos(AX) + cos(BY) + cos(CZ)";
            end

            if strcmp(app.GradingEquation_grade.Value, 'Circular')
                app.Radius_grade.Enable = 'on';
            else
                app.Radius_grade.Enable = 'off';
            end
        end

        % Value changed function: BrowseButton_grade
        function BrowseButton_gradeValueChanged(app, event)
            filename = uigetfile('*.stl');
            if ~isequal(filename, 0)  % Check if the user canceled the dialog
                app.fileName_uni.Value = filename;
            else
            end
        end

        % Value changed function: BrowseButton_uni
        function BrowseButton_uniValueChanged(app, event)
            filename = uigetfile('*.stl');
            if ~isequal(filename, 0)  % Check if the user canceled the dialog
                app.fileName_uni.Value = filename;
            else
            end
        end

        % Value changing function: positionX_grade
        function positionX_gradeValueChanging(app, event)
            app.positionX_grade.Limits = [0 app.cubiodX_grade.Value];
        end

        % Value changing function: positionY_grade
        function positionY_gradeValueChanging(app, event)
            app.positionY_grade.Limits = [0 app.cubiodY_grade.Value];
        end

        % Value changing function: positionZ_grade
        function positionZ_gradeValueChanging(app, event)
            app.positionZ_grade.Limits = [0 app.cubiodZ_grade.Value];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.UniformDensityTab = uitab(app.TabGroup);
            app.UniformDensityTab.Title = 'Uniform Density';
            app.UIAxes_uni = uiaxes(app.UniformDensityTab);
            xlabel(app.UIAxes_uni, 'X')
            ylabel(app.UIAxes_uni, 'Y')
            zlabel(app.UIAxes_uni, 'Z')
            app.UIAxes_uni.XTick = [];
            app.UIAxes_uni.XTickLabel = '';
            app.UIAxes_uni.YTick = [];
            app.UIAxes_uni.YTickLabel = '';
            app.UIAxes_uni.ZTick = [];
            app.Panel = uipanel(app.UniformDensityTab);
            app.GeometryInitializationLabel = uilabel(app.Panel);
            app.GeometryInitializationLabel.Text = 'Geometry Initialization';
            app.ImportCustomMeshDropDownLabel = uilabel(app.Panel);
            app.ImportCustomMeshDropDownLabel.Tooltip = {'Would you like to import a custom mesh?'};
            app.ImportCustomMeshDropDownLabel.Text = 'Import Custom Mesh:';
            app.ImportedMesh_uni = uidropdown(app.Panel);
            app.ImportedMesh_uni.Items = {'No', 'Yes'};
            app.ImportedMesh_uni.ValueChangedFcn = createCallbackFcn(app, @ImportedMesh_uniValueChanged, true);
            app.ImportedMesh_uni.Value = 'No';
            app.NameofFileLabel = uilabel(app.Panel);
            app.NameofFileLabel.Tooltip = {'Provide the name of the imported mesh file or "Browse" to locate it on your computer'};
            app.NameofFileLabel.Text = 'Name of file:';
            app.fileName_uni = uieditfield(app.Panel, 'text');
            app.fileName_uni.Editable = 'off';
            app.BrowseButton_uni = uibutton(app.Panel, 'state');
            app.BrowseButton_uni.ValueChangedFcn = createCallbackFcn(app, @BrowseButton_uniValueChanged, true);
            app.BrowseButton_uni.Enable = 'off';
            app.BrowseButton_uni.Text = 'Browse';
            app.NumberofGridpointsSpinnerLabel = uilabel(app.Panel);
            app.NumberofGridpointsSpinnerLabel.Tooltip = {'Specify the number of gridpoints to use for generating the plot'};
            app.NumberofGridpointsSpinnerLabel.Text = 'Number of Gridpoints:';
            app.Gridpoints_uni = uispinner(app.Panel);
            app.Gridpoints_uni.Value = 100;
            app.CuboidDimensionsLabel_2 = uilabel(app.Panel);
            app.CuboidDimensionsLabel_2.Tooltip = {'Enter the length, width, and height values for the cuboid'};
            app.CuboidDimensionsLabel_2.Text = 'Cuboid Dimensions:';
            app.XSpinnerLabel_4 = uilabel(app.Panel);
            app.XSpinnerLabel_4.Text = 'X:';
            app.cubiodX_uni = uispinner(app.Panel);
            app.cubiodX_uni.Limits = [0 Inf];
            app.cubiodX_uni.Value = 60;
            app.YLabel = uilabel(app.Panel);
            app.YLabel.Text = 'Y:';
            app.cubiodY_uni = uispinner(app.Panel);
            app.cubiodY_uni.Limits = [0 Inf];
            app.cubiodY_uni.Value = 60;
            app.ZLabel = uilabel(app.Panel);
            app.ZLabel.Text = 'Z:';
            app.cubiodZ_uni = uispinner(app.Panel);
            app.cubiodZ_uni.Limits = [0 Inf];
            app.cubiodZ_uni.Value = 60;
            app.CellDimensionsLabel = uilabel(app.Panel);
            app.CellDimensionsLabel.Tooltip = {'Enter the length, width, and height values for the unit cell'};
            app.CellDimensionsLabel.Text = 'Cell Dimensions:';
            app.XSpinnerLabel_5 = uilabel(app.Panel);
            app.XSpinnerLabel_5.Text = 'X:';
            app.cellX_uni = uispinner(app.Panel);
            app.cellX_uni.Limits = [0 Inf];
            app.cellX_uni.Value = 60;
            app.YLabel_2 = uilabel(app.Panel);
            app.YLabel_2.Text = 'Y:';
            app.cellY_uni = uispinner(app.Panel);
            app.cellY_uni.Limits = [0 Inf];
            app.cellY_uni.Value = 60;
            app.ZLabel_2 = uilabel(app.Panel);
            app.ZLabel_2.Text = 'Z:';
            app.cellZ_uni = uispinner(app.Panel);
            app.cellZ_uni.Limits = [0 Inf];
            app.cellZ_uni.Value = 60;
            app.Panel_2 = uipanel(app.UniformDensityTab);
            app.TPMSParametersLabel = uilabel(app.Panel_2);
            app.TPMSParametersLabel.Text = 'TPMS Parameters';
            app.FunctionLabel = uilabel(app.Panel_2);
            app.FunctionLabel.Tooltip = {'Select a predefined TPMS function'};
            app.FunctionLabel.Text = 'Function:';
            app.Function_uni = uidropdown(app.Panel_2);
            app.Function_uni.Items = {'Primitive Schwartz Surface', 'Gyroid', 'Diamond', 'I-WP', 'F-RD', 'I2-Y', 'Custom Function'};
            app.Function_uni.ValueChangedFcn = createCallbackFcn(app, @Function_uniValueChanged, true);
            app.Function_uni.Tooltip = {''};
            app.Function_uni.Value = 'Primitive Schwartz Surface';
            app.StructureType_uni = uiswitch(app.Panel_2, 'slider');
            app.StructureType_uni.Items = {'Sheet', 'Solid'};
            app.StructureType_uni.ValueChangedFcn = createCallbackFcn(app, @StructureType_uniValueChanged, true);
            app.StructureType_uni.Value = 'Sheet';
            app.CustomFunctionEditFieldLabel = uilabel(app.Panel_2);
            app.CustomFunctionEditFieldLabel.Tooltip = {'If using a custom function, enter the mathematical expression for your TPMS'};
            app.CustomFunctionEditFieldLabel.Text = 'Custom Function:';
            app.CustomFunction_uni = uieditfield(app.Panel_2, 'text');
            app.CustomFunction_uni.Editable = 'off';
            app.CustomFunction_uni.Tooltip = {''};
            app.FillDirectionLabel = uilabel(app.Panel_2);
            app.FillDirectionLabel.Tooltip = {'Choose whether to fill the solid structure above or below the determined isovalue'};
            app.FillDirectionLabel.Text = 'Fill Direction:';
            app.fillDirection_uni = uidropdown(app.Panel_2);
            app.fillDirection_uni.Items = {'Fill Above Isovalue', 'Fill Below Isovalue'};
            app.fillDirection_uni.Enable = 'off';
            app.fillDirection_uni.Tooltip = {''};
            app.fillDirection_uni.Value = 'Fill Above Isovalue';
            app.Panel_3 = uipanel(app.UniformDensityTab);
            app.UniformDensityLabel = uilabel(app.Panel_3);
            app.UniformDensityLabel.Tooltip = {''};
            app.UniformDensityLabel.Text = 'Uniform Density';
            app.ThicknessCheckBox_uni = uicheckbox(app.Panel_3);
            app.ThicknessCheckBox_uni.ValueChangedFcn = createCallbackFcn(app, @ThicknessCheckBox_uniValueChanged, true);
            app.ThicknessCheckBox_uni.Tooltip = {''};
            app.ThicknessCheckBox_uni.Text = 'Thickness';
            app.ThicknessCheckBox_uni.Value = true;
            app.RelativeDensityCheckBox_uni = uicheckbox(app.Panel_3);
            app.RelativeDensityCheckBox_uni.ValueChangedFcn = createCallbackFcn(app, @RelativeDensityCheckBox_uniValueChanged, true);
            app.RelativeDensityCheckBox_uni.Tooltip = {''};
            app.RelativeDensityCheckBox_uni.Text = 'Relative Density';
            app.ThicknessLabel = uilabel(app.Panel_3);
            app.ThicknessLabel.Tooltip = {'Set the desired thickness value'};
            app.ThicknessLabel.Text = 'Thickness:';
            app.thickness_uni = uispinner(app.Panel_3);
            app.thickness_uni.Step = 0.1;
            app.thickness_uni.Limits = [0.01 Inf];
            app.thickness_uni.Tooltip = {''};
            app.thickness_uni.Value = 0.5;
            app.RelativeDensitySpinnerLabel = uilabel(app.Panel_3);
            app.RelativeDensitySpinnerLabel.Tooltip = {'Set the desired relative density value to a value between 0 and 1'};
            app.RelativeDensitySpinnerLabel.Text = 'Relative Density:';
            app.relativeDensity_uni = uispinner(app.Panel_3);
            app.relativeDensity_uni.Step = 0.1;
            app.relativeDensity_uni.Limits = [0.01 1];
            app.relativeDensity_uni.Enable = 'off';
            app.relativeDensity_uni.Tooltip = {''};
            app.relativeDensity_uni.Value = 0.5;
            app.ExactRelativeDensityLabel = uilabel(app.Panel_3);
            app.ExactRelativeDensityLabel.Tooltip = {'The exact relative density of the object is displayed here'};
            app.ExactRelativeDensityLabel.Text = 'Exact Relative Density: ';
            app.exactRelativeDensity_uni = uieditfield(app.Panel_3, 'text');
            app.exactRelativeDensity_uni.Editable = 'off';
            app.exactRelativeDensity_uni.Tooltip = {''};
            app.Plot_uni = uibutton(app.Panel_3, 'state');
            app.Plot_uni.ValueChangedFcn = createCallbackFcn(app, @Plot_uniValueChanged, true);
            app.Plot_uni.Text = 'Plot';
            app.ExportSTL_uni = uibutton(app.Panel_3, 'push');
            app.ExportSTL_uni.ButtonPushedFcn = createCallbackFcn(app, @ExportSTL_uniButtonPushed, true);
            app.ExportSTL_uni.Text = 'Export STL';
            app.Lamp_uni = uilamp(app.Panel_3);
            app.LampLabel_uni = uilabel(app.Panel_3);
            app.LampLabel_uni.Text = ' ';
            app.GradedDensityTab = uitab(app.TabGroup);
            app.GradedDensityTab.Title = 'Graded Density';
            app.UIAxes_grade = uiaxes(app.GradedDensityTab);
            xlabel(app.UIAxes_grade, 'X')
            ylabel(app.UIAxes_grade, 'Y')
            zlabel(app.UIAxes_grade, 'Z')
            app.UIAxes_grade.XTick = [];
            app.UIAxes_grade.XTickLabel = '';
            app.UIAxes_grade.YTick = [];
            app.UIAxes_grade.YTickLabel = '';
            app.UIAxes_grade.ZTick = [];
            app.Panel_7 = uipanel(app.GradedDensityTab);
            app.GeometryInitializationLabel_2 = uilabel(app.Panel_7);
            app.GeometryInitializationLabel_2.Text = 'Geometry Initialization';
            app.ImportCustomMeshDropDownLabel_2 = uilabel(app.Panel_7);
            app.ImportCustomMeshDropDownLabel_2.Tooltip = {'Would you like to import a custom mesh?'};
            app.ImportCustomMeshDropDownLabel_2.Text = 'Import Custom Mesh:';
            app.ImportedMesh_grade = uidropdown(app.Panel_7);
            app.ImportedMesh_grade.Items = {'No', 'Yes'};
            app.ImportedMesh_grade.ValueChangedFcn = createCallbackFcn(app, @ImportedMesh_gradeValueChanged, true);
            app.ImportedMesh_grade.Value = 'No';
            app.NameofFIle_2 = uilabel(app.Panel_7);
            app.NameofFIle_2.Tooltip = {'Provide the name of the imported mesh file or "Browse" to locate it on your computer'};
            app.NameofFIle_2.Text = 'Name of file:';
            app.fileName_grade = uieditfield(app.Panel_7, 'text');
            app.fileName_grade.Editable = 'off';
            app.BrowseButton_grade = uibutton(app.Panel_7, 'state');
            app.BrowseButton_grade.ValueChangedFcn = createCallbackFcn(app, @BrowseButton_gradeValueChanged, true);
            app.BrowseButton_grade.Enable = 'off';
            app.BrowseButton_grade.Text = 'Browse';
            app.NumberofGridpointsSpinnerLabel_2 = uilabel(app.Panel_7);
            app.NumberofGridpointsSpinnerLabel_2.Tooltip = {'Specify the number of gridpoints to use for generating the plot'};
            app.NumberofGridpointsSpinnerLabel_2.Text = 'Number of Gridpoints:';
            app.Gridpoints_grade = uispinner(app.Panel_7);
            app.Gridpoints_grade.Value = 100;
            app.CuboidDimensionsLabel = uilabel(app.Panel_7);
            app.CuboidDimensionsLabel.Tooltip = {'Enter the length, width, and height values for the cuboid'};
            app.CuboidDimensionsLabel.Text = 'Cuboid Dimensions:';
            app.XSpinnerLabel_6 = uilabel(app.Panel_7);
            app.XSpinnerLabel_6.Text = 'X:';
            app.cubiodX_grade = uispinner(app.Panel_7);
            app.cubiodX_grade.Value = 60;
            app.YLabel_6 = uilabel(app.Panel_7);
            app.YLabel_6.Text = 'Y:';
            app.cubiodY_grade = uispinner(app.Panel_7);
            app.cubiodY_grade.Value = 60;
            app.ZLabel_7 = uilabel(app.Panel_7);
            app.ZLabel_7.Text = 'Z:';
            app.cubiodZ_grade = uispinner(app.Panel_7);
            app.cubiodZ_grade.Value = 60;
            app.CellDimensionsLabel_2 = uilabel(app.Panel_7);
            app.CellDimensionsLabel_2.Tooltip = {'Enter the length, width, and height values for the unit cell'};
            app.CellDimensionsLabel_2.Text = 'Cell Dimensions:';
            app.XSpinnerLabel_7 = uilabel(app.Panel_7);
            app.XSpinnerLabel_7.Text = 'X:';
            app.cellX_grade = uispinner(app.Panel_7);
            app.cellX_grade.Value = 60;
            app.YLabel_7 = uilabel(app.Panel_7);
            app.YLabel_7.Text = 'Y:';
            app.cellY_grade = uispinner(app.Panel_7);
            app.cellY_grade.Value = 60;
            app.ZLabel_8 = uilabel(app.Panel_7);
            app.ZLabel_8.Text = 'Z:';
            app.cellZ_grade = uispinner(app.Panel_7);
            app.cellZ_grade.Value = 60;
            app.Panel_8 = uipanel(app.GradedDensityTab);
            app.TPMSParametersLabel_2 = uilabel(app.Panel_8);
            app.TPMSParametersLabel_2.Text = 'TPMS Parameters';
            app.FunctionLabel_4 = uilabel(app.Panel_8);
            app.FunctionLabel_4.Tooltip = {'Select a predefined TPMS function'};
            app.FunctionLabel_4.Text = 'Function:';
            app.Function_grade = uidropdown(app.Panel_8);
            app.Function_grade.Items = {'Primitive Schwartz Surface', 'Gyroid', 'Diamond', 'I-WP', 'F-RD', 'I2-Y', 'Custom Function'};
            app.Function_grade.ValueChangedFcn = createCallbackFcn(app, @Function_gradeValueChanged, true);
            app.Function_grade.Tooltip = {'Select a predefined TPMS function'};
            app.Function_grade.Value = 'Primitive Schwartz Surface';
            app.StructureType_grade = uiswitch(app.Panel_8, 'slider');
            app.StructureType_grade.Items = {'Sheet', 'Solid'};
            app.StructureType_grade.ValueChangedFcn = createCallbackFcn(app, @StructureType_gradeValueChanged, true);
            app.StructureType_grade.Value = 'Sheet';
            app.CustomFunctionEditFieldLabel_2 = uilabel(app.Panel_8);
            app.CustomFunctionEditFieldLabel_2.Tooltip = {'If using a custom function, enter the mathematical expression for your TPMS'};
            app.CustomFunctionEditFieldLabel_2.Text = 'Custom Function:';
            app.CustomFunction_grade = uieditfield(app.Panel_8, 'text');
            app.CustomFunction_grade.Editable = 'off';
            app.CustomFunction_grade.Tooltip = {'If using a custom function, enter the mathematical expression for your TPMS'};
            app.FillDirectionLabel_2 = uilabel(app.Panel_8);
            app.FillDirectionLabel_2.Tooltip = {'Choose whether to fill the solid structure above or below the determined isovalue'};
            app.FillDirectionLabel_2.Text = 'Fill Direction:';
            app.fillDirection_grade = uidropdown(app.Panel_8);
            app.fillDirection_grade.Items = {'Fill Above Isovalue', 'Fill Below Isovalue'};
            app.fillDirection_grade.Enable = 'off';
            app.fillDirection_grade.Tooltip = {'Choose whether to fill the solid structure above or below the specified isovalue'};
            app.fillDirection_grade.Value = 'Fill Above Isovalue';
            app.Panel_6 = uipanel(app.GradedDensityTab);
            app.GradedDensityLabel = uilabel(app.Panel_6);
            app.GradedDensityLabel.Text = 'Graded Density';
            app.FunctionLabel_3 = uilabel(app.Panel_6);
            app.FunctionLabel_3.Tooltip = {'Choose a grading equation to determine the grading pattern'};
            app.FunctionLabel_3.Text = 'Grading Equation:';
            app.GradingEquation_grade = uidropdown(app.Panel_6);
            app.GradingEquation_grade.Items = {'Linear', 'Quadratic', 'Cubic', 'Circular', 'Exponential', 'Trigonometric - Sin', 'Trigonometric - Cosine'};
            app.GradingEquation_grade.ValueChangedFcn = createCallbackFcn(app, @GradingEquation_gradeValueChanged, true);
            app.GradingEquation_grade.Tooltip = {''};
            app.GradingEquation_grade.Value = 'Linear';
            app.CoefficientLabel = uilabel(app.Panel_6);
            app.CoefficientLabel.Tooltip = {'Adjust the coefficient of the selected grading equation to control the grading pattern'};
            app.CoefficientLabel.Text = 'Coefficient:';
            app.ALabel = uilabel(app.Panel_6);
            app.ALabel.Tooltip = {''};
            app.ALabel.Text = 'A:';
            app.coefficientA_grade = uispinner(app.Panel_6);
            app.coefficientA_grade.Tooltip = {''};
            app.coefficientA_grade.Value = 1;
            app.YLabel_5 = uilabel(app.Panel_6);
            app.YLabel_5.Tooltip = {''};
            app.YLabel_5.Text = 'B:';
            app.coefficientB_grade = uispinner(app.Panel_6);
            app.coefficientB_grade.Tooltip = {''};
            app.ZLabel_5 = uilabel(app.Panel_6);
            app.ZLabel_5.Tooltip = {''};
            app.ZLabel_5.Text = 'C:';
            app.coefficientC_grade = uispinner(app.Panel_6);
            app.coefficientC_grade.Tooltip = {''};
            app.ZLabel_6 = uilabel(app.Panel_6);
            app.ZLabel_6.Tooltip = {'If using a circular equation, manipulate the radius here'};
            app.ZLabel_6.Text = 'Radius:';
            app.Radius_grade = uispinner(app.Panel_6);
            app.Radius_grade.Enable = 'off';
            app.Radius_grade.Tooltip = {''};
            app.Radius_grade.Value = 60;
            app.RelativePosition_grade = uilabel(app.Panel_6);
            app.RelativePosition_grade.Tooltip = {'Define where on the object the grading will take place'};
            app.RelativePosition_grade.Text = 'Reference Position:';
            app.ALabel_2 = uilabel(app.Panel_6);
            app.ALabel_2.Tooltip = {''};
            app.ALabel_2.Text = 'X:';
            app.positionX_grade = uispinner(app.Panel_6);
            app.positionX_grade.ValueChangingFcn = createCallbackFcn(app, @positionX_gradeValueChanging, true);
            app.positionX_grade.Limits = [0 Inf];
            app.positionX_grade.Tooltip = {''};
            app.positionX_grade.Value = 60;
            app.YLabel_8 = uilabel(app.Panel_6);
            app.YLabel_8.Tooltip = {''};
            app.YLabel_8.Text = 'Y:';
            app.positionY_grade = uispinner(app.Panel_6);
            app.positionY_grade.ValueChangingFcn = createCallbackFcn(app, @positionY_gradeValueChanging, true);
            app.positionY_grade.Limits = [0 Inf];
            app.positionY_grade.Tooltip = {''};
            app.ZLabel_9 = uilabel(app.Panel_6);
            app.ZLabel_9.Tooltip = {''};
            app.ZLabel_9.Text = 'Z:';
            app.positionZ_grade = uispinner(app.Panel_6);
            app.positionZ_grade.ValueChangingFcn = createCallbackFcn(app, @positionZ_gradeValueChanging, true);
            app.positionZ_grade.Limits = [0 Inf];
            app.positionZ_grade.Tooltip = {''};
            app.ThicknessCheckBox_grade = uicheckbox(app.Panel_6);
            app.ThicknessCheckBox_grade.ValueChangedFcn = createCallbackFcn(app, @ThicknessCheckBox_gradeValueChanged, true);
            app.ThicknessCheckBox_grade.Tooltip = {''};
            app.ThicknessCheckBox_grade.Text = 'Thickness';
            app.ThicknessCheckBox_grade.Value = true;
            app.RelativeDensityCheckBox_grade = uicheckbox(app.Panel_6);
            app.RelativeDensityCheckBox_grade.ValueChangedFcn = createCallbackFcn(app, @RelativeDensityCheckBox_gradeValueChanged, true);
            app.RelativeDensityCheckBox_grade.Tooltip = {''};
            app.RelativeDensityCheckBox_grade.Text = 'Relative Density';
            app.MinimumDensitySpinnerLabel = uilabel(app.Panel_6);
            app.MinimumDensitySpinnerLabel.Tooltip = {'Set the desired minimum and maximum relative density value to a value between 0 and 1'};
            app.MinimumDensitySpinnerLabel.Text = 'Minimum Density:';
            app.MinimumDensitySpinner_grade = uispinner(app.Panel_6);
            app.MinimumDensitySpinner_grade.Step = 0.1;
            app.MinimumDensitySpinner_grade.Limits = [0.01 1];
            app.MinimumDensitySpinner_grade.Enable = 'off';
            app.MinimumDensitySpinner_grade.Tooltip = {''};
            app.MinimumDensitySpinner_grade.Value = 0.2;
            app.MaximumDensitySpinnerLabel = uilabel(app.Panel_6);
            app.MaximumDensitySpinnerLabel.Tooltip = {'Set the desired '; ' minimum and maximum relative density value to a value between 0 and 1'};
            app.MaximumDensitySpinnerLabel.Text = 'Maximum Density:';
            app.MaximumDensitySpinner_grade = uispinner(app.Panel_6);
            app.MaximumDensitySpinner_grade.Step = 0.1;
            app.MaximumDensitySpinner_grade.Limits = [0.01 1];
            app.MaximumDensitySpinner_grade.Enable = 'off';
            app.MaximumDensitySpinner_grade.Tooltip = {''};
            app.MaximumDensitySpinner_grade.Value = 0.8;
            app.MinimumThicknessSpinnerLabel = uilabel(app.Panel_6);
            app.MinimumThicknessSpinnerLabel.Tooltip = {'Set the desired minimum and maximum thickness values'};
            app.MinimumThicknessSpinnerLabel.Text = 'Minimum Thickness:';
            app.MinimumThicknessSpinner_grade = uispinner(app.Panel_6);
            app.MinimumThicknessSpinner_grade.Step = 0.1;
            app.MinimumThicknessSpinner_grade.Limits = [0.01 Inf];
            app.MinimumThicknessSpinner_grade.Tooltip = {''};
            app.MinimumThicknessSpinner_grade.Value = 0.5;
            app.MaximumThicknessSpinnerLabel = uilabel(app.Panel_6);
            app.MaximumThicknessSpinnerLabel.Tooltip = {'Set the desired minimum and maximum thickness values'};
            app.MaximumThicknessSpinnerLabel.Text = 'Maximum Thickness:';
            app.MaximumThicknessSpinner_grade = uispinner(app.Panel_6);
            app.MaximumThicknessSpinner_grade.Step = 0.1;
            app.MaximumThicknessSpinner_grade.Limits = [0.01 Inf];
            app.MaximumThicknessSpinner_grade.Tooltip = {''};
            app.MaximumThicknessSpinner_grade.Value = 1;
            app.ExactRelativeDensityLabel_2 = uilabel(app.Panel_6);
            app.ExactRelativeDensityLabel_2.Tooltip = {'The exact relative density of the object is displayed here'};
            app.ExactRelativeDensityLabel_2.Text = 'Exact Relative Density: ';
            app.exactRelativeDensity_grade = uieditfield(app.Panel_6, 'text');
            app.exactRelativeDensity_grade.Editable = 'off';
            app.exactRelativeDensity_grade.Tooltip = {''};
            app.Plot_grade = uibutton(app.Panel_6, 'state');
            app.Plot_grade.ValueChangedFcn = createCallbackFcn(app, @Plot_gradeValueChanged, true);
            app.Plot_grade.Text = 'Plot';
            app.ExportSTL_grade = uibutton(app.Panel_6, 'push');
            app.ExportSTL_grade.ButtonPushedFcn = createCallbackFcn(app, @ExportSTL_gradeButtonPushed, true);
            app.ExportSTL_grade.Text = 'Export STL';
            app.Lamp_grade = uilamp(app.Panel_6);
            app.LampLabel_grade = uilabel(app.Panel_6);
            app.LampLabel_grade.Text = ' ';
            app.GradingFormula = uieditfield(app.Panel_6, 'text');
            app.GradingFormula.Editable = 'off';
            app.GradingFormula.Tooltip = {''};
            app.GradingFormula.Value = 'AX + BY + CZ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = impModelGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end