classdef LattGen1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        UniformDensityTab               matlab.ui.container.Tab
        Panel_3                         matlab.ui.container.Panel
        Stop_uni                        matlab.ui.control.StateButton
        LampLabel_uni                   matlab.ui.control.Label
        Lamp_uni                        matlab.ui.control.Lamp
        ExportSTL_uni                   matlab.ui.control.Button
        Plot_uni                        matlab.ui.control.StateButton
        exactRelativeDensity_uni        matlab.ui.control.EditField
        ExactRelativeDensityLabel       matlab.ui.control.Label
        relativeDensity_uni             matlab.ui.control.Spinner
        RelativeDensitySpinnerLabel     matlab.ui.control.Label
        thickness_uni                   matlab.ui.control.Spinner
        ThicknessLabel                  matlab.ui.control.Label
        RelativeDensityCheckBox_uni     matlab.ui.control.CheckBox
        ThicknessCheckBox_uni           matlab.ui.control.CheckBox
        UniformDensityLabel             matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        fillDirection_uni               matlab.ui.control.DropDown
        FillDirectionLabel              matlab.ui.control.Label
        CustomFunction_uni              matlab.ui.control.EditField
        CustomFunctionEditFieldLabel    matlab.ui.control.Label
        StructureType_uni               matlab.ui.control.Switch
        Function_uni                    matlab.ui.control.DropDown
        FunctionLabel                   matlab.ui.control.Label
        TPMSParametersLabel             matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        cellZ_uni                       matlab.ui.control.Spinner
        ZLabel_2                        matlab.ui.control.Label
        cellY_uni                       matlab.ui.control.Spinner
        YLabel_2                        matlab.ui.control.Label
        cellX_uni                       matlab.ui.control.Spinner
        XSpinnerLabel_5                 matlab.ui.control.Label
        CellDimensionsLabel             matlab.ui.control.Label
        cubiodZ_uni                     matlab.ui.control.Spinner
        ZLabel                          matlab.ui.control.Label
        cubiodY_uni                     matlab.ui.control.Spinner
        YLabel                          matlab.ui.control.Label
        cubiodX_uni                     matlab.ui.control.Spinner
        XSpinnerLabel_4                 matlab.ui.control.Label
        CuboidDimensionsLabel_2         matlab.ui.control.Label
        Gridpoints_uni                  matlab.ui.control.Spinner
        NumberofGridpointsSpinnerLabel  matlab.ui.control.Label
        BrowseButton_uni                matlab.ui.control.StateButton
        fileName_uni                    matlab.ui.control.EditField
        NameofFileLabel                 matlab.ui.control.Label
        ImportedMesh_uni                matlab.ui.control.DropDown
        ImportCustomMeshDropDownLabel   matlab.ui.control.Label
        GeometryInitializationLabel     matlab.ui.control.Label
        UIAxes_uni                      matlab.ui.control.UIAxes
        GradedDensityTab                matlab.ui.container.Tab
        Panel_6                         matlab.ui.container.Panel
        Stop_grade                      matlab.ui.control.StateButton
        LampLabel_grade                 matlab.ui.control.Label
        Lamp_grade                      matlab.ui.control.Lamp
        ExportSTL_grade                 matlab.ui.control.Button
        Plot_grade                      matlab.ui.control.StateButton
        exactRelativeDensity_grade      matlab.ui.control.EditField
        ExactRelativeDensityLabel_2     matlab.ui.control.Label
        MaximumThicknessSpinner_grade   matlab.ui.control.Spinner
        MaximumThicknessSpinnerLabel    matlab.ui.control.Label
        MinimumThicknessSpinner_grade   matlab.ui.control.Spinner
        MinimumThicknessSpinnerLabel    matlab.ui.control.Label
        MaximumDensitySpinner_grade     matlab.ui.control.Spinner
        MaximumDensitySpinnerLabel      matlab.ui.control.Label
        MinimumDensitySpinner_grade     matlab.ui.control.Spinner
        MinimumDensitySpinnerLabel      matlab.ui.control.Label
        RelativeDensityCheckBox_grade   matlab.ui.control.CheckBox
        ThicknessCheckBox_grade         matlab.ui.control.CheckBox
        positionZ_grade                 matlab.ui.control.Spinner
        ZLabel_9                        matlab.ui.control.Label
        positionY_grade                 matlab.ui.control.Spinner
        YLabel_8                        matlab.ui.control.Label
        positionX_grade                 matlab.ui.control.Spinner
        ALabel_2                        matlab.ui.control.Label
        RelativePosition_grade          matlab.ui.control.Label
        Radius_grade                    matlab.ui.control.Spinner
        ZLabel_6                        matlab.ui.control.Label
        coefficientC_grade              matlab.ui.control.Spinner
        ZLabel_5                        matlab.ui.control.Label
        coefficientB_grade              matlab.ui.control.Spinner
        YLabel_5                        matlab.ui.control.Label
        coefficientA_grade              matlab.ui.control.Spinner
        ALabel                          matlab.ui.control.Label
        CoefficientLabel                matlab.ui.control.Label
        GradingFormula                  matlab.ui.control.EditField
        GradingEquation_grade           matlab.ui.control.DropDown
        FunctionLabel_3                 matlab.ui.control.Label
        GradedDensityLabel              matlab.ui.control.Label
        Panel_8                         matlab.ui.container.Panel
        fillDirection_grade             matlab.ui.control.DropDown
        FillDirectionLabel_2            matlab.ui.control.Label
        CustomFunction_grade            matlab.ui.control.EditField
        CustomFunctionEditFieldLabel_2  matlab.ui.control.Label
        StructureType_grade             matlab.ui.control.Switch
        Function_grade                  matlab.ui.control.DropDown
        FunctionLabel_4                 matlab.ui.control.Label
        TPMSParametersLabel_2           matlab.ui.control.Label
        Panel_7                         matlab.ui.container.Panel
        cellZ_grade                     matlab.ui.control.Spinner
        ZLabel_8                        matlab.ui.control.Label
        cellY_grade                     matlab.ui.control.Spinner
        YLabel_7                        matlab.ui.control.Label
        cellX_grade                     matlab.ui.control.Spinner
        XSpinnerLabel_7                 matlab.ui.control.Label
        CellDimensionsLabel_2           matlab.ui.control.Label
        cubiodZ_grade                   matlab.ui.control.Spinner
        ZLabel_7                        matlab.ui.control.Label
        cubiodY_grade                   matlab.ui.control.Spinner
        YLabel_6                        matlab.ui.control.Label
        cubiodX_grade                   matlab.ui.control.Spinner
        XSpinnerLabel_6                 matlab.ui.control.Label
        CuboidDimensionsLabel           matlab.ui.control.Label
        Gridpoints_grade                matlab.ui.control.Spinner
        NumberofGridpointsSpinnerLabel_2  matlab.ui.control.Label
        BrowseButton_grade              matlab.ui.control.StateButton
        fileName_grade                  matlab.ui.control.EditField
        NameofFIle_2                    matlab.ui.control.Label
        ImportedMesh_grade              matlab.ui.control.DropDown
        ImportCustomMeshDropDownLabel_2  matlab.ui.control.Label
        GeometryInitializationLabel_2   matlab.ui.control.Label
        UIAxes_grade                    matlab.ui.control.UIAxes
        HybridizationTab                matlab.ui.container.Tab
        Panel_11                        matlab.ui.container.Panel
        Stop_hybrid                     matlab.ui.control.StateButton
        positionZ_hybrid                matlab.ui.control.Spinner
        ZLabel_14                       matlab.ui.control.Label
        positionY_hybrid                matlab.ui.control.Spinner
        YLabel_12                       matlab.ui.control.Label
        positionX_hybrid                matlab.ui.control.Spinner
        ALabel_4                        matlab.ui.control.Label
        radius_hybrid                   matlab.ui.control.Spinner
        ZLabel_13                       matlab.ui.control.Label
        coefficientC_hybrid             matlab.ui.control.Spinner
        ZLabel_12                       matlab.ui.control.Label
        coefficientB_hybrid             matlab.ui.control.Spinner
        YLabel_11                       matlab.ui.control.Label
        coefficientA_hybrid             matlab.ui.control.Spinner
        ALabel_3                        matlab.ui.control.Label
        hybridEquation                  matlab.ui.control.DropDown
        FunctionLabel_7                 matlab.ui.control.Label
        RelativePosition_grade_2        matlab.ui.control.Label
        CoefficientLabel_2              matlab.ui.control.Label
        hybridFormula                   matlab.ui.control.EditField
        LampLabel_hybrid                matlab.ui.control.Label
        Lamp_hybrid                     matlab.ui.control.Lamp
        ExportSTL_hybrid                matlab.ui.control.Button
        Plot_hybrid                     matlab.ui.control.StateButton
        exactRelativeDensity_hybrid     matlab.ui.control.EditField
        ExactRelativeDensityLabel_3     matlab.ui.control.Label
        relativeDensity_hybrid          matlab.ui.control.Spinner
        RelativeDensitySpinnerLabel_2   matlab.ui.control.Label
        thickness_hybrid                matlab.ui.control.Spinner
        ThicknessLabel_2                matlab.ui.control.Label
        RelativeDensityCheckBox_hybrid  matlab.ui.control.CheckBox
        ThicknessCheckBox_hybrid        matlab.ui.control.CheckBox
        UniformDensityLabel_hybrid      matlab.ui.control.Label
        Panel_10                        matlab.ui.container.Panel
        k                               matlab.ui.control.Spinner
        kSpinnerLabel                   matlab.ui.control.Label
        fillDirection_hybrid            matlab.ui.control.DropDown
        FillDirectionLabel_3            matlab.ui.control.Label
        StructureType_hybrid            matlab.ui.control.Switch
        CustomFunction_hybrid_2         matlab.ui.control.EditField
        CustomFunction2Label            matlab.ui.control.Label
        CustomFunction_hybrid           matlab.ui.control.EditField
        CustomFunction1Label            matlab.ui.control.Label
        Function_hybrid_2               matlab.ui.control.DropDown
        FunctionLabel_6                 matlab.ui.control.Label
        Function_hybrid                 matlab.ui.control.DropDown
        FunctionLabel_5                 matlab.ui.control.Label
        TPMSParametersLabel_hybrid      matlab.ui.control.Label
        Panel_9                         matlab.ui.container.Panel
        cellZ_hybrid                    matlab.ui.control.Spinner
        ZLabel_11                       matlab.ui.control.Label
        cellY_hybrid                    matlab.ui.control.Spinner
        YLabel_10                       matlab.ui.control.Label
        cellX_hybrid                    matlab.ui.control.Spinner
        XSpinnerLabel_9                 matlab.ui.control.Label
        CellDimensionsLabel_3           matlab.ui.control.Label
        cubiodZ_hybrid                  matlab.ui.control.Spinner
        ZLabel_10                       matlab.ui.control.Label
        cubiodY_hybrid                  matlab.ui.control.Spinner
        YLabel_9                        matlab.ui.control.Label
        cubiodX_hybrid                  matlab.ui.control.Spinner
        XSpinnerLabel_8                 matlab.ui.control.Label
        CuboidDimensionsLabel_3         matlab.ui.control.Label
        Gridpoints_hybrid               matlab.ui.control.Spinner
        NumberofGridpointsSpinnerLabel_3  matlab.ui.control.Label
        BrowseButton_hybrid             matlab.ui.control.StateButton
        fileName_hybrid                 matlab.ui.control.EditField
        NameofFileLabel_2               matlab.ui.control.Label
        ImportedMesh_hybrid             matlab.ui.control.DropDown
        ImportCustomMeshDropDownLabel_3  matlab.ui.control.Label
        GeometryInitializationLabel_3   matlab.ui.control.Label
        UIAxes_hybrid                   matlab.ui.control.UIAxes
    end


    properties (Access = public)
        stopFlag = 0; %stop button flag
    end

    methods (Access = private)

        function [x1,y1,z1,out_p,inp] = initialGeo(app,len,bre,wid,cell_len_x,cell_len_y,cell_len_z,...
                imported_mesh,inp,gridpoints)

            if strcmp(imported_mesh, 'Yes')
                if contains(inp,'.stl') || contains(inp,'.STL')
                else
                    inp= strcat(inp, '.stl');
                end
            end

            period = [len/cell_len_x,bre/cell_len_y,wid/cell_len_z];

            if strcmp(imported_mesh,'No')
                x = linspace(0, period(1), gridpoints);
                y = linspace(0, period(2), gridpoints);
                z = linspace(0, period(3), gridpoints);
                out_p = [];
            elseif strcmp(imported_mesh,'Yes')
                [out_p,x,y,z] = stlinput(app,inp,gridpoints);
                x = x/cell_len_x; y = y/cell_len_y; z = z/cell_len_z;
            end

            [x1,y1,z1] = meshgrid(x,y,z);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure,"center")
            app.UIFigure.Visible = 'on';

            set(app.UIAxes_uni.XLabel,'Color','white'); set(app.UIAxes_uni.YLabel,'Color','white'); set(app.UIAxes_uni.ZLabel,'Color','white');
            set(app.UIAxes_grade.XLabel,'Color','white'); set(app.UIAxes_grade.YLabel,'Color','white'); set(app.UIAxes_grade.ZLabel,'Color','white');
            set(app.UIAxes_hybrid.XLabel,'Color','white'); set(app.UIAxes_hybrid.YLabel,'Color','white'); set(app.UIAxes_hybrid.ZLabel,'Color','white');

            app.Function_uni.Items = sort({'Primitive Schwartz Surface','Double P Surface','Gyroid Surface','Double Gyroid Surface','Diamond Surface','Double Diamond 1 Surface','Double Diamond 2 Surface','IWP Surface','FRD Prime Surface','I2-Y Surface','G Prime 1 Surface','G Prime 2 Surface','Neovius Surface','Lidinoid Surface','D Prime Surface','KP Surface','S Surface',...
                'Fischer-Koch Surface','Bionic Bone 1 Surface','Bionic Bone 2 Surface','Octo 1 Surface','Octo 2 Surface','PN Surface','C(Y) Surface','Black D Surface','FRD Surface','Diamond D Surface','D Surface','Split P Surface'});
            app.Function_uni.Items(end) = {'Custom Function'};
            app.Function_grade.Items = sort({'Primitive Schwartz Surface','Double P Surface','Gyroid Surface','Double Gyroid Surface','Diamond Surface','Double Diamond 1 Surface','Double Diamond 2 Surface','IWP Surface','FRD Prime Surface','I2-Y Surface','G Prime 1 Surface','G Prime 2 Surface','Neovius Surface','Lidinoid Surface','D Prime Surface','KP Surface','S Surface',...
                'Fischer-Koch Surface','Bionic Bone 1 Surface','Bionic Bone 2 Surface','Octo 1 Surface','Octo 2 Surface','PN Surface','C(Y) Surface','Black D Surface','FRD Surface','Diamond D Surface','D Surface','Split P Surface'});
            app.Function_grade.Items(end) = {'Custom Function'};
            app.Function_hybrid.Items = sort({'Primitive Schwartz Surface','Double P Surface','Gyroid Surface','Double Gyroid Surface','Diamond Surface','Double Diamond 1 Surface','Double Diamond 2 Surface','IWP Surface','FRD Prime Surface','I2-Y Surface','G Prime 1 Surface','G Prime 2 Surface','Neovius Surface','Lidinoid Surface','D Prime Surface','KP Surface','S Surface',...
                'Fischer-Koch Surface','Bionic Bone 1 Surface','Bionic Bone 2 Surface','Octo 1 Surface','Octo 2 Surface','PN Surface','C(Y) Surface','Black D Surface','FRD Surface','Diamond D Surface','D Surface','Split P Surface'});
            app.Function_hybrid.Items(end) = {'Custom Function'};
            app.Function_hybrid_2.Items = sort({'Primitive Schwartz Surface','Double P Surface','Gyroid Surface','Double Gyroid Surface','Diamond Surface','Double Diamond 1 Surface','Double Diamond 2 Surface','IWP Surface','FRD Prime Surface','I2-Y Surface','G Prime 1 Surface','G Prime 2 Surface','Neovius Surface','Lidinoid Surface','D Prime Surface','KP Surface','S Surface',...
                'Fischer-Koch Surface','Bionic Bone 1 Surface','Bionic Bone 2 Surface','Octo 1 Surface','Octo 2 Surface','PN Surface','C(Y) Surface','Black D Surface','FRD Surface','Diamond D Surface','D Surface','Split P Surface'});
            app.Function_hybrid_2.Items(end) = {'Custom Function'};
        end

        % Value changed function: StructureType_grade
        function StructureType_gradeValueChanged(app, event)
            if strcmp(app.StructureType_grade.Value, "Sheet")
                app.fillDirection_grade.Enable = 'off';
                app.ThicknessCheckBox_grade.Enable = 'on';
                app.RelativeDensityCheckBox_grade.Enable = 'on';
                if app.ThicknessCheckBox_grade.Value == true
                    app.RelativeDensityCheckBox_grade.Value = false;
                    app.MinimumThicknessSpinner_grade.Enable = "on";
                    app.MaximumThicknessSpinner_grade.Enable = 'on';
                    app.MaximumDensitySpinner_grade.Enable = 'off';
                    app.MinimumDensitySpinner_grade.Enable = 'off';
                else
                    app.RelativeDensityCheckBox_grade.Value = true;
                    app.MinimumThicknessSpinner_grade.Enable = "off";
                    app.MaximumThicknessSpinner_grade.Enable = 'off';
                    app.MaximumDensitySpinner_grade.Enable = 'on';
                    app.MinimumDensitySpinner_grade.Enable = 'on';
                end
            else
                app.fillDirection_grade.Enable = 'on';
                app.ThicknessCheckBox_grade.Enable = 'off';
                app.RelativeDensityCheckBox_grade.Enable = 'off';
                app.MinimumThicknessSpinner_grade.Enable = "off";
                app.MaximumThicknessSpinner_grade.Enable = 'off';
                app.MaximumDensitySpinner_grade.Enable = 'on';
                app.MinimumDensitySpinner_grade.Enable = 'on';

            end
        end

        % Value changed function: StructureType_uni
        function StructureType_uniValueChanged(app, event)
            if strcmp(app.StructureType_uni.Value, "Sheet")
                app.fillDirection_uni.Enable = 'off';
                app.ThicknessCheckBox_uni.Enable = 'on';
                app.RelativeDensityCheckBox_uni.Enable = 'on';
                if app.ThicknessCheckBox_uni.Value == true
                    app.thickness_uni.Enable = "on";
                    app.relativeDensity_uni.Enable = 'off';
                else
                    app.thickness_uni.Enable = "off";
                    app.relativeDensity_uni.Enable = 'on';
                end
            else
                app.fillDirection_uni.Enable = 'on';
                app.thickness_uni.Enable = 'off';
                app.ThicknessCheckBox_uni.Enable = 'off';
                app.RelativeDensityCheckBox_uni.Enable = 'off';
                app.relativeDensity_uni.Enable = 'on';

            end
        end

        % Value changed function: ImportedMesh_uni
        function ImportedMesh_uniValueChanged(app, event)
            if strcmp(app.ImportedMesh_uni.Value, "Yes")
                app.fileName_uni.Editable = 'on';
                app.BrowseButton_uni.Enable = 'on';
                app.cubiodX_uni.Enable = 'off';
                app.cubiodY_uni.Enable = 'off';
                app.cubiodZ_uni.Enable = 'off';
            else
                app.fileName_uni.Editable = 'off';
                app.BrowseButton_uni.Enable = 'off';
                app.cubiodX_uni.Enable = 'on';
                app.cubiodY_uni.Enable = 'on';
                app.cubiodZ_uni.Enable = 'on';
            end
        end

        % Value changed function: ImportedMesh_grade
        function ImportedMesh_gradeValueChanged(app, event)
            if strcmp(app.ImportedMesh_grade.Value, "Yes")
                app.fileName_grade.Editable = 'on';
                app.BrowseButton_grade.Enable = 'on';
                app.cubiodX_grade.Enable = 'off';
                app.cubiodY_grade.Enable = 'off';
                app.cubiodZ_grade.Enable = 'off';
            else
                app.fileName_grade.Editable = 'off';
                app.BrowseButton_grade.Enable = 'off';
                app.cubiodX_grade.Enable = 'on';
                app.cubiodY_grade.Enable = 'on';
                app.cubiodZ_grade.Enable = 'on';
            end
        end

        % Value changed function: Function_uni
        function Function_uniValueChanged(app, event)
            if strcmp(app.Function_uni.Value, "Custom Function")
                app.CustomFunction_uni.Editable = "on";
            else
                app.CustomFunction_uni.Editable = "off";
            end
        end

        % Value changed function: Function_grade
        function Function_gradeValueChanged(app, event)
            if strcmp(app.Function_grade.Value, "Custom Function")
                app.CustomFunction_grade.Editable = "on";
            else
                app.CustomFunction_grade.Editable = "off";
            end
        end

        % Value changed function: Plot_uni
        function Plot_uniValueChanged(app, event)
            try
                app.stopFlag = 0;
                app.Lamp_uni.Color = [1,1,0];
                app.LampLabel_uni.Text = "Running...";
                drawnow;

                if app.stopFlag == 0
                    [x1,y1,z1,out_p,inp] = initialGeo(app,app.cubiodX_uni.Value,app.cubiodY_uni.Value,app.cubiodZ_uni.Value,app.cellX_uni.Value,app.cellY_uni.Value,app.cellZ_uni.Value, app.ImportedMesh_uni.Value, app.fileName_uni.Value, app.Gridpoints_uni.Value);
                end

                drawnow;
                if app.stopFlag == 0
                    [isovalue_new,app_t_new] = rel_den(app,app.relativeDensity_uni.Value,app.ImportedMesh_uni.Value,inp,app.cubiodX_uni.Value,app.cubiodY_uni.Value,app.cubiodZ_uni.Value,app.cellX_uni.Value,app.cellY_uni.Value,app.cellZ_uni.Value,app.thickness_uni.Value,app.Function_uni.Value,app.StructureType_uni.Value, out_p, x1,y1,z1,app.fillDirection_uni.Value,app.RelativeDensityCheckBox_uni.Value,app.CustomFunction_uni.Value);
                end

                drawnow;
                if app.stopFlag == 0
                    [F, V] = imp_model_func(app,out_p, x1,y1,z1,app.cellX_uni.Value,app.cellY_uni.Value,app.cellZ_uni.Value,app_t_new,isovalue_new,app.Function_uni.Value,app.StructureType_uni.Value,app.fillDirection_uni.Value,app.CustomFunction_uni.Value);
                end

                drawnow;
                if app.stopFlag == 0
                    if strcmp(app.ImportedMesh_uni.Value,'Yes')                                                      % Check if the user is using an imported mesh domain
                        nd = stlread(inp);                                                              % read the imported mesh domain
                        ndv = nd.Points; ndf = nd.ConnectivityList;                                     % get face and vertices of imported mesh domain
                        v_ini = stlVolume(ndv',ndf');                                                   % compute the volume of imported mesh domain
                    else
                        v_ini = app.cubiodX_uni.Value*app.cubiodY_uni.Value*app.cubiodZ_uni.Value;                                                            % compute volume of default domain
                    end
                    app.exactRelativeDensity_uni.Value = num2str(abs(stlVolume(V',F'))/v_ini,3);
                end

                drawnow;
                if app.stopFlag == 0
                    trisurf(F,V(:,1),V(:,2),V(:,3), 'Facecolor', '[1, 0.341, 0.200]','Edgecolor','none','Parent',app.UIAxes_uni);
                    axis(app.UIAxes_uni, "equal");
                    camlight(app.UIAxes_uni, 'headlight');
                    light(app.UIAxes_uni,'position',[0 -1 0]);
                    lighting(app.UIAxes_uni,'flat') ;
                    pbaspect(app.UIAxes_uni,[1,1,1])
                end
                drawnow;
                app.Lamp_uni.Color = [0,1,0];
                app.LampLabel_uni.Text = " ";


            catch ME
                if app.stopFlag == 0
                    app.Lamp_uni.Color = [1,0,0];
                    app.LampLabel_uni.Text = 'Error! Check log';
                    if exist("error_log",'dir') == 0
                        mkdir error_log
                    else
                    end
                    logFile = fopen('.\error_log\logfile.txt', 'a');
                    fprintf(logFile, '%s\n', ME.message);
                    rethrow(ME)
                else
                    drawnow;
                    app.Lamp_uni.Color = [0,1,0];
                    app.LampLabel_uni.Text = " ";
                end
            end

        end

        % Button pushed function: ExportSTL_uni
        function ExportSTL_uniButtonPushed(app, event)
            try
                app.stopFlag = 0;
                app.Lamp_uni.Color = [1,1,0];
                app.LampLabel_uni.Text = "Running...";
                drawnow;

                if app.stopFlag == 0
                    [x1,y1,z1,out_p,inp] = initialGeo(app,app.cubiodX_uni.Value,app.cubiodY_uni.Value,app.cubiodZ_uni.Value,app.cellX_uni.Value,app.cellY_uni.Value,app.cellZ_uni.Value, app.ImportedMesh_uni.Value, app.fileName_uni.Value, app.Gridpoints_uni.Value);
                end

                drawnow;
                if app.stopFlag == 0
                    [isovalue_new,app_t_new] = rel_den(app,app.relativeDensity_uni.Value,app.ImportedMesh_uni.Value,inp,app.cubiodX_uni.Value,app.cubiodY_uni.Value,app.cubiodZ_uni.Value,app.cellX_uni.Value,app.cellY_uni.Value,app.cellZ_uni.Value,app.thickness_uni.Value,app.Function_uni.Value,app.StructureType_uni.Value,out_p,x1,y1,z1,app.fillDirection_uni.Value,app.RelativeDensityCheckBox_uni.Value,app.CustomFunction_uni.Value);
                end

                drawnow;
                if app.stopFlag == 0
                    [F, V] = imp_model_func(app,out_p, x1,y1,z1,app.cellX_uni.Value,app.cellY_uni.Value,app.cellZ_uni.Value,app_t_new,isovalue_new,app.Function_uni.Value,app.StructureType_uni.Value,app.fillDirection_uni.Value,app.CustomFunction_uni.Value);
                end

                drawnow;
                if app.stopFlag == 0
                    if strcmp(app.ImportedMesh_uni.Value,'Yes')                                                      % Check if the user is using an imported mesh domain
                        nd = stlread(inp);                                                              % read the imported mesh domain
                        ndv = nd.Points; ndf = nd.ConnectivityList;                                     % get face and vertices of imported mesh domain
                        v_ini = stlVolume(ndv',ndf');                                                   % compute the volume of imported mesh domain
                    else
                        v_ini = app.cubiodX_uni.Value*app.cubiodY_uni.Value*app.cubiodZ_uni.Value;                                                            % compute volume of default domain
                    end
                    app.exactRelativeDensity_uni.Value = num2str(abs(stlVolume(V',F'))/v_ini,3);
                end

                drawnow;
                if ~app.stopFlag
                    [file, path] = uiputfile('.stl','Save As');
                    if ~isequal(file, 0)  % Check if the user canceled the dialog
                        fileName = fullfile(path,file);
                        stlwrite2(fileName, F, V); %creates the object on an stl file
                    else
                    end
                end

                app.Lamp_uni.Color = [0,1,0];
                app.LampLabel_uni.Text = " ";

            catch ME
                if app.stopFlag == 0
                app.Lamp_uni.Color = [1,0,0];
                app.LampLabel_uni.Text = 'Error! Check log';
                if exist("error_log",'dir') == 0
                    mkdir error_log
                else
                end
                logFile = fopen('.\error_log\logfile.txt', 'a');
                fprintf(logFile, '%s\n', ME.message);
                rethrow(ME)
                else
                    drawnow;
                    app.Lamp_uni.Color = [0,1,0];
                    app.LampLabel_uni.Text = " ";
                end
            end
        end

        % Value changed function: Plot_grade
        function Plot_gradeValueChanged(app, event)
            try
                app.stopFlag = 0;
                app.Lamp_grade.Color = [1,1,0];
                app.LampLabel_grade.Text = "Running...";
                drawnow;

                if ~app.stopFlag
                    [x1,y1,z1,out_p,inp] = initialGeo(app,app.cubiodX_grade.Value,app.cubiodY_grade.Value,app.cubiodZ_grade.Value,app.cellX_grade.Value,app.cellY_grade.Value,app.cellZ_grade.Value, app.ImportedMesh_grade.Value, app.fileName_grade.Value, app.Gridpoints_grade.Value);
                end

                drawnow;
                if ~app.stopFlag
                    [isovalue_new,app_t_new] = grading(app,app.MinimumDensitySpinner_grade.Value,app.MaximumDensitySpinner_grade.Value,app.MinimumThicknessSpinner_grade.Value,app.MaximumThicknessSpinner_grade.Value,app.ImportedMesh_grade.Value,inp,app.cubiodX_grade.Value,app.cubiodY_grade.Value,app.cubiodZ_grade.Value,app.cellX_grade.Value,app.cellY_grade.Value,app.cellZ_grade.Value,...
                        app.Function_grade.Value,app.StructureType_grade.Value,out_p,x1,y1,z1,app.fillDirection_grade.Value,app.GradingEquation_grade.Value,app.coefficientA_grade.Value,app.coefficientB_grade.Value,app.coefficientC_grade.Value,app.Radius_grade.Value,app.RelativeDensityCheckBox_grade.Value,app.CustomFunction_grade.Value, app.positionX_grade.Value, app.positionY_grade.Value,app.positionZ_grade.Value);
                end

                drawnow;
                if ~app.stopFlag
                    [F, V] = imp_model_func(app,out_p, x1,y1,z1,app.cellX_grade.Value,app.cellY_grade.Value,app.cellZ_grade.Value,app_t_new,isovalue_new,app.Function_grade.Value,app.StructureType_grade.Value,app.fillDirection_grade.Value,app.CustomFunction_grade.Value);
                end

                drawnow;
                if ~app.stopFlag
                    if strcmp(app.ImportedMesh_grade.Value,'Yes')                                                      % Check if the user is using an imported mesh domain
                        nd = stlread(inp);                                                              % read the imported mesh domain
                        ndv = nd.Points; ndf = nd.ConnectivityList;                                     % get face and vertices of imported mesh domain
                        v_ini = stlVolume(ndv',ndf');                                                   % compute the volume of imported mesh domain
                    else
                        v_ini = app.cubiodX_grade.Value*app.cubiodY_grade.Value*app.cubiodZ_grade.Value;                                                            % compute volume of default domain
                    end
                    app.exactRelativeDensity_grade.Value = num2str(abs(stlVolume(V',F'))/v_ini,3);
                end

                drawnow;
                if ~app.stopFlag
                    trisurf(F,V(:,1),V(:,2),V(:,3), 'Facecolor', '[1, 0.341, 0.200]','Edgecolor','none','Parent',app.UIAxes_grade);
                    axis(app.UIAxes_grade, "equal");
                    camlight(app.UIAxes_grade, 'headlight');
                    light(app.UIAxes_grade,'position',[0 -1 0]);
                    lighting(app.UIAxes_grade,'flat') ;
                    pbaspect(app.UIAxes_grade,[1,1,1])
                end
                app.Lamp_grade.Color = [0,1,0];
                app.LampLabel_grade.Text = " ";

            catch ME
                if app.stopFlag == 0
                app.Lamp_grade.Color = [1,0,0];
                app.LampLabel_grade.Text = 'Error! Check log'; drawnow;
                if exist("error_log",'dir') == 0
                    mkdir error_log
                else
                end
                logFile = fopen('.\error_log\logfile.txt', 'a');
                fprintf(logFile, '%s\n', ME.message);
                rethrow(ME)
                else
                    drawnow;
                    app.Lamp_grade.Color = [0,1,0];
                    app.LampLabel_grade.Text = " ";
                    drawnow;
                end
            end
        end

        % Button pushed function: ExportSTL_grade
        function ExportSTL_gradeButtonPushed(app, event)
            try
                app.stopFlag = 0;
                app.Lamp_grade.Color = [1,1,0];
                app.LampLabel_grade.Text = "Running...";
                drawnow;

                if ~app.stopFlag
                    [x1,y1,z1,out_p,inp] = initialGeo(app,app.cubiodX_grade.Value,app.cubiodY_grade.Value,app.cubiodZ_grade.Value,app.cellX_grade.Value,app.cellY_grade.Value,app.cellZ_grade.Value, app.ImportedMesh_grade.Value, app.fileName_grade.Value, app.Gridpoints_grade.Value);
                end

                drawnow;
                if ~app.stopFlag
                    [isovalue_new,app_t_new] = grading(app,app.MinimumDensitySpinner_grade.Value,app.MaximumDensitySpinner_grade.Value,app.MinimumThicknessSpinner_grade.Value,app.MaximumThicknessSpinner_grade.Value,app.ImportedMesh_grade.Value,inp,app.cubiodX_grade.Value,app.cubiodY_grade.Value,app.cubiodZ_grade.Value,app.cellX_grade.Value,app.cellY_grade.Value,app.cellZ_grade.Value,...
                        app.Function_grade.Value,app.StructureType_grade.Value,out_p,x1,y1,z1,app.fillDirection_grade.Value,app.GradingEquation_grade.Value,app.coefficientA_grade.Value,app.coefficientB_grade.Value,app.coefficientC_grade.Value,app.Radius_grade.Value,app.RelativeDensityCheckBox_grade.Value,app.CustomFunction_grade.Value, app.positionX_grade.Value, app.positionY_grade.Value,app.positionZ_grade.Value);
                end

                drawnow;
                if ~app.stopFlag
                    [F, V] = imp_model_func(app,out_p, x1,y1,z1,app.cellX_grade.Value,app.cellY_grade.Value,app.cellZ_grade.Value,app_t_new,isovalue_new,app.Function_grade.Value,app.StructureType_grade.Value,app.fillDirection_grade.Value,app.CustomFunction_grade.Value);
                end

                drawnow;
                if ~app.stopFlag
                    if strcmp(app.ImportedMesh_grade.Value,'Yes')                                                      % Check if the user is using an imported mesh domain
                        nd = stlread(inp);                                                              % read the imported mesh domain
                        ndv = nd.Points; ndf = nd.ConnectivityList;                                     % get face and vertices of imported mesh domain
                        v_ini = stlVolume(ndv',ndf');                                                   % compute the volume of imported mesh domain
                    else
                        v_ini = app.cubiodX_grade.Value*app.cubiodY_grade.Value*app.cubiodZ_grade.Value;                                                            % compute volume of default domain
                    end
                    app.exactRelativeDensity_grade.Value = num2str(abs(stlVolume(V',F'))/v_ini,3);
                end

                drawnow;
                if ~app.stopFlag
                    [file, path] = uiputfile('.stl','Save As');
                    if ~isequal(file, 0)  % Check if the user canceled the dialog
                        fileName = fullfile(path,file);
                        stlwrite2(fileName, F, V); %creates the object on an stl file
                    else
                    end
                end
                app.Lamp_grade.Color = [0,1,0];
                app.LampLabel_grade.Text = " ";

            catch ME
                if app.stopFlag == 0
                    app.Lamp_grade.Color = [1,0,0];
                    app.LampLabel_grade.Text = 'Error! Check log';
                    if exist("error_log",'dir') == 0
                        mkdir error_log
                    else
                    end
                    logFile = fopen('.\error_log\logfile.txt', 'a');
                    fprintf(logFile, '%s\n', ME.message);
                    rethrow(ME)
                else
                    drawnow;
                    app.Lamp_grade.Color = [0,1,0];
                    app.LampLabel_grade.Text = " ";
                end
            end
        end

        % Value changed function: Plot_hybrid
        function Plot_hybridValueChanged(app, event)
            try
                app.stopFlag = 0;
                app.Lamp_hybrid.Color = [1,1,0];
                app.LampLabel_hybrid.Text = "Running...";
                drawnow;

                if ~app.stopFlag
                    [x1,y1,z1,out_p,inp] = initialGeo(app,app.cubiodX_hybrid.Value,app.cubiodY_hybrid.Value,app.cubiodZ_hybrid.Value,app.cellX_hybrid.Value,app.cellY_hybrid.Value,app.cellZ_hybrid.Value, app.ImportedMesh_hybrid.Value, app.fileName_hybrid.Value, app.Gridpoints_hybrid.Value);
                end

                drawnow;
                if ~app.stopFlag
                    [F,V] = hybrid_func(app,app.relativeDensity_hybrid.Value,app.thickness_hybrid.Value,app.ImportedMesh_hybrid.Value,inp,app.cubiodX_hybrid.Value,app.cubiodY_hybrid.Value,app.cubiodZ_hybrid.Value,out_p,x1,y1,z1,app.cellX_hybrid.Value,app.cellY_hybrid.Value,app.cellZ_hybrid.Value,app.Function_hybrid.Value,app.Function_hybrid_2.Value,app.StructureType_hybrid.Value,app.fillDirection_hybrid.Value,app.CustomFunction_hybrid.Value,app.CustomFunction_hybrid_2.Value,app.k.Value,app.hybridEquation.Value,app.coefficientA_hybrid.Value,app.coefficientB_hybrid.Value,app.coefficientC_hybrid.Value,app.radius_hybrid.Value,app.RelativeDensityCheckBox_hybrid.Value,app.positionX_hybrid.Value,app.positionY_hybrid.Value,app.positionZ_hybrid.Value);
                end

                drawnow;
                if ~app.stopFlag
                    if strcmp(app.ImportedMesh_hybrid.Value,'Yes')                                                      % Check if the user is using an imported mesh domain
                        nd = stlread(inp);                                                              % read the imported mesh domain
                        ndv = nd.Points; ndf = nd.ConnectivityList;                                     % get face and vertices of imported mesh domain
                        v_ini = stlVolume(ndv',ndf');                                                   % compute the volume of imported mesh domain
                    else
                        v_ini = app.cubiodX_hybrid.Value*app.cubiodY_hybrid.Value*app.cubiodZ_hybrid.Value;                                                            % compute volume of default domain
                    end
                    app.exactRelativeDensity_hybrid.Value = num2str(abs(stlVolume(V',F'))/v_ini,3);
                end

                drawnow;
                if ~app.stopFlag
                    trisurf(F,V(:,1),V(:,2),V(:,3), 'Facecolor', '[1, 0.341, 0.200]','Edgecolor','none','Parent',app.UIAxes_hybrid);
                    axis(app.UIAxes_hybrid, "equal");
                    camlight(app.UIAxes_hybrid, 'headlight');
                    light(app.UIAxes_hybrid,'position',[0 -1 0]);
                    lighting(app.UIAxes_hybrid,'flat') ;
                    pbaspect(app.UIAxes_hybrid,[1,1,1])
                end
                app.Lamp_hybrid.Color = [0,1,0];
                app.LampLabel_hybrid.Text = " ";

            catch ME
                if app.stopFlag == 0
                    app.Lamp_hybrid.Color = [1,0,0];
                    app.LampLabel_hybrid.Text = 'Error! Check log';
                    if exist("error_log",'dir') == 0
                        mkdir error_log
                    else
                    end
                    logFile = fopen('.\error_log\logfile.txt', 'a');
                    fprintf(logFile, '%s\n', ME.message);
                    rethrow(ME)
                else
                    drawnow;
                    app.Lamp_hybrid.Color = [0,1,0];
                    app.LampLabel_hybrid.Text = " ";
                end
            end

        end

        % Button pushed function: ExportSTL_hybrid
        function ExportSTL_hybridButtonPushed(app, event)
            try
                app.stopFlag = 0;
                app.Lamp_hybrid.Color = [1,1,0];
                app.LampLabel_hybrid.Text = "Running...";
                drawnow;

                if ~app.stopFlag
                    [x1,y1,z1,out_p,inp] = initialGeo(app,app.cubiodX_hybrid.Value,app.cubiodY_hybrid.Value,app.cubiodZ_hybrid.Value,app.cellX_hybrid.Value,app.cellY_hybrid.Value,app.cellZ_hybrid.Value, app.ImportedMesh_hybrid.Value, app.fileName_hybrid.Value, app.Gridpoints_hybrid.Value);
                end

                drawnow;
                if ~app.stopFlag
                    [F,V] = hybrid_func(app,app.relativeDensity_hybrid.Value,app.thickness_hybrid.Value,app.ImportedMesh_hybrid.Value,inp,app.cubiodX_hybrid.Value,app.cubiodY_hybrid.Value,app.cubiodZ_hybrid.Value,out_p,x1,y1,z1,app.cellX_hybrid.Value,app.cellY_hybrid.Value,app.cellZ_hybrid.Value,app.Function_hybrid.Value,app.Function_hybrid_2.Value,app.StructureType_hybrid.Value,app.fillDirection_hybrid.Value,app.CustomFunction_hybrid.Value,app.CustomFunction_hybrid_2.Value,app.k.Value,app.hybridEquation.Value,app.coefficientA_hybrid.Value,app.coefficientB_hybrid.Value,app.coefficientC_hybrid.Value,app.radius_hybrid.Value,app.RelativeDensityCheckBox_hybrid.Value,app.positionX_hybrid.Value,app.positionY_hybrid.Value,app.positionZ_hybrid.Value);
                end

                drawnow;
                if ~app.stopFlag
                    if strcmp(app.ImportedMesh_hybrid.Value,'Yes')                                                      % Check if the user is using an imported mesh domain
                        nd = stlread(inp);                                                              % read the imported mesh domain
                        ndv = nd.Points; ndf = nd.ConnectivityList;                                     % get face and vertices of imported mesh domain
                        v_ini = stlVolume(ndv',ndf');                                                   % compute the volume of imported mesh domain
                    else
                        v_ini = app.cubiodX_hybrid.Value*app.cubiodY_hybrid.Value*app.cubiodZ_hybrid.Value;                                                            % compute volume of default domain
                    end
                    app.exactRelativeDensity_hybrid.Value = num2str(abs(stlVolume(V',F'))/v_ini,3);
                end

                drawnow;
                if ~app.stopFlag
                    [file, path] = uiputfile('.stl','Save As');
                    if ~isequal(file, 0)  % Check if the user canceled the dialog
                        fileName = fullfile(path,file);
                        stlwrite2(fileName, F, V); %creates the object on an stl file
                    else
                    end
                end
                app.Lamp_hybrid.Color = [0,1,0];
                app.LampLabel_hybrid.Text = " ";

            catch ME
                if app.stopFlag == 0
                    app.Lamp_hybrid.Color = [1,0,0];
                    app.LampLabel_hybrid.Text = 'Error! Check log';
                    if exist("error_log",'dir') == 0
                        mkdir error_log
                    else
                    end
                    logFile = fopen('.\error_log\logfile.txt', 'a');
                    fprintf(logFile, '%s\n', ME.message);
                    rethrow(ME)
                else
                    drawnow;
                    app.Lamp_hybrid.Color = [0,1,0];
                    app.LampLabel_hybrid.Text = " ";
                end
            end
        end

        % Value changed function: ThicknessCheckBox_uni
        function ThicknessCheckBox_uniValueChanged(app, event)
            app.ThicknessCheckBox_uni.Value = true;
            app.RelativeDensityCheckBox_uni.Value = false;
            app.thickness_uni.Enable = "on";
            app.relativeDensity_uni.Enable = 'off';
        end

        % Value changed function: RelativeDensityCheckBox_uni
        function RelativeDensityCheckBox_uniValueChanged(app, event)
            app.ThicknessCheckBox_uni.Value = false;
            app.RelativeDensityCheckBox_uni.Value = true;
            app.thickness_uni.Enable = "off";
            app.relativeDensity_uni.Enable = 'on';
        end

        % Value changed function: ThicknessCheckBox_grade
        function ThicknessCheckBox_gradeValueChanged(app, event)
            app.ThicknessCheckBox_grade.Value = true;
            app.RelativeDensityCheckBox_grade.Value = false;
            app.MinimumThicknessSpinner_grade.Enable = "on";
            app.MaximumThicknessSpinner_grade.Enable = 'on';
            app.MaximumDensitySpinner_grade.Enable = 'off';
            app.MinimumDensitySpinner_grade.Enable = 'off';
        end

        % Value changed function: RelativeDensityCheckBox_grade
        function RelativeDensityCheckBox_gradeValueChanged(app, event)
            app.ThicknessCheckBox_grade.Value = false;
            app.RelativeDensityCheckBox_grade.Value = true;
            app.MinimumThicknessSpinner_grade.Enable = "off";
            app.MaximumThicknessSpinner_grade.Enable = 'off';
            app.MaximumDensitySpinner_grade.Enable = 'on';
            app.MinimumDensitySpinner_grade.Enable = 'on';
        end

        % Value changed function: GradingEquation_grade
        function GradingEquation_gradeValueChanged(app, event)
            switch app.GradingEquation_grade.Value
                case "Linear"
                    app.GradingFormula.Value = "AX + BY + CZ";
                case "Quadratic"
                    app.GradingFormula.Value = "AX^2 + BY^2 + CZ^2";
                case "Cubic"
                    app.GradingFormula.Value = "AX^3 + BY^3 + CZ^3";
                case "Circular"
                    app.GradingFormula.Value = "sqrt(AX^2 + BY^2 + CZ^2) - R";
                case "Exponential"
                    app.GradingFormula.Value = "e^(AX) + e^(BY) + e^(CZ)";
                case "Trigonometric - Sin"
                    app.GradingFormula.Value = "sin(AX) + sin(BY) + sin(CZ)";
                case "Trigonometric - Cosine"
                    app.GradingFormula.Value = "cos(AX) + cos(BY) + cos(CZ)";
            end

            if strcmp(app.GradingEquation_grade.Value, 'Circular')
                app.Radius_grade.Enable = 'on';
            else
                app.Radius_grade.Enable = 'off';
            end
        end

        % Value changed function: BrowseButton_grade
        function BrowseButton_gradeValueChanged(app, event)
            [filename,path] = uigetfile('*.stl');
            file = fullfile(path,filename);
            if ~isequal(filename, 0)  % Check if the user canceled the dialog
                app.fileName_grade.Value = file;
            else
            end
        end

        % Value changed function: BrowseButton_uni
        function BrowseButton_uniValueChanged(app, event)
            [filename,path] = uigetfile('*.stl');
            file = fullfile(path,filename);
            if ~isequal(filename, 0)  % Check if the user canceled the dialog
                app.fileName_uni.Value = file;
            else
            end
        end

        % Value changed function: BrowseButton_hybrid
        function BrowseButton_hybridValueChanged(app, event)
            [filename,path] = uigetfile('*.stl');
            file = fullfile(path,filename);
            if ~isequal(filename, 0)  % Check if the user canceled the dialog
                app.fileName_hybrid.Value = file;
            else
            end
        end

        % Value changing function: positionX_grade
        function positionX_gradeValueChanging(app, event)
            app.positionX_grade.Limits = [0 app.cubiodX_grade.Value];
        end

        % Value changing function: positionY_grade
        function positionY_gradeValueChanging(app, event)
            app.positionY_grade.Limits = [0 app.cubiodY_grade.Value];
        end

        % Value changing function: positionZ_grade
        function positionZ_gradeValueChanging(app, event)
            app.positionZ_grade.Limits = [0 app.cubiodZ_grade.Value];
        end

        % Value changed function: ImportedMesh_hybrid
        function ImportedMesh_hybridValueChanged(app, event)
            if strcmp(app.ImportedMesh_hybrid.Value, "Yes")
                app.fileName_hybrid.Editable = 'on';
                app.BrowseButton_hybrid.Enable = 'on';
                app.cubiodX_hybrid.Enable = 'off';
                app.cubiodY_hybrid.Enable = 'off';
                app.cubiodZ_hybrid.Enable = 'off';

            else
                app.fileName_hybrid.Editable = 'off';
                app.BrowseButton_hybrid.Enable = 'off';
                app.cubiodX_hybrid.Enable = 'on';
                app.cubiodY_hybrid.Enable = 'on';
                app.cubiodZ_hybrid.Enable = 'on';
            end
        end

        % Value changed function: Function_hybrid
        function Function_hybridValueChanged(app, event)
            if strcmp(app.Function_hybrid.Value, "Custom Function")
                app.CustomFunction_hybrid.Editable = "on";
            else
                app.CustomFunction_hybrid.Editable = "off";
            end
        end

        % Value changed function: Function_hybrid_2
        function Function_hybrid_2ValueChanged(app, event)
            if strcmp(app.Function_hybrid_2.Value, "Custom Function")
                app.CustomFunction_hybrid_2.Editable = "on";
            else
                app.CustomFunction_hybrid_2.Editable = "off";
            end
        end

        % Value changed function: CustomFunction_hybrid
        function CustomFunction_hybridValueChanged(app, event)
            if strcmp(app.StructureType_hybrid.Value, "Sheet")
                app.fillDirection_hybrid.Enable = 'off';
                app.ThicknessCheckBox_hybrid.Enable = 'on';
                app.RelativeDensityCheckBox_hybrid.Enable = 'on';
                if app.ThicknessCheckBox_hybrid.Value == true
                    app.thickness_hybrid.Enable = "on";
                    app.relativeDensity_hybrid.Enable = 'off';
                else
                    app.thickness_hybrid.Enable = "off";
                    app.relativeDensity_hybrid.Enable = 'on';
                end
            else
                app.fillDirection_hybrid.Enable = 'on';
                app.thickness_hybrid.Enable = 'off';
                app.ThicknessCheckBox_hybrid.Enable = 'off';
                app.RelativeDensityCheckBox_hybrid.Enable = 'off';
                app.relativeDensity_hybrid.Enable = 'on';

            end
        end

        % Value changed function: ThicknessCheckBox_hybrid
        function ThicknessCheckBox_hybridValueChanged(app, event)
            app.ThicknessCheckBox_hybrid.Value = true;
            app.RelativeDensityCheckBox_hybrid.Value = false;
            app.thickness_hybrid.Enable = "on";
            app.relativeDensity_hybrid.Enable = 'off';
        end

        % Value changed function: RelativeDensityCheckBox_hybrid
        function RelativeDensityCheckBox_hybridValueChanged(app, event)
            app.ThicknessCheckBox_hybrid.Value = false;
            app.RelativeDensityCheckBox_hybrid.Value = true;
            app.thickness_hybrid.Enable = "off";
            app.relativeDensity_hybrid.Enable = 'on';
        end

        % Value changed function: hybridEquation
        function hybridEquationValueChanged(app, event)
            switch app.hybridEquation.Value
                case "Linear"
                    app.hybridFormula.Value = "AX + BY + CZ";
                case "Quadratic"
                    app.hybridFormula.Value = "AX^2 + BY^2 + CZ^2";
                case "Cubic"
                    app.hybridFormula.Value = "AX^3 + BY^3 + CZ^3";
                case "Circular"
                    app.hybridFormula.Value = "sqrt(AX^2 + BY^2 + CZ^2) - R";
                case "Exponential"
                    app.hybridFormula.Value = "e^(AX) + e^(BY) + e^(CZ)";
                case "Trigonometric - Sin"
                    app.hybridFormula.Value = "sin(AX) + sin(BY) + sin(CZ)";
                case "Trigonometric - Cosine"
                    app.hybridFormula.Value = "cos(AX) + cos(BY) + cos(CZ)";
            end

            if strcmp(app.hybridEquation.Value, 'Circular')
                app.radius_hybrid.Enable = 'on';
            else
                app.radius_hybrid.Enable = 'off';
            end
        end

        % Value changed function: StructureType_hybrid
        function StructureType_hybridValueChanged(app, event)
            if strcmp(app.StructureType_hybrid.Value, "Sheet")
                app.fillDirection_hybrid.Enable = 'off';
                app.ThicknessCheckBox_hybrid.Enable = 'on';
                app.RelativeDensityCheckBox_hybrid.Enable = 'on';
                if app.ThicknessCheckBox_hybrid.Value == true
                    app.RelativeDensityCheckBox_hybrid.Value = false;
                    app.thickness_hybrid.Enable = 'on';
                    app.relativeDensity_hybrid.Enable = 'off';
                else
                    app.RelativeDensityCheckBox_hybrid.Value = true;
                    app.thickness_hybrid.Enable = 'off';
                    app.relativeDensity_hybrid.Enable = 'on';
                end
            else
                app.fillDirection_hybrid.Enable = 'on';
                app.ThicknessCheckBox_hybrid.Enable = 'off';
                app.RelativeDensityCheckBox_hybrid.Enable = 'off';
                app.thickness_hybrid.Enable = 'off';
                app.relativeDensity_hybrid.Enable = 'on';

            end
        end

        % Value changed function: Stop_uni
        function Stop_uniValueChanged(app, event)
            if strcmp(app.LampLabel_uni.Text, "Running...")
                app.stopFlag = 1;
                app.Lamp_uni.Color = [1,0,0];
                app.LampLabel_uni.Text = "Stopping...";
                pause(1)
            end

        end

        % Value changed function: Stop_hybrid
        function Stop_hybridValueChanged(app, event)
            if strcmp(app.LampLabel_hybrid.Text, "Running...")
                app.stopFlag = 1;
                app.Lamp_hybrid.Color = [1,0,0];
                app.LampLabel_hybrid.Text = "Stopping...";
                pause(1)
            end
        end

        % Value changed function: Stop_grade
        function Stop_gradeValueChanged(app, event)
            if strcmp(app.LampLabel_grade.Text, "Running...")
                app.stopFlag = 1;
                app.Lamp_grade.Color = [1,0,0];
                app.LampLabel_grade.Text = "Stopping...";
                pause(1)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.UniformDensityTab = uitab(app.TabGroup);
            app.UniformDensityTab.Title = 'Uniform Density';
            app.UIAxes_uni = uiaxes(app.UniformDensityTab);
            xlabel(app.UIAxes_uni, 'X')
            ylabel(app.UIAxes_uni, 'Y')
            zlabel(app.UIAxes_uni, 'Z')
            app.UIAxes_uni.XTick = [];
            app.UIAxes_uni.XTickLabel = '';
            app.UIAxes_uni.YTick = [];
            app.UIAxes_uni.YTickLabel = '';
            app.UIAxes_uni.ZTick = [];
            app.Panel = uipanel(app.UniformDensityTab);
            app.GeometryInitializationLabel = uilabel(app.Panel);
            app.GeometryInitializationLabel.Text = 'Geometry Initialization';
            app.ImportCustomMeshDropDownLabel = uilabel(app.Panel);
            app.ImportCustomMeshDropDownLabel.Tooltip = {'Would you like to import a custom mesh?'};
            app.ImportCustomMeshDropDownLabel.Text = 'Import Custom Mesh:';
            app.ImportedMesh_uni = uidropdown(app.Panel);
            app.ImportedMesh_uni.Items = {'No', 'Yes'};
            app.ImportedMesh_uni.ValueChangedFcn = createCallbackFcn(app, @ImportedMesh_uniValueChanged, true);
            app.ImportedMesh_uni.Value = 'No';
            app.NameofFileLabel = uilabel(app.Panel);
            app.NameofFileLabel.Tooltip = {'Provide the name of the imported mesh file or "Browse" to locate it on your computer'};
            app.NameofFileLabel.Text = 'Name of file:';
            app.fileName_uni = uieditfield(app.Panel, 'text');
            app.fileName_uni.Editable = 'off';
            app.BrowseButton_uni = uibutton(app.Panel, 'state');
            app.BrowseButton_uni.ValueChangedFcn = createCallbackFcn(app, @BrowseButton_uniValueChanged, true);
            app.BrowseButton_uni.Enable = 'off';
            app.BrowseButton_uni.Text = 'Browse';
            app.NumberofGridpointsSpinnerLabel = uilabel(app.Panel);
            app.NumberofGridpointsSpinnerLabel.Tooltip = {'Specify the number of gridpoints to use for generating the plot'};
            app.NumberofGridpointsSpinnerLabel.Text = 'Number of Gridpoints:';
            app.Gridpoints_uni = uispinner(app.Panel);
            app.Gridpoints_uni.Value = 100;
            app.CuboidDimensionsLabel_2 = uilabel(app.Panel);
            app.CuboidDimensionsLabel_2.Tooltip = {'Enter the length, width, and height values for the bounding box'};
            app.CuboidDimensionsLabel_2.Text = 'Cuboid Dimensions:';
            app.XSpinnerLabel_4 = uilabel(app.Panel);
            app.XSpinnerLabel_4.Text = 'X:';
            app.cubiodX_uni = uispinner(app.Panel);
            app.cubiodX_uni.Limits = [0 Inf];
            app.cubiodX_uni.Value = 60;
            app.YLabel = uilabel(app.Panel);
            app.YLabel.Text = 'Y:';
            app.cubiodY_uni = uispinner(app.Panel);
            app.cubiodY_uni.Limits = [0 Inf];
            app.cubiodY_uni.Value = 60;
            app.ZLabel = uilabel(app.Panel);
            app.ZLabel.Text = 'Z:';
            app.cubiodZ_uni = uispinner(app.Panel);
            app.cubiodZ_uni.Limits = [0 Inf];
            app.cubiodZ_uni.Value = 60;
            app.CellDimensionsLabel = uilabel(app.Panel);
            app.CellDimensionsLabel.Tooltip = {'Enter the length, width, and height values for the unit cell'};
            app.CellDimensionsLabel.Text = 'Cell Dimensions:';
            app.XSpinnerLabel_5 = uilabel(app.Panel);
            app.XSpinnerLabel_5.Text = 'X:';
            app.cellX_uni = uispinner(app.Panel);
            app.cellX_uni.Limits = [0 Inf];
            app.cellX_uni.Value = 10;
            app.YLabel_2 = uilabel(app.Panel);
            app.YLabel_2.Text = 'Y:';
            app.cellY_uni = uispinner(app.Panel);
            app.cellY_uni.Limits = [0 Inf];
            app.cellY_uni.Value = 10;
            app.ZLabel_2 = uilabel(app.Panel);
            app.ZLabel_2.Text = 'Z:';
            app.cellZ_uni = uispinner(app.Panel);
            app.cellZ_uni.Limits = [0 Inf];
            app.cellZ_uni.Value = 60;
            app.Panel_2 = uipanel(app.UniformDensityTab);
            app.TPMSParametersLabel = uilabel(app.Panel_2);
            app.TPMSParametersLabel.Text = 'TPMS Parameters';
            app.FunctionLabel = uilabel(app.Panel_2);
            app.FunctionLabel.Tooltip = {'Select a predefined TPMS function'};
            app.FunctionLabel.Text = 'Function:';
            app.Function_uni = uidropdown(app.Panel_2);
            app.Function_uni.Items = {'Primitive Schwartz Surface', 'Gyroid', 'Diamond', 'I-WP', 'F-RD', 'I2-Y', 'Custom Function'};
            app.Function_uni.ValueChangedFcn = createCallbackFcn(app, @Function_uniValueChanged, true);
            app.Function_uni.Tooltip = {''};
            app.Function_uni.Value = 'Primitive Schwartz Surface';
            app.StructureType_uni = uiswitch(app.Panel_2, 'slider');
            app.StructureType_uni.Items = {'Sheet', 'Solid'};
            app.StructureType_uni.ValueChangedFcn = createCallbackFcn(app, @StructureType_uniValueChanged, true);
            app.StructureType_uni.Value = 'Sheet';
            app.CustomFunctionEditFieldLabel = uilabel(app.Panel_2);
            app.CustomFunctionEditFieldLabel.Tooltip = {'If using a custom function, enter the mathematical expression for your TPMS'};
            app.CustomFunctionEditFieldLabel.Text = 'Custom Function:';
            app.CustomFunction_uni = uieditfield(app.Panel_2, 'text');
            app.CustomFunction_uni.Editable = 'off';
            app.CustomFunction_uni.Tooltip = {''};
            app.FillDirectionLabel = uilabel(app.Panel_2);
            app.FillDirectionLabel.Tooltip = {'Choose whether to fill the solid structure above or below the determined isovalue'};
            app.FillDirectionLabel.Text = 'Fill Direction:';
            app.fillDirection_uni = uidropdown(app.Panel_2);
            app.fillDirection_uni.Items = {'Fill Above Isovalue', 'Fill Below Isovalue'};
            app.fillDirection_uni.Enable = 'off';
            app.fillDirection_uni.Tooltip = {''};
            app.fillDirection_uni.Value = 'Fill Above Isovalue';
            app.Panel_3 = uipanel(app.UniformDensityTab);
            app.UniformDensityLabel = uilabel(app.Panel_3);
            app.UniformDensityLabel.Tooltip = {''};
            app.UniformDensityLabel.Text = 'Uniform Density';
            app.ThicknessCheckBox_uni = uicheckbox(app.Panel_3);
            app.ThicknessCheckBox_uni.ValueChangedFcn = createCallbackFcn(app, @ThicknessCheckBox_uniValueChanged, true);
            app.ThicknessCheckBox_uni.Tooltip = {''};
            app.ThicknessCheckBox_uni.Text = 'Thickness';
            app.ThicknessCheckBox_uni.Value = true;
            app.RelativeDensityCheckBox_uni = uicheckbox(app.Panel_3);
            app.RelativeDensityCheckBox_uni.ValueChangedFcn = createCallbackFcn(app, @RelativeDensityCheckBox_uniValueChanged, true);
            app.RelativeDensityCheckBox_uni.Tooltip = {''};
            app.RelativeDensityCheckBox_uni.Text = 'Relative Density';
            app.ThicknessLabel = uilabel(app.Panel_3);
            app.ThicknessLabel.Tooltip = {'Set the desired thickness value'};
            app.ThicknessLabel.Text = 'Thickness:';
            app.thickness_uni = uispinner(app.Panel_3);
            app.thickness_uni.Step = 0.1;
            app.thickness_uni.Limits = [0.01 Inf];
            app.thickness_uni.Tooltip = {''};
            app.thickness_uni.Value = 0.5;
            app.RelativeDensitySpinnerLabel = uilabel(app.Panel_3);
            app.RelativeDensitySpinnerLabel.Tooltip = {'Set the desired relative density value to a value between 0 and 1'};
            app.RelativeDensitySpinnerLabel.Text = 'Relative Density:';
            app.relativeDensity_uni = uispinner(app.Panel_3);
            app.relativeDensity_uni.Step = 0.1;
            app.relativeDensity_uni.Limits = [0.01 1];
            app.relativeDensity_uni.Enable = 'off';
            app.relativeDensity_uni.Tooltip = {''};
            app.relativeDensity_uni.Value = 0.5;
            app.ExactRelativeDensityLabel = uilabel(app.Panel_3);
            app.ExactRelativeDensityLabel.Tooltip = {'The exact relative density of the object is displayed here'};
            app.ExactRelativeDensityLabel.Text = 'Exact Relative Density: ';
            app.exactRelativeDensity_uni = uieditfield(app.Panel_3, 'text');
            app.exactRelativeDensity_uni.Editable = 'off';
            app.exactRelativeDensity_uni.Tooltip = {''};
            app.Plot_uni = uibutton(app.Panel_3, 'state');
            app.Plot_uni.ValueChangedFcn = createCallbackFcn(app, @Plot_uniValueChanged, true);
            app.Plot_uni.Text = 'Plot';
            app.ExportSTL_uni = uibutton(app.Panel_3, 'push');
            app.ExportSTL_uni.ButtonPushedFcn = createCallbackFcn(app, @ExportSTL_uniButtonPushed, true);
            app.ExportSTL_uni.Text = 'Export STL';
            app.Lamp_uni = uilamp(app.Panel_3);
            app.LampLabel_uni = uilabel(app.Panel_3);
            app.LampLabel_uni.Text = ' ';
            app.Stop_uni = uibutton(app.Panel_3, 'state');
            app.Stop_uni.ValueChangedFcn = createCallbackFcn(app, @Stop_uniValueChanged, true);
            app.Stop_uni.Text = 'Stop';
            app.GradedDensityTab = uitab(app.TabGroup);
            app.GradedDensityTab.Title = 'Graded Density';
            app.UIAxes_grade = uiaxes(app.GradedDensityTab);
            xlabel(app.UIAxes_grade, 'X')
            ylabel(app.UIAxes_grade, 'Y')
            zlabel(app.UIAxes_grade, 'Z')
            app.UIAxes_grade.XTick = [];
            app.UIAxes_grade.XTickLabel = '';
            app.UIAxes_grade.YTick = [];
            app.UIAxes_grade.YTickLabel = '';
            app.UIAxes_grade.ZTick = [];
            app.Panel_7 = uipanel(app.GradedDensityTab);
            app.GeometryInitializationLabel_2 = uilabel(app.Panel_7);
            app.GeometryInitializationLabel_2.Text = 'Geometry Initialization';
            app.ImportCustomMeshDropDownLabel_2 = uilabel(app.Panel_7);
            app.ImportCustomMeshDropDownLabel_2.Tooltip = {'Would you like to import a custom mesh?'};
            app.ImportCustomMeshDropDownLabel_2.Text = 'Import Custom Mesh:';
            app.ImportedMesh_grade = uidropdown(app.Panel_7);
            app.ImportedMesh_grade.Items = {'No', 'Yes'};
            app.ImportedMesh_grade.ValueChangedFcn = createCallbackFcn(app, @ImportedMesh_gradeValueChanged, true);
            app.ImportedMesh_grade.Value = 'No';
            app.NameofFIle_2 = uilabel(app.Panel_7);
            app.NameofFIle_2.Tooltip = {'Provide the name of the imported mesh file or "Browse" to locate it on your computer'};
            app.NameofFIle_2.Text = 'Name of file:';
            app.fileName_grade = uieditfield(app.Panel_7, 'text');
            app.fileName_grade.Editable = 'off';
            app.BrowseButton_grade = uibutton(app.Panel_7, 'state');
            app.BrowseButton_grade.ValueChangedFcn = createCallbackFcn(app, @BrowseButton_gradeValueChanged, true);
            app.BrowseButton_grade.Enable = 'off';
            app.BrowseButton_grade.Text = 'Browse';
            app.NumberofGridpointsSpinnerLabel_2 = uilabel(app.Panel_7);
            app.NumberofGridpointsSpinnerLabel_2.Tooltip = {'Specify the number of gridpoints to use for generating the plot'};
            app.NumberofGridpointsSpinnerLabel_2.Text = 'Number of Gridpoints:';
            app.Gridpoints_grade = uispinner(app.Panel_7);
            app.Gridpoints_grade.Value = 100;
            app.CuboidDimensionsLabel = uilabel(app.Panel_7);
            app.CuboidDimensionsLabel.Tooltip = {'Enter the length, width, and height values for the bounding box'};
            app.CuboidDimensionsLabel.Text = 'Cuboid Dimensions:';
            app.XSpinnerLabel_6 = uilabel(app.Panel_7);
            app.XSpinnerLabel_6.Text = 'X:';
            app.cubiodX_grade = uispinner(app.Panel_7);
            app.cubiodX_grade.Value = 60;
            app.YLabel_6 = uilabel(app.Panel_7);
            app.YLabel_6.Text = 'Y:';
            app.cubiodY_grade = uispinner(app.Panel_7);
            app.cubiodY_grade.Value = 60;
            app.ZLabel_7 = uilabel(app.Panel_7);
            app.ZLabel_7.Text = 'Z:';
            app.cubiodZ_grade = uispinner(app.Panel_7);
            app.cubiodZ_grade.Value = 60;
            app.CellDimensionsLabel_2 = uilabel(app.Panel_7);
            app.CellDimensionsLabel_2.Tooltip = {'Enter the length, width, and height values for the unit cell'};
            app.CellDimensionsLabel_2.Text = 'Cell Dimensions:';
            app.XSpinnerLabel_7 = uilabel(app.Panel_7);
            app.XSpinnerLabel_7.Text = 'X:';
            app.cellX_grade = uispinner(app.Panel_7);
            app.cellX_grade.Value = 60;
            app.YLabel_7 = uilabel(app.Panel_7);
            app.YLabel_7.Text = 'Y:';
            app.cellY_grade = uispinner(app.Panel_7);
            app.cellY_grade.Value = 60;
            app.ZLabel_8 = uilabel(app.Panel_7);
            app.ZLabel_8.Text = 'Z:';
            app.cellZ_grade = uispinner(app.Panel_7);
            app.cellZ_grade.Value = 60;
            app.Panel_8 = uipanel(app.GradedDensityTab);
            app.TPMSParametersLabel_2 = uilabel(app.Panel_8);
            app.TPMSParametersLabel_2.Text = 'TPMS Parameters';
            app.FunctionLabel_4 = uilabel(app.Panel_8);
            app.FunctionLabel_4.Tooltip = {'Select a predefined TPMS function'};
            app.FunctionLabel_4.Text = 'Function:';
            app.Function_grade = uidropdown(app.Panel_8);
            app.Function_grade.Items = {'Primitive Schwartz Surface', 'Gyroid', 'Diamond', 'I-WP', 'F-RD', 'I2-Y', 'Custom Function'};
            app.Function_grade.ValueChangedFcn = createCallbackFcn(app, @Function_gradeValueChanged, true);
            app.Function_grade.Tooltip = {'Select a predefined TPMS function'};
            app.Function_grade.Value = 'Primitive Schwartz Surface';
            app.StructureType_grade = uiswitch(app.Panel_8, 'slider');
            app.StructureType_grade.Items = {'Sheet', 'Solid'};
            app.StructureType_grade.ValueChangedFcn = createCallbackFcn(app, @StructureType_gradeValueChanged, true);
            app.StructureType_grade.Value = 'Sheet';
            app.CustomFunctionEditFieldLabel_2 = uilabel(app.Panel_8);
            app.CustomFunctionEditFieldLabel_2.Tooltip = {'If using a custom function, enter the mathematical expression for your TPMS'};
            app.CustomFunctionEditFieldLabel_2.Text = 'Custom Function:';
            app.CustomFunction_grade = uieditfield(app.Panel_8, 'text');
            app.CustomFunction_grade.Editable = 'off';
            app.CustomFunction_grade.Tooltip = {'If using a custom function, enter the mathematical expression for your TPMS'};
            app.FillDirectionLabel_2 = uilabel(app.Panel_8);
            app.FillDirectionLabel_2.Tooltip = {'Choose whether to fill the solid structure above or below the determined isovalue'};
            app.FillDirectionLabel_2.Text = 'Fill Direction:';
            app.fillDirection_grade = uidropdown(app.Panel_8);
            app.fillDirection_grade.Items = {'Fill Above Isovalue', 'Fill Below Isovalue'};
            app.fillDirection_grade.Enable = 'off';
            app.fillDirection_grade.Tooltip = {'Choose whether to fill the solid structure above or below the specified isovalue'};
            app.fillDirection_grade.Value = 'Fill Above Isovalue';
            app.Panel_6 = uipanel(app.GradedDensityTab);
            app.GradedDensityLabel = uilabel(app.Panel_6);
            app.GradedDensityLabel.Text = 'Graded Density';
            app.FunctionLabel_3 = uilabel(app.Panel_6);
            app.FunctionLabel_3.Tooltip = {'Choose a grading equation to determine the grading pattern'};
            app.FunctionLabel_3.Text = 'Grading Equation:';
            app.GradingEquation_grade = uidropdown(app.Panel_6);
            app.GradingEquation_grade.Items = {'Linear', 'Quadratic', 'Cubic', 'Circular', 'Exponential', 'Trigonometric - Sin', 'Trigonometric - Cosine'};
            app.GradingEquation_grade.ValueChangedFcn = createCallbackFcn(app, @GradingEquation_gradeValueChanged, true);
            app.GradingEquation_grade.Tooltip = {''};
            app.GradingEquation_grade.Value = 'Linear';
            app.GradingFormula = uieditfield(app.Panel_6, 'text');
            app.GradingFormula.Editable = 'off';
            app.GradingFormula.Tooltip = {''};
            app.GradingFormula.Value = 'AX + BY + CZ';
            app.CoefficientLabel = uilabel(app.Panel_6);
            app.CoefficientLabel.Tooltip = {'Adjust the coefficient of the selected grading equation to control the grading pattern'};
            app.CoefficientLabel.Text = 'Coefficient:';
            app.ALabel = uilabel(app.Panel_6);
            app.ALabel.Tooltip = {''};
            app.ALabel.Text = 'A:';
            app.coefficientA_grade = uispinner(app.Panel_6);
            app.coefficientA_grade.Tooltip = {''};
            app.coefficientA_grade.Value = 1;
            app.YLabel_5 = uilabel(app.Panel_6);
            app.YLabel_5.Tooltip = {''};
            app.YLabel_5.Text = 'B:';
            app.coefficientB_grade = uispinner(app.Panel_6);
            app.coefficientB_grade.Tooltip = {''};
            app.ZLabel_5 = uilabel(app.Panel_6);
            app.ZLabel_5.Tooltip = {''};
            app.ZLabel_5.Text = 'C:';
            app.coefficientC_grade = uispinner(app.Panel_6);
            app.coefficientC_grade.Tooltip = {''};
            app.ZLabel_6 = uilabel(app.Panel_6);
            app.ZLabel_6.Tooltip = {'If using a circular equation, manipulate the radius here'};
            app.ZLabel_6.Text = 'Radius:';
            app.Radius_grade = uispinner(app.Panel_6);
            app.Radius_grade.Enable = 'off';
            app.Radius_grade.Tooltip = {''};
            app.Radius_grade.Value = 60;
            app.RelativePosition_grade = uilabel(app.Panel_6);
            app.RelativePosition_grade.Tooltip = {'Define where on the object the grading will take place'};
            app.RelativePosition_grade.Text = 'Reference Position:';
            app.ALabel_2 = uilabel(app.Panel_6);
            app.ALabel_2.Tooltip = {''};
            app.ALabel_2.Text = 'X:';
            app.positionX_grade = uispinner(app.Panel_6);
            app.positionX_grade.ValueChangingFcn = createCallbackFcn(app, @positionX_gradeValueChanging, true);
            app.positionX_grade.Limits = [0 Inf];
            app.positionX_grade.Tooltip = {''};
            app.positionX_grade.Value = 60;
            app.YLabel_8 = uilabel(app.Panel_6);
            app.YLabel_8.Tooltip = {''};
            app.YLabel_8.Text = 'Y:';
            app.positionY_grade = uispinner(app.Panel_6);
            app.positionY_grade.ValueChangingFcn = createCallbackFcn(app, @positionY_gradeValueChanging, true);
            app.positionY_grade.Limits = [0 Inf];
            app.positionY_grade.Tooltip = {''};
            app.ZLabel_9 = uilabel(app.Panel_6);
            app.ZLabel_9.Tooltip = {''};
            app.ZLabel_9.Text = 'Z:';
            app.positionZ_grade = uispinner(app.Panel_6);
            app.positionZ_grade.ValueChangingFcn = createCallbackFcn(app, @positionZ_gradeValueChanging, true);
            app.positionZ_grade.Limits = [0 Inf];
            app.positionZ_grade.Tooltip = {''};
            app.ThicknessCheckBox_grade = uicheckbox(app.Panel_6);
            app.ThicknessCheckBox_grade.ValueChangedFcn = createCallbackFcn(app, @ThicknessCheckBox_gradeValueChanged, true);
            app.ThicknessCheckBox_grade.Tooltip = {''};
            app.ThicknessCheckBox_grade.Text = 'Thickness';
            app.ThicknessCheckBox_grade.Value = true;
            app.RelativeDensityCheckBox_grade = uicheckbox(app.Panel_6);
            app.RelativeDensityCheckBox_grade.ValueChangedFcn = createCallbackFcn(app, @RelativeDensityCheckBox_gradeValueChanged, true);
            app.RelativeDensityCheckBox_grade.Tooltip = {''};
            app.RelativeDensityCheckBox_grade.Text = 'Relative Density';
            app.MinimumDensitySpinnerLabel = uilabel(app.Panel_6);
            app.MinimumDensitySpinnerLabel.Tooltip = {'Set the desired minimum and maximum relative density value to a value between 0 and 1'};
            app.MinimumDensitySpinnerLabel.Text = 'Minimum Density:';
            app.MinimumDensitySpinner_grade = uispinner(app.Panel_6);
            app.MinimumDensitySpinner_grade.Step = 0.1;
            app.MinimumDensitySpinner_grade.Limits = [0.01 1];
            app.MinimumDensitySpinner_grade.Enable = 'off';
            app.MinimumDensitySpinner_grade.Tooltip = {''};
            app.MinimumDensitySpinner_grade.Value = 0.2;
            app.MaximumDensitySpinnerLabel = uilabel(app.Panel_6);
            app.MaximumDensitySpinnerLabel.Tooltip = {'Set the desired '; ' minimum and maximum relative density value to a value between 0 and 1'};
            app.MaximumDensitySpinnerLabel.Text = 'Maximum Density:';
            app.MaximumDensitySpinner_grade = uispinner(app.Panel_6);
            app.MaximumDensitySpinner_grade.Step = 0.1;
            app.MaximumDensitySpinner_grade.Limits = [0.01 1];
            app.MaximumDensitySpinner_grade.Enable = 'off';
            app.MaximumDensitySpinner_grade.Tooltip = {''};
            app.MaximumDensitySpinner_grade.Value = 0.8;
            app.MinimumThicknessSpinnerLabel = uilabel(app.Panel_6);
            app.MinimumThicknessSpinnerLabel.Tooltip = {'Set the desired minimum and maximum thickness values'};
            app.MinimumThicknessSpinnerLabel.Text = 'Minimum Thickness:';
            app.MinimumThicknessSpinner_grade = uispinner(app.Panel_6);
            app.MinimumThicknessSpinner_grade.Step = 0.1;
            app.MinimumThicknessSpinner_grade.Limits = [0.01 Inf];
            app.MinimumThicknessSpinner_grade.Tooltip = {''};
            app.MinimumThicknessSpinner_grade.Value = 0.5;
            app.MaximumThicknessSpinnerLabel = uilabel(app.Panel_6);
            app.MaximumThicknessSpinnerLabel.Tooltip = {'Set the desired minimum and maximum thickness values'};
            app.MaximumThicknessSpinnerLabel.Text = 'Maximum Thickness:';
            app.MaximumThicknessSpinner_grade = uispinner(app.Panel_6);
            app.MaximumThicknessSpinner_grade.Step = 0.1;
            app.MaximumThicknessSpinner_grade.Limits = [0.01 Inf];
            app.MaximumThicknessSpinner_grade.Tooltip = {''};
            app.MaximumThicknessSpinner_grade.Value = 1;
            app.ExactRelativeDensityLabel_2 = uilabel(app.Panel_6);
            app.ExactRelativeDensityLabel_2.Tooltip = {'The exact relative density of the object is displayed here'};
            app.ExactRelativeDensityLabel_2.Text = 'Exact Relative Density: ';
            app.exactRelativeDensity_grade = uieditfield(app.Panel_6, 'text');
            app.exactRelativeDensity_grade.Editable = 'off';
            app.exactRelativeDensity_grade.Tooltip = {''};
            app.Plot_grade = uibutton(app.Panel_6, 'state');
            app.Plot_grade.ValueChangedFcn = createCallbackFcn(app, @Plot_gradeValueChanged, true);
            app.Plot_grade.Text = 'Plot';
            app.ExportSTL_grade = uibutton(app.Panel_6, 'push');
            app.ExportSTL_grade.ButtonPushedFcn = createCallbackFcn(app, @ExportSTL_gradeButtonPushed, true);
            app.ExportSTL_grade.Text = 'Export STL';
            app.Lamp_grade = uilamp(app.Panel_6);
            app.LampLabel_grade = uilabel(app.Panel_6);
            app.LampLabel_grade.Text = ' ';
            app.Stop_grade = uibutton(app.Panel_6, 'state');
            app.Stop_grade.ValueChangedFcn = createCallbackFcn(app, @Stop_gradeValueChanged, true);
            app.Stop_grade.Text = 'Stop';
            app.HybridizationTab = uitab(app.TabGroup);
            app.HybridizationTab.Title = 'Hybridization';
            app.UIAxes_hybrid = uiaxes(app.HybridizationTab);
            xlabel(app.UIAxes_hybrid, 'X')
            ylabel(app.UIAxes_hybrid, 'Y')
            zlabel(app.UIAxes_hybrid, 'Z')
            app.UIAxes_hybrid.XTick = [];
            app.UIAxes_hybrid.XTickLabel = '';
            app.UIAxes_hybrid.YTick = [];
            app.UIAxes_hybrid.YTickLabel = '';
            app.UIAxes_hybrid.ZTick = [];
            app.Panel_9 = uipanel(app.HybridizationTab);
            app.GeometryInitializationLabel_3 = uilabel(app.Panel_9);
            app.GeometryInitializationLabel_3.Text = 'Geometry Initialization';
            app.ImportCustomMeshDropDownLabel_3 = uilabel(app.Panel_9);
            app.ImportCustomMeshDropDownLabel_3.Tooltip = {'Would you like to import a custom mesh?'};
            app.ImportCustomMeshDropDownLabel_3.Text = 'Import Custom Mesh:';
            app.ImportedMesh_hybrid = uidropdown(app.Panel_9);
            app.ImportedMesh_hybrid.Items = {'No', 'Yes'};
            app.ImportedMesh_hybrid.ValueChangedFcn = createCallbackFcn(app, @ImportedMesh_hybridValueChanged, true);
            app.ImportedMesh_hybrid.Value = 'No';
            app.NameofFileLabel_2 = uilabel(app.Panel_9);
            app.NameofFileLabel_2.Tooltip = {'Provide the name of the imported mesh file or "Browse" to locate it on your computer'};
            app.NameofFileLabel_2.Text = 'Name of file:';
            app.fileName_hybrid = uieditfield(app.Panel_9, 'text');
            app.fileName_hybrid.Editable = 'off';
            app.BrowseButton_hybrid = uibutton(app.Panel_9, 'state');
            app.BrowseButton_hybrid.ValueChangedFcn = createCallbackFcn(app, @BrowseButton_hybridValueChanged, true);
            app.BrowseButton_hybrid.Enable = 'off';
            app.BrowseButton_hybrid.Text = 'Browse';
            app.NumberofGridpointsSpinnerLabel_3 = uilabel(app.Panel_9);
            app.NumberofGridpointsSpinnerLabel_3.Tooltip = {'Specify the number of gridpoints to use for generating the plot'};
            app.NumberofGridpointsSpinnerLabel_3.Text = 'Number of Gridpoints:';
            app.Gridpoints_hybrid = uispinner(app.Panel_9);
            app.Gridpoints_hybrid.Value = 100;
            app.CuboidDimensionsLabel_3 = uilabel(app.Panel_9);
            app.CuboidDimensionsLabel_3.Tooltip = {'Enter the length, width, and height values for the bounding box'};
            app.CuboidDimensionsLabel_3.Text = 'Cuboid Dimensions:';
            app.XSpinnerLabel_8 = uilabel(app.Panel_9);
            app.XSpinnerLabel_8.Text = 'X:';
            app.cubiodX_hybrid = uispinner(app.Panel_9);
            app.cubiodX_hybrid.Limits = [0 Inf];
            app.cubiodX_hybrid.Value = 60;
            app.YLabel_9 = uilabel(app.Panel_9);
            app.YLabel_9.Text = 'Y:';
            app.cubiodY_hybrid = uispinner(app.Panel_9);
            app.cubiodY_hybrid.Limits = [0 Inf];
            app.cubiodY_hybrid.Value = 60;
            app.ZLabel_10 = uilabel(app.Panel_9);
            app.ZLabel_10.Text = 'Z:';
            app.cubiodZ_hybrid = uispinner(app.Panel_9);
            app.cubiodZ_hybrid.Limits = [0 Inf];
            app.cubiodZ_hybrid.Value = 60;
            app.CellDimensionsLabel_3 = uilabel(app.Panel_9);
            app.CellDimensionsLabel_3.Tooltip = {'Enter the length, width, and height values for the unit cell'};
            app.CellDimensionsLabel_3.Text = 'Cell Dimensions:';
            app.XSpinnerLabel_9 = uilabel(app.Panel_9);
            app.XSpinnerLabel_9.Text = 'X:';
            app.cellX_hybrid = uispinner(app.Panel_9);
            app.cellX_hybrid.Limits = [0 Inf];
            app.cellX_hybrid.Value = 60;
            app.YLabel_10 = uilabel(app.Panel_9);
            app.YLabel_10.Text = 'Y:';
            app.cellY_hybrid = uispinner(app.Panel_9);
            app.cellY_hybrid.Limits = [0 Inf];
            app.cellY_hybrid.Value = 60;
            app.ZLabel_11 = uilabel(app.Panel_9);
            app.ZLabel_11.Text = 'Z:';
            app.cellZ_hybrid = uispinner(app.Panel_9);
            app.cellZ_hybrid.Limits = [0 Inf];
            app.cellZ_hybrid.Value = 60;
            app.Panel_10 = uipanel(app.HybridizationTab);
            app.TPMSParametersLabel_hybrid = uilabel(app.Panel_10);
            app.TPMSParametersLabel_hybrid.Text = 'TPMS Parameters';
            app.FunctionLabel_5 = uilabel(app.Panel_10);
            app.FunctionLabel_5.Tooltip = {'Select a predefined TPMS function'};
            app.FunctionLabel_5.Text = 'Function 1:';
            app.Function_hybrid = uidropdown(app.Panel_10);
            app.Function_hybrid.Items = {'Primitive Schwartz Surface', 'Gyroid Surface', 'Diamond', 'I-WP', 'F-RD', 'I2-Y', 'Custom Function'};
            app.Function_hybrid.ValueChangedFcn = createCallbackFcn(app, @Function_hybridValueChanged, true);
            app.Function_hybrid.Tooltip = {''};
            app.Function_hybrid.Value = 'Gyroid Surface';
            app.FunctionLabel_6 = uilabel(app.Panel_10);
            app.FunctionLabel_6.Tooltip = {'Select a predefined TPMS function'};
            app.FunctionLabel_6.Text = 'Function 2:';
            app.Function_hybrid_2 = uidropdown(app.Panel_10);
            app.Function_hybrid_2.Items = {'Primitive Schwartz Surface', 'Gyroid', 'Diamond', 'I-WP', 'F-RD', 'I2-Y', 'Custom Function'};
            app.Function_hybrid_2.ValueChangedFcn = createCallbackFcn(app, @Function_hybrid_2ValueChanged, true);
            app.Function_hybrid_2.Tooltip = {''};
            app.Function_hybrid_2.Value = 'Primitive Schwartz Surface';
            app.CustomFunction1Label = uilabel(app.Panel_10);
            app.CustomFunction1Label.Tooltip = {'If using a custom function, enter the mathematical expression for your TPMS'};
            app.CustomFunction1Label.Text = 'Custom Function 1:';
            app.CustomFunction_hybrid = uieditfield(app.Panel_10, 'text');
            app.CustomFunction_hybrid.ValueChangedFcn = createCallbackFcn(app, @CustomFunction_hybridValueChanged, true);
            app.CustomFunction_hybrid.Editable = 'off';
            app.CustomFunction_hybrid.Tooltip = {''};
            app.CustomFunction2Label = uilabel(app.Panel_10);
            app.CustomFunction2Label.Tooltip = {'If using a custom function, enter the mathematical expression for your TPMS'};
            app.CustomFunction2Label.Text = 'Custom Function 2:';
            app.CustomFunction_hybrid_2 = uieditfield(app.Panel_10, 'text');
            app.CustomFunction_hybrid_2.Editable = 'off';
            app.CustomFunction_hybrid_2.Tooltip = {''};
            app.StructureType_hybrid = uiswitch(app.Panel_10, 'slider');
            app.StructureType_hybrid.Items = {'Sheet', 'Solid'};
            app.StructureType_hybrid.ValueChangedFcn = createCallbackFcn(app, @StructureType_hybridValueChanged, true);
            app.StructureType_hybrid.Value = 'Sheet';
            app.FillDirectionLabel_3 = uilabel(app.Panel_10);
            app.FillDirectionLabel_3.Tooltip = {'Choose whether to fill the solid structure above or below the determined isovalue'};
            app.FillDirectionLabel_3.Text = 'Fill Direction:';
            app.fillDirection_hybrid = uidropdown(app.Panel_10);
            app.fillDirection_hybrid.Items = {'Fill Above Isovalue', 'Fill Below Isovalue'};
            app.fillDirection_hybrid.Enable = 'off';
            app.fillDirection_hybrid.Tooltip = {''};
            app.fillDirection_hybrid.Value = 'Fill Above Isovalue';
            app.kSpinnerLabel = uilabel(app.Panel_10);
            app.kSpinnerLabel.Tooltip = {'k is defined as the width of transition'};
            app.kSpinnerLabel.Text = 'k:';
            app.k = uispinner(app.Panel_10);
            app.k.Limits = [0 Inf];
            app.k.Value = 5;
            app.Panel_11 = uipanel(app.HybridizationTab);
            app.UniformDensityLabel_hybrid = uilabel(app.Panel_11);
            app.UniformDensityLabel_hybrid.Tooltip = {''};
            app.UniformDensityLabel_hybrid.Text = 'Hybridization';
            app.ThicknessCheckBox_hybrid = uicheckbox(app.Panel_11);
            app.ThicknessCheckBox_hybrid.ValueChangedFcn = createCallbackFcn(app, @ThicknessCheckBox_hybridValueChanged, true);
            app.ThicknessCheckBox_hybrid.Tooltip = {''};
            app.ThicknessCheckBox_hybrid.Text = 'Thickness';
            app.ThicknessCheckBox_hybrid.Value = true;
            app.RelativeDensityCheckBox_hybrid = uicheckbox(app.Panel_11);
            app.RelativeDensityCheckBox_hybrid.ValueChangedFcn = createCallbackFcn(app, @RelativeDensityCheckBox_hybridValueChanged, true);
            app.RelativeDensityCheckBox_hybrid.Tooltip = {''};
            app.RelativeDensityCheckBox_hybrid.Text = 'Relative Density';
            app.ThicknessLabel_2 = uilabel(app.Panel_11);
            app.ThicknessLabel_2.Tooltip = {'Set the desired thickness value'};
            app.ThicknessLabel_2.Text = 'Thickness:';
            app.thickness_hybrid = uispinner(app.Panel_11);
            app.thickness_hybrid.Step = 0.1;
            app.thickness_hybrid.Limits = [0.01 Inf];
            app.thickness_hybrid.Tooltip = {''};
            app.thickness_hybrid.Value = 0.5;
            app.RelativeDensitySpinnerLabel_2 = uilabel(app.Panel_11);
            app.RelativeDensitySpinnerLabel_2.Tooltip = {'Set the desired relative density value to a value between 0 and 1'};
            app.RelativeDensitySpinnerLabel_2.Text = 'Relative Density:';
            app.relativeDensity_hybrid = uispinner(app.Panel_11);
            app.relativeDensity_hybrid.Step = 0.1;
            app.relativeDensity_hybrid.Limits = [0.01 1];
            app.relativeDensity_hybrid.Enable = 'off';
            app.relativeDensity_hybrid.Tooltip = {''};
            app.relativeDensity_hybrid.Value = 0.5;
            app.ExactRelativeDensityLabel_3 = uilabel(app.Panel_11);
            app.ExactRelativeDensityLabel_3.Tooltip = {'The exact relative density of the object is displayed here'};
            app.ExactRelativeDensityLabel_3.Text = 'Exact Relative Density: ';
            app.exactRelativeDensity_hybrid = uieditfield(app.Panel_11, 'text');
            app.exactRelativeDensity_hybrid.Editable = 'off';
            app.exactRelativeDensity_hybrid.Tooltip = {''};
            app.Plot_hybrid = uibutton(app.Panel_11, 'state');
            app.Plot_hybrid.ValueChangedFcn = createCallbackFcn(app, @Plot_hybridValueChanged, true);
            app.Plot_hybrid.Text = 'Plot';
            app.ExportSTL_hybrid = uibutton(app.Panel_11, 'push');
            app.ExportSTL_hybrid.ButtonPushedFcn = createCallbackFcn(app, @ExportSTL_hybridButtonPushed, true);
            app.ExportSTL_hybrid.Text = 'Export STL';
            app.Lamp_hybrid = uilamp(app.Panel_11);
            app.LampLabel_hybrid = uilabel(app.Panel_11);
            app.LampLabel_hybrid.Text = ' ';
            app.hybridFormula = uieditfield(app.Panel_11, 'text');
            app.hybridFormula.Editable = 'off';
            app.hybridFormula.Tooltip = {''};
            app.hybridFormula.Value = 'AX + BY + CZ';
            app.CoefficientLabel_2 = uilabel(app.Panel_11);
            app.CoefficientLabel_2.Tooltip = {'Adjust the coefficient of the selected transition equation to control the transition pattern'};
            app.CoefficientLabel_2.Text = 'Coefficient:';
            app.RelativePosition_grade_2 = uilabel(app.Panel_11);
            app.RelativePosition_grade_2.Tooltip = {'Define where on the object the transition will take place'};
            app.RelativePosition_grade_2.Text = 'Reference Position:';
            app.FunctionLabel_7 = uilabel(app.Panel_11);
            app.FunctionLabel_7.Tooltip = {'Defines the shape of transition between the two regions '};
            app.FunctionLabel_7.Text = 'Transition Equation:';
            app.hybridEquation = uidropdown(app.Panel_11);
            app.hybridEquation.Items = {'Linear', 'Quadratic', 'Cubic', 'Circular', 'Exponential', 'Trigonometric - Sin', 'Trigonometric - Cosine'};
            app.hybridEquation.ValueChangedFcn = createCallbackFcn(app, @hybridEquationValueChanged, true);
            app.hybridEquation.Tooltip = {''};
            app.hybridEquation.Value = 'Linear';
            app.ALabel_3 = uilabel(app.Panel_11);
            app.ALabel_3.Tooltip = {''};
            app.ALabel_3.Text = 'A:';
            app.coefficientA_hybrid = uispinner(app.Panel_11);
            app.coefficientA_hybrid.Tooltip = {''};
            app.coefficientA_hybrid.Value = 1;
            app.YLabel_11 = uilabel(app.Panel_11);
            app.YLabel_11.Tooltip = {''};
            app.YLabel_11.Text = 'B:';
            app.coefficientB_hybrid = uispinner(app.Panel_11);
            app.coefficientB_hybrid.Tooltip = {''};
            app.ZLabel_12 = uilabel(app.Panel_11);
            app.ZLabel_12.Tooltip = {''};
            app.ZLabel_12.Text = 'C:';
            app.coefficientC_hybrid = uispinner(app.Panel_11);
            app.coefficientC_hybrid.Tooltip = {''};
            app.ZLabel_13 = uilabel(app.Panel_11);
            app.ZLabel_13.Tooltip = {''};
            app.ZLabel_13.Text = 'Radius:';
            app.radius_hybrid = uispinner(app.Panel_11);
            app.radius_hybrid.Enable = 'off';
            app.radius_hybrid.Tooltip = {''};
            app.radius_hybrid.Value = 20;
            app.ALabel_4 = uilabel(app.Panel_11);
            app.ALabel_4.Tooltip = {''};
            app.ALabel_4.Text = 'X:';
            app.positionX_hybrid = uispinner(app.Panel_11);
            app.positionX_hybrid.Limits = [0 Inf];
            app.positionX_hybrid.Tooltip = {''};
            app.positionX_hybrid.Value = 30;
            app.YLabel_12 = uilabel(app.Panel_11);
            app.YLabel_12.Tooltip = {''};
            app.YLabel_12.Text = 'Y:';
            app.positionY_hybrid = uispinner(app.Panel_11);
            app.positionY_hybrid.Limits = [0 Inf];
            app.positionY_hybrid.Tooltip = {''};
            app.positionY_hybrid.Value = 30;
            app.ZLabel_14 = uilabel(app.Panel_11);
            app.ZLabel_14.Tooltip = {''};
            app.ZLabel_14.Text = 'Z:';
            app.positionZ_hybrid = uispinner(app.Panel_11);
            app.positionZ_hybrid.Limits = [0 Inf];
            app.positionZ_hybrid.Tooltip = {''};
            app.positionZ_hybrid.Value = 30;
            app.Stop_hybrid = uibutton(app.Panel_11, 'state');
            app.Stop_hybrid.ValueChangedFcn = createCallbackFcn(app, @Stop_hybridValueChanged, true);
            app.Stop_hybrid.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LattGen1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end