classdef impModelGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        UniformDensityTab               matlab.ui.container.Tab
        Panel_3                         matlab.ui.container.Panel
        LampLabel_uni                   matlab.ui.control.Label
        Lamp_uni                        matlab.ui.control.Lamp
        thickness_uni                   matlab.ui.control.Spinner
        ThicknessLabel                  matlab.ui.control.Label
        RelativeDensityCheckBox_uni     matlab.ui.control.CheckBox
        ThicknessCheckBox_uni           matlab.ui.control.CheckBox
        relativeDensity_uni             matlab.ui.control.Spinner
        RelativeDensitySpinnerLabel     matlab.ui.control.Label
        exactRelativeDensity_uni        matlab.ui.control.EditField
        ExactRelativeDensityEditFieldLabel  matlab.ui.control.Label
        UniformDensityLabel             matlab.ui.control.Label
        ExportSTL_uni                   matlab.ui.control.Button
        Plot_uni                        matlab.ui.control.StateButton
        Panel_2                         matlab.ui.container.Panel
        TPMSParametersLabel             matlab.ui.control.Label
        CustomFunction_uni              matlab.ui.control.EditField
        CustomFunctionEditFieldLabel    matlab.ui.control.Label
        fillDirection_uni               matlab.ui.control.DropDown
        FillDirectionLabel              matlab.ui.control.Label
        Function_uni                    matlab.ui.control.DropDown
        FunctionLabel                   matlab.ui.control.Label
        StructureType_uni               matlab.ui.control.Switch
        Panel                           matlab.ui.container.Panel
        BrowseButton_uni                matlab.ui.control.StateButton
        Gridpoints_uni                  matlab.ui.control.Spinner
        NumberofGridpointsSpinnerLabel  matlab.ui.control.Label
        ImportedMesh_uni                matlab.ui.control.DropDown
        ImportCustomMeshDropDownLabel   matlab.ui.control.Label
        cellZ_uni                       matlab.ui.control.Spinner
        ZLabel_2                        matlab.ui.control.Label
        cellY_uni                       matlab.ui.control.Spinner
        YLabel_2                        matlab.ui.control.Label
        cellX_uni                       matlab.ui.control.Spinner
        XSpinnerLabel_5                 matlab.ui.control.Label
        cubiodZ_uni                     matlab.ui.control.Spinner
        ZLabel                          matlab.ui.control.Label
        cubiodY_uni                     matlab.ui.control.Spinner
        YLabel                          matlab.ui.control.Label
        cubiodX_uni                     matlab.ui.control.Spinner
        XSpinnerLabel_4                 matlab.ui.control.Label
        GeometryInitializationLabel     matlab.ui.control.Label
        CellDimensionsLabel             matlab.ui.control.Label
        CubiodDimensionsLabel           matlab.ui.control.Label
        NameofFileLabel                 matlab.ui.control.Label
        fileName_uni                    matlab.ui.control.EditField
        UIAxes_uni                      matlab.ui.control.UIAxes
        GradedDensityTab                matlab.ui.container.Tab
        Panel_8                         matlab.ui.container.Panel
        TPMSParametersLabel_2           matlab.ui.control.Label
        CustomFunction_grade            matlab.ui.control.EditField
        CustomFunctionEditFieldLabel_2  matlab.ui.control.Label
        fillDirection_grade             matlab.ui.control.DropDown
        FillDirectionLabel_2            matlab.ui.control.Label
        Function_grade                  matlab.ui.control.DropDown
        FunctionLabel_4                 matlab.ui.control.Label
        StructureType_grade             matlab.ui.control.Switch
        Panel_7                         matlab.ui.container.Panel
        BrowseButton_grade              matlab.ui.control.StateButton
        Gridpoints_grade                matlab.ui.control.Spinner
        NumberofGridpointsSpinnerLabel_2  matlab.ui.control.Label
        ImportedMesh_grade              matlab.ui.control.DropDown
        ImportCustomMeshDropDownLabel_2  matlab.ui.control.Label
        cellZ_grade                     matlab.ui.control.Spinner
        ZLabel_8                        matlab.ui.control.Label
        cellY_grade                     matlab.ui.control.Spinner
        YLabel_7                        matlab.ui.control.Label
        cellX_grade                     matlab.ui.control.Spinner
        XSpinnerLabel_7                 matlab.ui.control.Label
        cubiodZ_grade                   matlab.ui.control.Spinner
        ZLabel_7                        matlab.ui.control.Label
        cubiodY_grade                   matlab.ui.control.Spinner
        YLabel_6                        matlab.ui.control.Label
        cubiodX_grade                   matlab.ui.control.Spinner
        XSpinnerLabel_6                 matlab.ui.control.Label
        GeometryInitializationLabel_2   matlab.ui.control.Label
        CellDimensionsLabel_2           matlab.ui.control.Label
        CubiodDimensionsLabel_2         matlab.ui.control.Label
        NameofFIle_2                    matlab.ui.control.Label
        fileName_grade                  matlab.ui.control.EditField
        Panel_6                         matlab.ui.container.Panel
        exactRelativeDensity_grade      matlab.ui.control.EditField
        ExactRelativeDensityEditFieldLabel_2  matlab.ui.control.Label
        RelativePosition_grade          matlab.ui.control.Label
        LampLabel_grade                 matlab.ui.control.Label
        Lamp_grade                      matlab.ui.control.Lamp
        positionZ_grade                 matlab.ui.control.Spinner
        ZLabel_9                        matlab.ui.control.Label
        positionY_grade                 matlab.ui.control.Spinner
        YLabel_8                        matlab.ui.control.Label
        positionX_grade                 matlab.ui.control.Spinner
        ALabel_2                        matlab.ui.control.Label
        GradingFormula                  matlab.ui.control.TextArea
        ExportSTL_grade                 matlab.ui.control.Button
        Plot_grade                      matlab.ui.control.StateButton
        RelativeDensityCheckBox_grade   matlab.ui.control.CheckBox
        ThicknessCheckBox_grade         matlab.ui.control.CheckBox
        MaximumThicknessSpinner_grade   matlab.ui.control.Spinner
        MaximumThicknessSpinnerLabel    matlab.ui.control.Label
        MaximumDensitySpinner_grade     matlab.ui.control.Spinner
        MaximumDensitySpinnerLabel      matlab.ui.control.Label
        MinimumThicknessSpinner_grade   matlab.ui.control.Spinner
        MinimumThicknessSpinnerLabel    matlab.ui.control.Label
        MinimumDensitySpinner_grade     matlab.ui.control.Spinner
        MinimumDensitySpinnerLabel      matlab.ui.control.Label
        Radius_grade                    matlab.ui.control.Spinner
        ZLabel_6                        matlab.ui.control.Label
        CoefficientLabel                matlab.ui.control.Label
        coefficientC_grade              matlab.ui.control.Spinner
        ZLabel_5                        matlab.ui.control.Label
        coefficientB_grade              matlab.ui.control.Spinner
        YLabel_5                        matlab.ui.control.Label
        coefficientA_grade              matlab.ui.control.Spinner
        ALabel                          matlab.ui.control.Label
        GradingEquation_grade           matlab.ui.control.DropDown
        FunctionLabel_3                 matlab.ui.control.Label
        GradedDensityLabel              matlab.ui.control.Label
        UIAxes_grade                    matlab.ui.control.UIAxes
    end


    methods (Access = private)

        function exportSTL(app, filename, F, V)
            stlwrite2(filename, F, V);
        end

        function [totalVolume, totalArea] = stlVol(app, V, F)
            [totalVolume, totalArea] = stlVolume(V, F);
        end

        function [x1,y1,z1,out_p,volumeFill,inp] = initialGeo(app,len,bre,wid,cell_len_x,cell_len_y,cell_len_z,...
                imported_mesh,inp,gridpoints)


            if strcmp(imported_mesh, 'Yes')
                if contains(inp,'.stl') || contains(inp,'.STL')
                else
                    inp= strcat(inp, '.stl');
                end
            end

            period = [len/cell_len_x,bre/cell_len_y,wid/cell_len_z];

            if strcmp(imported_mesh,'No')
                x = linspace(0, period(1), gridpoints);
                y = linspace(0, period(2), gridpoints);
                z = linspace(0, period(3), gridpoints);
                out_p = [];
            elseif strcmp(imported_mesh,'Yes')
                [out_p,x,y,z] = stlinput(inp,gridpoints);
                x = x/cell_len_x; y = y/cell_len_y; z = z/cell_len_z;
            end

            [x1,y1,z1] = meshgrid(x,y,z);

            volumeFill = app.fillDirection_uni.Value;
            switch volumeFill
                case "Fill Above Isovalue"
                    volumeFill = 'above';
                case "Fill Below Isovalue"
                    volumeFill = 'below';
            end
        end

        function [F, V] = impModelFunc(app,out_p,x1,y1,z1,cell_len_x,cell_len_y,cell_len_z,...
                app_t,isovalue,lattice_type,structure_type,volumeFill,custom_function)
            
            f = tpms_function(app,x1,y1,z1,lattice_type,custom_function);

            switch structure_type
                case "Sheet"
                    f(out_p == 0) = 1e10;
                    isovalue = app_t/2;
                    h = (f-isovalue).*-(f+isovalue);
                    [face1, vertex1] = isosurface(x1,y1,z1,h,0);
                    [face2, vertex2] = isocaps(x1,y1,z1,h,0);

                case "Solid"
                    f(out_p == 0) = -1e10;
                    h = f+isovalue;
                    [face1, vertex1] = isosurface(x1,y1,z1,h,0);
                    [face2, vertex2] = isocaps(x1,y1,z1,h,0,volumeFill );

            end

            F = [face1; length(vertex1(:,1)) + face2];
            V = [vertex1; vertex2];
            V = [V(:,1)*cell_len_x, V(:,2)*cell_len_y, V(:,3)*cell_len_z];

        end

        function [isovalue_new,app_t_new] = rel_den(app,rd_in,imported_mesh,inp,len,bre,wid,cell_len_x,cell_len_y,cell_len_z,...
                app_t,lattice_type,structure_type,out_p, x1,y1,z1,volumeFill,relDenCheckbox,custom_function)

            if strcmp(structure_type,'Solid')                                                           % Check if solid
                isovalue = [-1 1]; rd = zeros(size(isovalue));                                          % Initialize isovalue for two extreme values -1 and 1
                for i = 1:length(isovalue)
                    % for-loop for the two isovalues chosen
                    [F, V] = impModelFunc(app, out_p, x1,y1,z1,cell_len_x,cell_len_y,cell_len_z,...
                        app_t,isovalue(i),lattice_type,structure_type, volumeFill,custom_function);
                    if strcmp(imported_mesh,'Yes')                                                      % Check if the user is using an imported mesh domain
                        nd = stlread(inp);                                                              % read the imported mesh domain
                        ndv = nd.Points; ndf = nd.ConnectivityList;                                     % get face and vertices of imported mesh domain
                        v_ini = stlVolume(ndv',ndf');                                                   % compute the volume of imported mesh domain
                    else
                        v_ini = len*bre*wid;                                                            % compute volume of default domain
                    end
                    rd(i) = abs(stlVolume(V',F'))/v_ini;                                                % calculate the relative density of TPMS based on a particular isovalue
                end
                isovalue_new = (isovalue(2)-isovalue(1))*((rd_in-rd(1))/(rd(2)-rd(1)))+isovalue(1);     % estimate the new isovalue by interpolating between the two initial extreme isovalues
                app_t_new = [];
            else
                if relDenCheckbox == true
                    app_t= [0.2 2];
                    rd = zeros(size(app_t));
                    for i = 1:length(app_t)
                        [F, V] = impModelFunc(app, out_p, x1,y1,z1,cell_len_x,cell_len_y,cell_len_z,...
                            app_t(i),[],lattice_type,structure_type, volumeFill,custom_function);
                        if strcmp(imported_mesh,'Yes')
                            nd = stlread(inp);
                            ndv = nd.Points; ndf = nd.ConnectivityList;
                            v_ini = stlVolume(ndv',ndf');
                        else
                            v_ini = len*bre*wid;
                        end
                        rd(i) = abs(stlVolume(V',F'))/v_ini;
                    end
                    app_t_new = (app_t(2)-app_t(1))*((rd_in-rd(1))/(rd(2)-rd(1)))+app_t(1);
                    isovalue_new = [];
                else
                    app_t_new = app_t;
                    isovalue_new = [];
                end
            end
        end

        function [isovalue_new,app_t_new] = grading(app,rd1,rd2,app_t1,app_t2,imported_mesh,inp,len,bre,wid,cell_len_x,cell_len_y,cell_len_z,...
                lattice_type,structure_type, out_p, x1,y1,z1,volumeFill,equation_type,m1,m2,m3,rr,relDenCheckbox,custom_function,positionX,positionY,positionZ)
      
                x_c = len*positionX; y_c = bre*positionY; z_c = wid*positionZ;

                switch structure_type
                    case "Sheet"
                        if relDenCheckbox == true
                            [~,at1] = rel_den(app,rd1,imported_mesh,inp,len,bre,wid,cell_len_x,cell_len_y,cell_len_z,...
                                [],lattice_type,structure_type, out_p, x1,y1,z1,volumeFill,relDenCheckbox,custom_function);

                            [~,at2] = rel_den(app,rd2,imported_mesh,inp,len,bre,wid,cell_len_x,cell_len_y,cell_len_z,...
                                [],lattice_type,structure_type, out_p, x1,y1,z1,volumeFill,relDenCheckbox,custom_function);
                        else
                            at1 = app_t1; at2 = app_t2;
                        end

                        xx = x1-x_c/cell_len_x; yy = y1-y_c/cell_len_y; zz = z1-z_c/cell_len_z;   
                        xyz_max = max([max(xx(:)) max(yy(:)) max(zz(:))]);
                        xx = xx/xyz_max; yy = yy/xyz_max; zz = zz/xyz_max;                          

                        if strcmp(equation_type,'Linear')
                            is_f = abs(m1*xx+m2*yy+m3*zz);                                         
                        elseif strcmp(equation_type,'Quadratic')
                            is_f = abs(m1*xx.^2+m2*yy.^2+m3*zz.^2);
                        elseif strcmp(equation_type,'Cubic')
                            is_f = abs(m1*xx.^3+m2*yy.^3+m3*zz.^3);
                        elseif strcmp(equation_type,'Circular')
                            rr = rr/max([len bre wid]);
                            is_f = abs(sqrt(m1.*xx.^2+m2.*yy.^2+m3.*zz.^2)-rr);                  
                        elseif strcmp(equation_type,'Exponential')
                            is_f = abs(exp(m1*xx)+exp(m2*yy)+exp(m3*zz));
                        elseif strcmp(equation_type, 'Trigonometric - Sin')
                            is_f = abs(sin(m1*xx)+sin(m2*yy)+sin(m3*zz));
                        elseif strcmp(equation_type, 'Trigonometric - Cosine')
                            is_f = abs(cos(m1*xx)+cos(m2*yy)+cos(m3*zz));
                        end
                        app_t_new = (at1+is_f*(at2-at1));
                        isovalue_new = (at1+is_f*(at2-at1))/2;                                      

                    case "Solid"

                        [is1,~] = rel_den(app,rd1,imported_mesh,inp,len,bre,wid,cell_len_x,cell_len_y,cell_len_z,...
                            [],lattice_type,structure_type, out_p, x1,y1,z1,volumeFill,relDenCheckbox,custom_function);
                        [is2,~] = rel_den(app,rd2,imported_mesh,inp,len,bre,wid,cell_len_x,cell_len_y,cell_len_z,...
                            [],lattice_type,structure_type, out_p, x1,y1,z1,volumeFill,relDenCheckbox,custom_function);

                        xx = x1-x_c/cell_len_x; yy = y1-y_c/cell_len_y; zz = z1-z_c/cell_len_z;
                        xyz_max = max([max(xx(:)) max(yy(:)) max(zz(:))]);
                        xx = xx/xyz_max; yy = yy/xyz_max; zz = zz/xyz_max;
                        if strcmp(equation_type,'Linear')
                            is_f = abs(m1*xx+m2*yy+m3*zz);
                        elseif strcmp(equation_type,'Quadratic')
                            is_f = abs(m1*xx.^2+m2*yy.^2+m3*zz.^2);
                        elseif strcmp(equation_type,'Cubic')
                            is_f = abs(m1*xx.^3+m2*yy.^3+m3*zz.^3);
                        elseif strcmp(equation_type,'Circular')
                            rr = rr/max([len bre wid]);
                            is_f = abs(sqrt(m1.*xx.^2+m2.*yy.^2+m3.*zz.^2)-rr);
                        elseif strcmp(equation_type,'Exponential')
                            is_f = abs(exp(m1*xx)+exp(m2*yy)+exp(m3*zz));
                        elseif strcmp(equation_type, 'Trigonometric - Sin')
                            is_f = abs(sin(m1*xx)+sin(m2*yy)+sin(m3*zz));
                        elseif strcmp(equation_type, 'Trigonometric - Cosine')
                            is_f = abs(cos(m1*xx)+cos(m2*yy)+cos(m3*zz));
                        end
                        isovalue_new = is1+is_f*(is2-is1);
                        app_t_new = [];
                end
            
            
        end
        
        function f = tpms_function(app,x1,y1,z1,lattice_type,custom_function)
            
            X = 2*pi*x1;
            Y = 2*pi*y1;
            Z = 2*pi*z1;

            switch lattice_type
                case "Primitive Schwartz"
                    f = cos(X)+cos(Y)+cos(Z);
                case "Double P Surface"
                    f = 0.5*(cos(X).*cos(Y)+cos(Y).*cos(Z)+cos(Z).*cos(X))+0.2*(cos(2*X)+cos(2*Y)+cos(2*Z));
                case "Gyroid Surface"
                    f = sin(X).*cos(Y) + sin(Z).*cos(X) + sin(Y).*cos(Z);
                case "Double Gyroid Surface"
                    f = 2.75.*(sin(2*X).*sin(Z).*cos(Y) + sin(2*Y).*sin(X).*cos(Z) + sin(2*Z).*sin(Y).*cos(X))-(cos(2*X).*cos(2*Y)+cos(2*Y).*cos(2*Z)+cos(2*Z).*cos(2*X));
                case "Diamond Surface"
                    f = (cos(X).*cos(Y).*cos(Z)) - (sin(X).*sin(Y).*sin(Z));
                case "Double Diamond 1 Surface"
                    f = sin(2*X).*sin(2*Y)+sin(2*Y).*sin(2*Z)+sin(2*X).*sin(2*Z)+cos(2*X).*cos(2*Y).*cos(2*Z);
                case "Double Diamond 2 Surface"
                    f = cos(2*X).*cos(2*Y)+cos(2*Y).*cos(2*Z)+cos(2*X).*cos(2*Z)+sin(2*X).*sin(2*Y).*sin(2*Z);
                case "IWP Surface"
                    f = 2*(cos(X).*cos(Y)+cos(Y).*cos(Z)+cos(Z).*cos(X))-(cos(2*X)+cos(2*Y)+cos(2*Z));
                case "FRD Prime Surface"
                    f = 4*(cos(X).*cos(Y).*cos(Z))-(cos(2*X).*cos(2*Y)+cos(2*X).*cos(2*Z)+cos(2*Y).*cos(2*Z));
                case "I2-Y"
                    f = -2*(sin(2*X).*cos(Y).*sin(Z) + sin(X).*sin(2*Y).*cos(Z) + cos(X).*sin(Y).*sin(2*Z)) + cos(2*X).*cos(2*Y) + cos(2*Y).*cos(2*Z) + cos(2*X).*cos(2*Z);
                 case "G Prime 1 Surface"
                    f = 5*(sin(2*X).*sin(Z).*cos(Y)+sin(2*Y).*sin(X).*cos(Z)+sin(2*Z).*sin(Y).*cos(X))+(cos(2*X).*cos(2*Y)+cos(2*Y).*cos(2*Z)+cos(2*Z).*cos(2*X));
                case "G Prime 2 Surface"
                    f = (sin(2*X).*sin(Z).*cos(Y)+sin(2*Y).*sin(X).*cos(Z)+sin(2*Z).*sin(Y).*cos(X))+0.32;
                case "Neovius Surface"
                    f = 3*(cos(X)+cos(Y)+cos(Z))+4*(cos(X).*cos(Y).*cos(Z));
                case "Lidinoid Surface"
                    f = (sin(2*X).*sin(Z).*cos(Y)+sin(2*Y).*sin(X).*cos(Z)+sin(2*Z).*sin(Y).*cos(X))-(cos(2*X).*cos(2*Y)+cos(2*Y).*cos(2*Z)+cos(2*Z).*cos(2*X))+0.3;
                case "D Prime Surface"
                    f = 0.5*(sin(X).*sin(Y).*sin(Z)+cos(X).*cos(Y).*cos(Z))-0.5*(cos(2*X).*cos(2*Y)+cos(2*Y).*cos(2*Z)+cos(2*Z).*cos(2*X))-0.2;
                case "KP Surface"
                    f = 0.3*(cos(X)+cos(Y)+cos(Z))+0.3*(cos(X).*cos(Y)+cos(Y).*cos(Z)+cos(Z).*cos(X))-0.4*(cos(2*X)+cos(2*Y)+cos(2*Z))+0.2;
                case "S Surface"
                    f = cos(2*X).*sin(Y).*cos(Z)+cos(2*Y).*sin(Z).*cos(X)+cos(2*Z).*sin(X).*cos(Y)-0.4;
                case "Fischer-Koch Surface"
                    f = (cos(X).*cos(Y)+cos(Y).*cos(Z)+cos(Z).*cos(X))-(cos(2*X)+cos(2*Y)+cos(2*Z));
                case "Bionic Bone 1 Surface"
                    f = 20 *(cos(X).*sin(Y)+cos(Y).*sin(Z)+cos(Z).*sin(X))-0.5*(cos(2*X).*cos(2*Y)+cos(2*Y).*cos(2*Z)+cos(2*Z).*cos(2*X))-4;
                case "Bionic Bone 2 Surface"
                    f = 10*(cos(X).*sin(Y)+cos(Y).*sin(Z)+cos(Z).*sin(X))-2*(cos(2*X).*cos(2*Y)+cos(2*Y).*cos(2*Z)+cos(2*Z).*cos(2*X))-12;
                case "Octo 1 Surface"
                    f = 4*(cos(X).*cos(Y)+cos(Y).*cos(Z)+cos(Z).*cos(X))-2.8*(cos(X).*cos(Y).*cos(Z))+(cos(X)+cos(Y)+cos(Z))+1.5;
                case "Octo 2 Surface"
                    f = 4*(cos(X).*cos(Y)+cos(Y).*cos(Z)+cos(Z).*cos(X))-3*(cos(X)+cos(Y)+cos(Z))+2.4;
                case "PN Surface"
                    f = 0.6*(cos(X).*cos(Y).*cos(Z))+0.4*(cos(X)+cos(Y)+cos(Z))+0.2*(cos(2*X).*cos(2*Y).*cos(2*Z))+0.2*(cos(2*X)+cos(2*Y)+cos(2*Z))+0.1*(cos(3*X)+cos(3*Y)+cos(3*Z))+0.2*(cos(X).*cos(Y)+cos(Y).*cos(Z)+cos(Z).*cos(X));
                case "C(Y) Surface"
                    f = sin(X).*sin(Y).*sin(Z)+sin(2*X).*sin(Y)+sin(2*Y).*sin(Z)+sin(2*Z).*sin(X)-cos(X).*cos(Y).*cos(Z)+sin(2*X).*cos(Z)+sin(2*Y).*cos(X)+sin(2*Z).*cos(Y);
                case "Scherk Surface"
                    f = sin(Z) - sinh(X).*sinh(Y);
                case "Black D Surface"
                    f = sin(X).*sin(Y).*sin(Z)+sin(X).*cos(Y).*cos(Z)+cos(X).*sin(Y).*cos(Z)+cos(X).*cos(Y).*sin(Z);
                case "FRD Surface"
                    f = 8*(cos(X).*cos(Y).*cos(Z))+cos(2*X).*cos(2*Y).*cos(2*Z)-cos(2*X).*cos(2*Y)-cos(2*Y).*cos(2*Z)-cos(2*Z).*cos(2*X);
                case "Diamond D Surface"
                    f = sin(X).*sin(Y).*sin(Z)+sin(X).*cos(Y).*cos(Z)+cos(X).*sin(Y).*cos(Z)+cos(X).*cos(Y).*sin(Z);
                case "D Surface"
                    f = cos(X).*cos(Y).*cos(Z)-sin(X).*sin(Y).*sin(Z);
                case "Split P Surface"
                    f = 1.1*(sin(2*X).*sin(Z).*cos(Y)+sin(2*Y).*sin(X).*cos(Z)+sin(2*Z).*sin(Y).*cos(X))-0.2*(cos(2*X).*cos(2*Y)+cos(2*Y).*cos(2*Z)+cos(2*Z).*cos(2*X))-0.4*(cos(X)+cos(Y)+cos(Z));
                case "Custom Function"
                    f = custom_function;
            end
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Function_uni.Items = sort({'Primitive Schwartz','Double P Surface','Gyroid Surface','Double Gyroid Surface','Diamond Surface','Double Diamond 1 Surface','Double Diamond 2 Surface','IWP Surface','FRD Prime Surface','I2-Y','G Prime 1 Surface','G Prime 2 Surface','Neovius Surface','Lidinoid Surface','D Prime Surface','KP Surface','S Surface',...
                                            'Fischer-Koch Surface','Bionic Bone 1 Surface','Bionic Bone 2 Surface','Octo 1 Surface','Octo 2 Surface','PN Surface','C(Y) Surface','Scherk Surface','Black D Surface','FRD Surface','Diamond D Surface','D Surface','Split P Surface'});
            app.Function_uni.Items(end) = {'Custom Function'};
            app.Function_grade.Items = sort({'Primitive Schwartz','Double P Surface','Gyroid Surface','Double Gyroid Surface','Diamond Surface','Double Diamond 1 Surface','Double Diamond 2 Surface','IWP Surface','FRD Prime Surface','I2-Y','G Prime 1 Surface','G Prime 2 Surface','Neovius Surface','Lidinoid Surface','D Prime Surface','KP Surface','S Surface',...
                                            'Fischer-Koch Surface','Bionic Bone 1 Surface','Bionic Bone 2 Surface','Octo 1 Surface','Octo 2 Surface','PN Surface','C(Y) Surface','Scherk Surface','Black D Surface','FRD Surface','Diamond D Surface','D Surface','Split P Surface'});
            app.Function_grade.Items(end) = {'Custom Function'};
        end

        % Value changed function: StructureType_grade
        function StructureType_gradeValueChanged(app, event)
            if strcmp(app.StructureType_grade.Value, "Sheet")
                app.fillDirection_grade.Enable = 'off';
                app.ThicknessCheckBox_grade.Enable = 'on';
                app.RelativeDensityCheckBox_grade.Enable = 'on';
                if app.ThicknessCheckBox_grade.Value == true
                    app.RelativeDensityCheckBox_grade.Value = false;
                    app.MinimumThicknessSpinner_grade.Enable = "on";
                    app.MaximumThicknessSpinner_grade.Enable = 'on';
                    app.MaximumDensitySpinner_grade.Enable = 'off';
                    app.MinimumDensitySpinner_grade.Enable = 'off';
                else
                    app.RelativeDensityCheckBox_grade.Value = true;
                    app.MinimumThicknessSpinner_grade.Enable = "off";
                    app.MaximumThicknessSpinner_grade.Enable = 'off';
                    app.MaximumDensitySpinner_grade.Enable = 'on';
                    app.MinimumDensitySpinner_grade.Enable = 'on';
                end
            else
                app.fillDirection_grade.Enable = 'on';
                app.ThicknessCheckBox_grade.Enable = 'off';
                app.RelativeDensityCheckBox_grade.Enable = 'off';
                app.MinimumThicknessSpinner_grade.Enable = "off";
                app.MaximumThicknessSpinner_grade.Enable = 'off';
                app.MaximumDensitySpinner_grade.Enable = 'on';
                app.MinimumDensitySpinner_grade.Enable = 'on';

            end
        end

        % Value changed function: StructureType_uni
        function StructureType_uniValueChanged(app, event)
            if strcmp(app.StructureType_uni.Value, "Sheet")
                app.fillDirection_uni.Enable = 'off';
                app.ThicknessCheckBox_uni.Enable = 'on';
                app.RelativeDensityCheckBox_uni.Enable = 'on';
                if app.ThicknessCheckBox_uni.Value == true
                    app.thickness_uni.Enable = "on";
                    app.relativeDensity_uni.Enable = 'off';
                else
                    app.thickness_uni.Enable = "off";
                    app.relativeDensity_uni.Enable = 'on';
                end
            else
                app.fillDirection_uni.Enable = 'on';
                app.thickness_uni.Enable = 'off';
                app.ThicknessCheckBox_uni.Enable = 'off';
                app.RelativeDensityCheckBox_uni.Enable = 'off';
                app.relativeDensity_uni.Enable = 'on';

            end
        end

        % Value changed function: ImportedMesh_uni
        function ImportedMesh_uniValueChanged(app, event)
            if strcmp(app.ImportedMesh_uni.Value, "Yes")
                app.fileName_uni.Editable = 'on';
                app.BrowseButton_uni.Enable = 'on';
            else
                app.fileName_uni.Editable = 'off';
                app.BrowseButton_grade.Enable = 'off';
            end
        end

        % Value changed function: ImportedMesh_grade
        function ImportedMesh_gradeValueChanged(app, event)
            if strcmp(app.ImportedMesh_grade.Value, "Yes")
                app.fileName_grade.Editable = 'on';
                app.BrowseButton_grade.Enable = 'on';
            else
                app.fileName_grade.Editable = 'off';
                app.BrowseButton_uni.Enable = 'off';
            end
        end

        % Value changed function: Function_uni
        function Function_uniValueChanged(app, event)
            if strcmp(app.Function_uni.Value, "Custom Function")
                app.CustomFunction_uni.Editable = "on";
            else
                app.CustomFunction_uni.Editable = "off";
            end
        end

        % Value changed function: Function_grade
        function Function_gradeValueChanged(app, event)
            if strcmp(app.Function_grade.Value, "Custom Function")
                app.CustomFunction_grade.Editable = "on";
            else
                app.CustomFunction_grade.Editable = "off";
            end
        end

        % Value changed function: Plot_uni
        function Plot_uniValueChanged(app, event)
            app.Lamp_uni.Color = [1,1,0];
            app.LampLabel_uni.Text = "Running...";
            drawnow;
            [x1,y1,z1,out_p,volumeFill,inp] = initialGeo(app,app.cubiodX_uni.Value,app.cubiodY_uni.Value,app.cubiodZ_uni.Value,app.cellX_uni.Value,app.cellY_uni.Value,app.cellZ_uni.Value, app.ImportedMesh_uni.Value, app.fileName_uni.Value, app.Gridpoints_uni.Value);
            [isovalue_new,app_t_new] = rel_den(app,app.relativeDensity_uni.Value,app.ImportedMesh_uni.Value,inp,app.cubiodX_uni.Value,app.cubiodY_uni.Value,app.cubiodZ_uni.Value,app.cellX_uni.Value,app.cellY_uni.Value,app.cellZ_uni.Value,app.thickness_uni.Value,app.Function_uni.Value,app.StructureType_uni.Value, out_p, x1,y1,z1,volumeFill,app.RelativeDensityCheckBox_uni.Value,app.CustomFunction_uni.Value);
            [F, V] = impModelFunc(app, out_p, x1,y1,z1,app.cellX_uni.Value,app.cellY_uni.Value,app.cellZ_uni.Value,app_t_new,isovalue_new,app.Function_uni.Value,app.StructureType_uni.Value,volumeFill,app.CustomFunction_uni.Value);
            if strcmp(app.ImportedMesh_uni.Value,'Yes')                                                      % Check if the user is using an imported mesh domain
                nd = stlread(inp);                                                              % read the imported mesh domain
                ndv = nd.Points; ndf = nd.ConnectivityList;                                     % get face and vertices of imported mesh domain
                v_ini = stlVolume(ndv',ndf');                                                   % compute the volume of imported mesh domain
            else
                v_ini = app.cubiodX_uni.Value*app.cubiodY_uni.Value*app.cubiodZ_uni.Value;                                                            % compute volume of default domain
            end
            app.exactRelativeDensity_uni.Value = num2str(abs(stlVolume(V',F'))/v_ini,3);

            trisurf(F,V(:,1),V(:,2),V(:,3), 'Facecolor', '[1, 0.341, 0.200]','Edgecolor','none','Parent',app.UIAxes_uni);
            axis(app.UIAxes_uni, "equal");
            camlight(app.UIAxes_uni, 'headlight');
            light(app.UIAxes_uni,'position',[0 -1 0]);
            lighting(app.UIAxes_uni,'flat') ;
            pbaspect(app.UIAxes_uni,[1,1,1])
            app.Lamp_uni.Color = [0,1,0];
            app.LampLabel_uni.Text = " ";
        end

        % Button pushed function: ExportSTL_uni
        function ExportSTL_uniButtonPushed(app, event)
            app.Lamp_uni.Color = [1,1,0];
            app.LampLabel_uni.Text = "Running...";
            drawnow;
            [x1,y1,z1,out_p,volumeFill,inp] = initialGeo(app,app.cubiodX_uni.Value,app.cubiodY_uni.Value,app.cubiodZ_uni.Value,app.cellX_uni.Value,app.cellY_uni.Value,app.cellZ_uni.Value, app.ImportedMesh_uni.Value, app.fileName_uni.Value, app.Gridpoints_uni.Value);
            [isovalue_new,app_t_new] = rel_den(app,app.relativeDensity_uni.Value,app.ImportedMesh_uni.Value,inp,app.cubiodX_uni.Value,app.cubiodY_uni.Value,app.cubiodZ_uni.Value,app.cellX_uni.Value,app.cellY_uni.Value,app.cellZ_uni.Value,app.thickness_uni.Value,app.Function_uni.Value,app.StructureType_uni.Value,out_p,x1,y1,z1,volumeFill,app.RelativeDensityCheckBox_uni.Value,app.CustomFunction_uni.Value);
            [F, V] = impModelFunc(app, out_p, x1,y1,z1,app.cellX_uni.Value,app.cellY_uni.Value,app.cellZ_uni.Value,app_t_new,isovalue_new,app.Function_uni.Value,app.StructureType_uni.Value,volumeFill,app.CustomFunction_uni.Value);
            exportSTL(app,'newplot.stl', F, V); %creates the object on an stl file
            if strcmp(app.ImportedMesh_uni.Value,'Yes')                                                      % Check if the user is using an imported mesh domain
                nd = stlread(inp);                                                              % read the imported mesh domain
                ndv = nd.Points; ndf = nd.ConnectivityList;                                     % get face and vertices of imported mesh domain
                v_ini = stlVolume(ndv',ndf');                                                   % compute the volume of imported mesh domain
            else
                v_ini = app.cubiodX_uni.Value*app.cubiodY_uni.Value*app.cubiodZ_uni.Value;                                                            % compute volume of default domain
            end
            app.exactRelativeDensity_uni.Value = num2str(abs(stlVolume(V',F'))/v_ini,3);
            app.Lamp_uni.Color = [0,1,0];
            app.LampLabel_uni.Text = " ";
        end

        % Value changed function: Plot_grade
        function Plot_gradeValueChanged(app, event)
            app.Lamp_grade.Color = [1,1,0];
            app.LampLabel_grade.Text = "Running...";
            drawnow;
            [x1,y1,z1,out_p,volumeFill,inp] = initialGeo(app,app.cubiodX_grade.Value,app.cubiodY_grade.Value,app.cubiodZ_grade.Value,app.cellX_grade.Value,app.cellY_grade.Value,app.cellZ_grade.Value, app.ImportedMesh_grade.Value, app.fileName_grade.Value, app.Gridpoints_grade.Value);
            [isovalue_new,app_t_new] = grading(app,app.MinimumDensitySpinner_grade.Value,app.MaximumDensitySpinner_grade.Value,app.MinimumThicknessSpinner_grade.Value,app.MaximumThicknessSpinner_grade.Value,app.ImportedMesh_grade.Value,inp,app.cubiodX_grade.Value,app.cubiodY_grade.Value,app.cubiodZ_grade.Value,app.cellX_grade.Value,app.cellY_grade.Value,app.cellZ_grade.Value,app.Function_grade.Value,app.StructureType_grade.Value,out_p,x1,y1,z1,volumeFill,app.GradingEquation_grade.Value,app.coefficientA_grade.Value,app.coefficientB_grade.Value,app.coefficientC_grade.Value,app.Radius_grade.Value,app.RelativeDensityCheckBox_grade.Value,app.CustomFunction_grade.Value, app.positionX_grade.Value, app.positionY_grade.Value,app.positionZ_grade.Value);
            [F, V] = impModelFunc(app, out_p, x1,y1,z1,app.cellX_grade.Value,app.cellY_grade.Value,app.cellZ_grade.Value,app_t_new,isovalue_new,app.Function_grade.Value,app.StructureType_grade.Value,volumeFill,app.CustomFunction_grade.Value);
            
            if strcmp(app.ImportedMesh_grade.Value,'Yes')                                                      % Check if the user is using an imported mesh domain
                nd = stlread(inp);                                                              % read the imported mesh domain
                ndv = nd.Points; ndf = nd.ConnectivityList;                                     % get face and vertices of imported mesh domain
                v_ini = stlVolume(ndv',ndf');                                                   % compute the volume of imported mesh domain
            else
                v_ini = app.cubiodX_grade.Value*app.cubiodY_grade.Value*app.cubiodZ_grade.Value;                                                            % compute volume of default domain
            end
            app.exactRelativeDensity_grade.Value = num2str(abs(stlVolume(V',F'))/v_ini,3);

            trisurf(F,V(:,1),V(:,2),V(:,3), 'Facecolor', '[1, 0.341, 0.200]','Edgecolor','none','Parent',app.UIAxes_grade);
            axis(app.UIAxes_grade, "equal");
            camlight(app.UIAxes_grade, 'headlight');
            light(app.UIAxes_grade,'position',[0 -1 0]);
            lighting(app.UIAxes_grade,'flat') ;
            pbaspect(app.UIAxes_grade,[1,1,1])
            app.Lamp_grade.Color = [0,1,0];
            app.LampLabel_grade.Text = " ";
        end

        % Button pushed function: ExportSTL_grade
        function ExportSTL_gradeButtonPushed(app, event)
            app.Lamp_grade.Color = [1,1,0];
            app.LampLabel_grade.Text = "Running...";
            drawnow;
            [x1,y1,z1,out_p,volumeFill,inp] = initialGeo(app,app.cubiodX_grade.Value,app.cubiodY_grade.Value,app.cubiodZ_grade.Value,app.cellX_grade.Value,app.cellY_grade.Value,app.cellZ_grade.Value, app.ImportedMesh_grade.Value, app.fileName_grade.Value, app.Gridpoints_grade.Value);
            [isovalue_new,app_t_new] = grading(app,app.MinimumDensitySpinner_grade.Value,app.MaximumDensitySpinner_grade.Value,app.MinimumThicknessSpinner_grade.Value,app.MaximumThicknessSpinner_grade.Value,app.ImportedMesh_grade.Value,inp,app.cubiodX_grade.Value,app.cubiodY_grade.Value,app.cubiodZ_grade.Value,app.cellX_grade.Value,app.cellY_grade.Value,app.cellZ_grade.Value,app.Function_grade.Value,app.StructureType_grade.Value,out_p,x1,y1,z1,volumeFill,app.GradingEquation_grade.Value,app.coefficientA_grade.Value,app.coefficientB_grade.Value,app.coefficientC_grade.Value,app.Radius_grade.Value,app.Grade_grade.Value,app.RelativeDensityCheckBox_grade.Value,app.CustomFunction_grade.Value);
            [F, V] = impModelFunc(app, out_p, x1,y1,z1,app.cellX_grade.Value,app.cellY_grade.Value,app.cellZ_grade.Value,app_t_new,isovalue_new,app.Function_grade.Value,app.StructureType_grade.Value,volumeFill,app.CustomFunction_grade.Value);
            exportSTL(app,'newplot.stl', F, V); %creates the object on an stl file
            app.Lamp_grade.Color = [0,1,0];
            app.LampLabel_grade.Text = " ";
        end

        % Value changed function: ThicknessCheckBox_uni
        function ThicknessCheckBox_uniValueChanged(app, event)
            app.ThicknessCheckBox_uni.Value = true;
            app.RelativeDensityCheckBox_uni.Value = false;
            app.thickness_uni.Enable = "on";
            app.relativeDensity_uni.Enable = 'off';
        end

        % Value changed function: RelativeDensityCheckBox_uni
        function RelativeDensityCheckBox_uniValueChanged(app, event)
            app.ThicknessCheckBox_uni.Value = false;
            app.RelativeDensityCheckBox_uni.Value = true;
            app.thickness_uni.Enable = "off";
            app.relativeDensity_uni.Enable = 'on';
        end

        % Value changed function: ThicknessCheckBox_grade
        function ThicknessCheckBox_gradeValueChanged(app, event)
            app.ThicknessCheckBox_grade.Value = true;
            app.RelativeDensityCheckBox_grade.Value = false;
            app.MinimumThicknessSpinner_grade.Enable = "on";
            app.MaximumThicknessSpinner_grade.Enable = 'on';
            app.MaximumDensitySpinner_grade.Enable = 'off';
            app.MinimumDensitySpinner_grade.Enable = 'off';
        end

        % Value changed function: RelativeDensityCheckBox_grade
        function RelativeDensityCheckBox_gradeValueChanged(app, event)
            app.ThicknessCheckBox_grade.Value = false;
            app.RelativeDensityCheckBox_grade.Value = true;
            app.MinimumThicknessSpinner_grade.Enable = "off";
            app.MaximumThicknessSpinner_grade.Enable = 'off';
            app.MaximumDensitySpinner_grade.Enable = 'on';
            app.MinimumDensitySpinner_grade.Enable = 'on';
        end

        % Value changed function: GradingEquation_grade
        function GradingEquation_gradeValueChanged(app, event)
            switch app.GradingEquation_grade.Value
                case "Linear"
                    app.GradingFormula.Value = "AX + BY + CZ";
                case "Quadratic"
                    app.GradingFormula.Value = "AX^2 + BY^2 + CZ^2";
                case "Cubic"
                    app.GradingFormula.Value = "AX^3 + BY^3 + CZ^3";
                case "Circular"
                    app.GradingFormula.Value = "sqrt(AX^2 + BY^2 + CZ^2) - R";
                case "Exponential"
                    app.GradingFormula.Value = "e^(AX) + e^(BY) + e^(CZ)";
                case "Trigonometric - Sin"
                    app.GradingFormula.Value = "sin(AX) + sin(BY) + sin(CZ)";
                case "Trigonometric - Cosine"
                    app.GradingFormula.Value = "cos(AX) + cos(BY) + cos(CZ)";
            end

            
            if strcmp(app.GradingEquation_grade.Value, 'Circular')
                app.Radius_grade.Enable = 'on';
            else
                app.Radius_grade.Enable = 'off';
            end
        end

        % Value changed function: BrowseButton_grade
        function BrowseButton_gradeValueChanged(app, event)
            filename = uigetfile('*.stl');
            app.fileName_grade.Value = filename;
        end

        % Value changed function: BrowseButton_uni
        function BrowseButton_uniValueChanged(app, event)
            filename = uigetfile('*.stl');
            app.fileName_uni.Value = filename;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.UniformDensityTab = uitab(app.TabGroup);
            app.UniformDensityTab.Title = 'Uniform Density';
            app.UIAxes_uni = uiaxes(app.UniformDensityTab);
            title(app.UIAxes_uni, '3D Plot')
            app.UIAxes_uni.XTick = [];
            app.UIAxes_uni.XTickLabel = '';
            app.UIAxes_uni.YTick = [];
            app.UIAxes_uni.YTickLabel = '';
            app.UIAxes_uni.ZTick = [];
            app.Panel = uipanel(app.UniformDensityTab);
            app.fileName_uni = uieditfield(app.Panel, 'text');
            app.fileName_uni.Editable = 'off';
            app.NameofFileLabel = uilabel(app.Panel);
            app.NameofFileLabel.Text = 'Name of file:';
            app.CubiodDimensionsLabel = uilabel(app.Panel);
            app.CubiodDimensionsLabel.Text = 'Cubiod Dimensions:';
            app.CellDimensionsLabel = uilabel(app.Panel);
            app.CellDimensionsLabel.Text = 'Cell Dimensions:';
            app.GeometryInitializationLabel = uilabel(app.Panel);
            app.GeometryInitializationLabel.Text = 'Geometry Initialization';
            app.XSpinnerLabel_4 = uilabel(app.Panel);
            app.XSpinnerLabel_4.Text = 'X:';
            app.cubiodX_uni = uispinner(app.Panel);
            app.cubiodX_uni.Value = 60;
            app.YLabel = uilabel(app.Panel);
            app.YLabel.Text = 'Y:';
            app.cubiodY_uni = uispinner(app.Panel);
            app.cubiodY_uni.Value = 60;
            app.ZLabel = uilabel(app.Panel);
            app.ZLabel.Text = 'Z:';
            app.cubiodZ_uni = uispinner(app.Panel);
            app.cubiodZ_uni.Value = 60;
            app.XSpinnerLabel_5 = uilabel(app.Panel);
            app.XSpinnerLabel_5.Text = 'X:';
            app.cellX_uni = uispinner(app.Panel);
            app.cellX_uni.Value = 60;
            app.YLabel_2 = uilabel(app.Panel);
            app.YLabel_2.Text = 'Y:';
            app.cellY_uni = uispinner(app.Panel);
            app.cellY_uni.Value = 60;
            app.ZLabel_2 = uilabel(app.Panel);
            app.ZLabel_2.Text = 'Z:';
            app.cellZ_uni = uispinner(app.Panel);
            app.cellZ_uni.Value = 60;
            app.ImportCustomMeshDropDownLabel = uilabel(app.Panel);
            app.ImportCustomMeshDropDownLabel.Text = 'Import Custom Mesh:';
            app.ImportedMesh_uni = uidropdown(app.Panel);
            app.ImportedMesh_uni.Items = {'No', 'Yes'};
            app.ImportedMesh_uni.ValueChangedFcn = createCallbackFcn(app, @ImportedMesh_uniValueChanged, true);
            app.ImportedMesh_uni.Value = 'No';
            app.NumberofGridpointsSpinnerLabel = uilabel(app.Panel);
            app.NumberofGridpointsSpinnerLabel.Text = 'Number of Gridpoints:';
            app.Gridpoints_uni = uispinner(app.Panel);
            app.Gridpoints_uni.Value = 100;
            app.BrowseButton_uni = uibutton(app.Panel, 'state');
            app.BrowseButton_uni.ValueChangedFcn = createCallbackFcn(app, @BrowseButton_uniValueChanged, true);
            app.BrowseButton_uni.Enable = 'off';
            app.BrowseButton_uni.Text = 'Browse';
            app.Panel_2 = uipanel(app.UniformDensityTab);
            app.StructureType_uni = uiswitch(app.Panel_2, 'slider');
            app.StructureType_uni.Items = {'Sheet', 'Solid'};
            app.StructureType_uni.ValueChangedFcn = createCallbackFcn(app, @StructureType_uniValueChanged, true);
            app.StructureType_uni.Value = 'Sheet';
            app.FunctionLabel = uilabel(app.Panel_2);
            app.FunctionLabel.Text = 'Function:';
            app.Function_uni = uidropdown(app.Panel_2);
            app.Function_uni.Items = {'Primitive Schwartz', 'Gyroid', 'Diamond', 'I-WP', 'F-RD', 'I2-Y', 'Custom Function'};
            app.Function_uni.ValueChangedFcn = createCallbackFcn(app, @Function_uniValueChanged, true);
            app.Function_uni.Value = 'Primitive Schwartz';
            app.FillDirectionLabel = uilabel(app.Panel_2);
            app.FillDirectionLabel.Text = 'Fill Direction:';
            app.fillDirection_uni = uidropdown(app.Panel_2);
            app.fillDirection_uni.Items = {'Fill Above Isovalue', 'Fill Below Isovalue'};
            app.fillDirection_uni.Enable = 'off';
            app.fillDirection_uni.Value = 'Fill Above Isovalue';
            app.CustomFunctionEditFieldLabel = uilabel(app.Panel_2);
            app.CustomFunctionEditFieldLabel.Text = 'Custom Function:';
            app.CustomFunction_uni = uieditfield(app.Panel_2, 'text');
            app.CustomFunction_uni.Editable = 'off';
            app.TPMSParametersLabel = uilabel(app.Panel_2);
            app.TPMSParametersLabel.Text = 'TPMS Parameters';
            app.Panel_3 = uipanel(app.UniformDensityTab);
            app.Plot_uni = uibutton(app.Panel_3, 'state');
            app.Plot_uni.ValueChangedFcn = createCallbackFcn(app, @Plot_uniValueChanged, true);
            app.Plot_uni.Text = 'Plot';
            app.ExportSTL_uni = uibutton(app.Panel_3, 'push');
            app.ExportSTL_uni.ButtonPushedFcn = createCallbackFcn(app, @ExportSTL_uniButtonPushed, true);
            app.ExportSTL_uni.Text = 'Export STL';
            app.UniformDensityLabel = uilabel(app.Panel_3);
            app.UniformDensityLabel.Text = 'Uniform Density';
            app.ExactRelativeDensityEditFieldLabel = uilabel(app.Panel_3);
            app.ExactRelativeDensityEditFieldLabel.Text = 'Exact Relative Density ';
            app.exactRelativeDensity_uni = uieditfield(app.Panel_3, 'text');
            app.exactRelativeDensity_uni.Editable = 'off';
            app.RelativeDensitySpinnerLabel = uilabel(app.Panel_3);
            app.RelativeDensitySpinnerLabel.Text = 'Relative Density:';
            app.relativeDensity_uni = uispinner(app.Panel_3);
            app.relativeDensity_uni.Step = 0.1;
            app.relativeDensity_uni.Limits = [0.01 1];
            app.relativeDensity_uni.Enable = 'off';
            app.relativeDensity_uni.Value = 0.5;
            app.ThicknessCheckBox_uni = uicheckbox(app.Panel_3);
            app.ThicknessCheckBox_uni.ValueChangedFcn = createCallbackFcn(app, @ThicknessCheckBox_uniValueChanged, true);
            app.ThicknessCheckBox_uni.Text = 'Thickness';
            app.ThicknessCheckBox_uni.Value = true;
            app.RelativeDensityCheckBox_uni = uicheckbox(app.Panel_3);
            app.RelativeDensityCheckBox_uni.ValueChangedFcn = createCallbackFcn(app, @RelativeDensityCheckBox_uniValueChanged, true);
            app.RelativeDensityCheckBox_uni.Text = 'Relative Density';
            app.ThicknessLabel = uilabel(app.Panel_3);
            app.ThicknessLabel.Text = 'Thickness:';
            app.thickness_uni = uispinner(app.Panel_3);
            app.thickness_uni.Step = 0.1;
            app.thickness_uni.Limits = [0.01 Inf];
            app.thickness_uni.Value = 0.5;
            app.Lamp_uni = uilamp(app.Panel_3);
            app.LampLabel_uni = uilabel(app.Panel_3);
            app.LampLabel_uni.Text = ' ';
            app.GradedDensityTab = uitab(app.TabGroup);
            app.GradedDensityTab.Title = 'Graded Density';
            app.UIAxes_grade = uiaxes(app.GradedDensityTab);
            title(app.UIAxes_grade, '3D Plot')
            app.UIAxes_grade.XTick = [];
            app.UIAxes_grade.XTickLabel = '';
            app.UIAxes_grade.YTick = [];
            app.UIAxes_grade.YTickLabel = '';
            app.UIAxes_grade.ZTick = [];
            app.Panel_6 = uipanel(app.GradedDensityTab);
            app.GradedDensityLabel = uilabel(app.Panel_6);
            app.GradedDensityLabel.Text = 'Graded Density';
            app.FunctionLabel_3 = uilabel(app.Panel_6);
            app.FunctionLabel_3.Text = 'Grading Equation:';
            app.GradingEquation_grade = uidropdown(app.Panel_6);
            app.GradingEquation_grade.Items = {'Linear', 'Quadratic', 'Cubic', 'Circular', 'Exponential', 'Trigonometric - Sin', 'Trigonometric - Cosine'};
            app.GradingEquation_grade.ValueChangedFcn = createCallbackFcn(app, @GradingEquation_gradeValueChanged, true);
            app.GradingEquation_grade.Value = 'Linear';
            app.ALabel = uilabel(app.Panel_6);
            app.ALabel.Text = 'A:';
            app.coefficientA_grade = uispinner(app.Panel_6);
            app.coefficientA_grade.Value = -0.5;
            app.YLabel_5 = uilabel(app.Panel_6);
            app.YLabel_5.Text = 'B:';
            app.coefficientB_grade = uispinner(app.Panel_6);
            app.coefficientB_grade.Value = 2;
            app.ZLabel_5 = uilabel(app.Panel_6);
            app.ZLabel_5.Text = 'C:';
            app.coefficientC_grade = uispinner(app.Panel_6);
            app.coefficientC_grade.Value = 0.5;
            app.CoefficientLabel = uilabel(app.Panel_6);
            app.CoefficientLabel.Text = 'Coefficient:';
            app.ZLabel_6 = uilabel(app.Panel_6);
            app.ZLabel_6.Text = 'Radius:';
            app.Radius_grade = uispinner(app.Panel_6);
            app.Radius_grade.Enable = 'off';
            app.Radius_grade.Value = 60;
            app.MinimumDensitySpinnerLabel = uilabel(app.Panel_6);
            app.MinimumDensitySpinnerLabel.Text = 'Minimum Density:';
            app.MinimumDensitySpinner_grade = uispinner(app.Panel_6);
            app.MinimumDensitySpinner_grade.Step = 0.1;
            app.MinimumDensitySpinner_grade.Limits = [0.01 1];
            app.MinimumDensitySpinner_grade.Enable = 'off';
            app.MinimumDensitySpinner_grade.Value = 0.1;
            app.MinimumThicknessSpinnerLabel = uilabel(app.Panel_6);
            app.MinimumThicknessSpinnerLabel.Text = 'Minimum Thickness:';
            app.MinimumThicknessSpinner_grade = uispinner(app.Panel_6);
            app.MinimumThicknessSpinner_grade.Step = 0.1;
            app.MinimumThicknessSpinner_grade.Limits = [0.01 Inf];
            app.MinimumThicknessSpinner_grade.Value = 0.5;
            app.MaximumDensitySpinnerLabel = uilabel(app.Panel_6);
            app.MaximumDensitySpinnerLabel.Text = 'Maximum Density:';
            app.MaximumDensitySpinner_grade = uispinner(app.Panel_6);
            app.MaximumDensitySpinner_grade.Step = 0.1;
            app.MaximumDensitySpinner_grade.Limits = [0.01 1];
            app.MaximumDensitySpinner_grade.Enable = 'off';
            app.MaximumDensitySpinner_grade.Value = 0.1;
            app.MaximumThicknessSpinnerLabel = uilabel(app.Panel_6);
            app.MaximumThicknessSpinnerLabel.Text = 'Maximum Thickness:';
            app.MaximumThicknessSpinner_grade = uispinner(app.Panel_6);
            app.MaximumThicknessSpinner_grade.Step = 0.1;
            app.MaximumThicknessSpinner_grade.Limits = [0.01 Inf];
            app.MaximumThicknessSpinner_grade.Value = 0.5;
            app.ThicknessCheckBox_grade = uicheckbox(app.Panel_6);
            app.ThicknessCheckBox_grade.ValueChangedFcn = createCallbackFcn(app, @ThicknessCheckBox_gradeValueChanged, true);
            app.ThicknessCheckBox_grade.Text = 'Thickness';
            app.ThicknessCheckBox_grade.Value = true;
            app.RelativeDensityCheckBox_grade = uicheckbox(app.Panel_6);
            app.RelativeDensityCheckBox_grade.ValueChangedFcn = createCallbackFcn(app, @RelativeDensityCheckBox_gradeValueChanged, true);
            app.RelativeDensityCheckBox_grade.Text = 'Relative Density';
            app.Plot_grade = uibutton(app.Panel_6, 'state');
            app.Plot_grade.ValueChangedFcn = createCallbackFcn(app, @Plot_gradeValueChanged, true);
            app.Plot_grade.Text = 'Plot';
            app.ExportSTL_grade = uibutton(app.Panel_6, 'push');
            app.ExportSTL_grade.ButtonPushedFcn = createCallbackFcn(app, @ExportSTL_gradeButtonPushed, true);
            app.ExportSTL_grade.Text = 'Export STL';
            app.GradingFormula = uitextarea(app.Panel_6);
            app.ALabel_2 = uilabel(app.Panel_6);
            app.ALabel_2.Text = 'X:';
            app.positionX_grade = uispinner(app.Panel_6);
            app.positionX_grade.Step = 0.1;
            app.positionX_grade.Limits = [0 1];
            app.YLabel_8 = uilabel(app.Panel_6);
            app.YLabel_8.Text = 'Y:';
            app.positionY_grade = uispinner(app.Panel_6);
            app.positionY_grade.Step = 0.1;
            app.positionY_grade.Limits = [0 1];
            app.positionY_grade.Value = 1;
            app.ZLabel_9 = uilabel(app.Panel_6);
            app.ZLabel_9.Text = 'Z:';
            app.positionZ_grade = uispinner(app.Panel_6);
            app.positionZ_grade.Step = 0.1;
            app.positionZ_grade.Limits = [0 1];
            app.positionZ_grade.Value = 0.5;
            app.Lamp_grade = uilamp(app.Panel_6);
            app.LampLabel_grade = uilabel(app.Panel_6);
            app.LampLabel_grade.Text = ' ';
            app.RelativePosition_grade = uilabel(app.Panel_6);
            app.RelativePosition_grade.Text = 'Reference Position:';
            app.ExactRelativeDensityEditFieldLabel_2 = uilabel(app.Panel_6);
            app.ExactRelativeDensityEditFieldLabel_2.Text = 'Exact Relative Density ';
            app.exactRelativeDensity_grade = uieditfield(app.Panel_6, 'text');
            app.exactRelativeDensity_grade.Editable = 'off';
            app.Panel_7 = uipanel(app.GradedDensityTab);
            app.fileName_grade = uieditfield(app.Panel_7, 'text');
            app.fileName_grade.Editable = 'off';
            app.NameofFIle_2 = uilabel(app.Panel_7);
            app.NameofFIle_2.Text = 'Name of file:';
            app.CubiodDimensionsLabel_2 = uilabel(app.Panel_7);
            app.CubiodDimensionsLabel_2.Text = 'Cubiod Dimensions:';
            app.CellDimensionsLabel_2 = uilabel(app.Panel_7);
            app.CellDimensionsLabel_2.Text = 'Cell Dimensions:';
            app.GeometryInitializationLabel_2 = uilabel(app.Panel_7);
            app.GeometryInitializationLabel_2.Text = 'Geometry Initialization';
            app.XSpinnerLabel_6 = uilabel(app.Panel_7);
            app.XSpinnerLabel_6.Text = 'X:';
            app.cubiodX_grade = uispinner(app.Panel_7);
            app.cubiodX_grade.Value = 60;
            app.YLabel_6 = uilabel(app.Panel_7);
            app.YLabel_6.Text = 'Y:';
            app.cubiodY_grade = uispinner(app.Panel_7);
            app.cubiodY_grade.Value = 60;
            app.ZLabel_7 = uilabel(app.Panel_7);
            app.ZLabel_7.Text = 'Z:';
            app.cubiodZ_grade = uispinner(app.Panel_7);
            app.cubiodZ_grade.Value = 60;
            app.XSpinnerLabel_7 = uilabel(app.Panel_7);
            app.XSpinnerLabel_7.Text = 'X:';
            app.cellX_grade = uispinner(app.Panel_7);
            app.cellX_grade.Value = 60;
            app.YLabel_7 = uilabel(app.Panel_7);
            app.YLabel_7.Text = 'Y:';
            app.cellY_grade = uispinner(app.Panel_7);
            app.cellY_grade.Value = 60;
            app.ZLabel_8 = uilabel(app.Panel_7);
            app.ZLabel_8.Text = 'Z:';
            app.cellZ_grade = uispinner(app.Panel_7);
            app.cellZ_grade.Value = 60;
            app.ImportCustomMeshDropDownLabel_2 = uilabel(app.Panel_7);
            app.ImportCustomMeshDropDownLabel_2.Text = 'Import Custom Mesh:';
            app.ImportedMesh_grade = uidropdown(app.Panel_7);
            app.ImportedMesh_grade.Items = {'No', 'Yes'};
            app.ImportedMesh_grade.ValueChangedFcn = createCallbackFcn(app, @ImportedMesh_gradeValueChanged, true);
            app.ImportedMesh_grade.Value = 'No';
            app.NumberofGridpointsSpinnerLabel_2 = uilabel(app.Panel_7);
            app.NumberofGridpointsSpinnerLabel_2.Text = 'Number of Gridpoints:';
            app.Gridpoints_grade = uispinner(app.Panel_7);
            app.Gridpoints_grade.Value = 100;
            app.BrowseButton_grade = uibutton(app.Panel_7, 'state');
            app.BrowseButton_grade.ValueChangedFcn = createCallbackFcn(app, @BrowseButton_gradeValueChanged, true);
            app.BrowseButton_grade.Enable = 'off';
            app.BrowseButton_grade.Text = 'Browse';
            app.Panel_8 = uipanel(app.GradedDensityTab);
            app.StructureType_grade = uiswitch(app.Panel_8, 'slider');
            app.StructureType_grade.Items = {'Sheet', 'Solid'};
            app.StructureType_grade.ValueChangedFcn = createCallbackFcn(app, @StructureType_gradeValueChanged, true);
            app.StructureType_grade.Value = 'Sheet';
            app.FunctionLabel_4 = uilabel(app.Panel_8);
            app.FunctionLabel_4.Text = 'Function:';
            app.Function_grade = uidropdown(app.Panel_8);
            app.Function_grade.Items = {'Primitive Schwartz', 'Gyroid', 'Diamond', 'I-WP', 'F-RD', 'I2-Y', 'Custom Function'};
            app.Function_grade.ValueChangedFcn = createCallbackFcn(app, @Function_gradeValueChanged, true);
            app.Function_grade.Value = 'Primitive Schwartz';
            app.FillDirectionLabel_2 = uilabel(app.Panel_8);
            app.FillDirectionLabel_2.Text = 'Fill Direction:';
            app.fillDirection_grade = uidropdown(app.Panel_8);
            app.fillDirection_grade.Items = {'Fill Above Isovalue', 'Fill Below Isovalue'};
            app.fillDirection_grade.Enable = 'off';
            app.fillDirection_grade.Value = 'Fill Above Isovalue';
            app.CustomFunctionEditFieldLabel_2 = uilabel(app.Panel_8);
            app.CustomFunctionEditFieldLabel_2.Text = 'Custom Function:';
            app.CustomFunction_grade = uieditfield(app.Panel_8, 'text');
            app.CustomFunction_grade.Editable = 'off';
            app.TPMSParametersLabel_2 = uilabel(app.Panel_8);
            app.TPMSParametersLabel_2.Text = 'TPMS Parameters';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = impModelGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end